classdef Manual_Courtship_Tracking_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        showSLEAPpointsButton        matlab.ui.control.Button
        swappointsButton             matlab.ui.control.Button
        pointapprovalButton          matlab.ui.control.Button
        ExpFolderEditField           matlab.ui.control.EditField
        ExpFolderEditFieldLabel      matlab.ui.control.Label
        ArenaEditField_2             matlab.ui.control.EditField
        ArenaEditField_2Label        matlab.ui.control.Label
        ExpNameEditField             matlab.ui.control.EditField
        ExpNameEditFieldLabel        matlab.ui.control.Label
        DateEditField_2              matlab.ui.control.EditField
        DateEditField_2Label         matlab.ui.control.Label
        TrialBaseEditField           matlab.ui.control.EditField
        TrialBaseEditFieldLabel      matlab.ui.control.Label
        VideoBaseEditField           matlab.ui.control.EditField
        VideoBaseEditFieldLabel      matlab.ui.control.Label
        SelectExperimentButton       matlab.ui.control.Button
        sskipforwardsLabel           matlab.ui.control.Label
        qskipbackwardsLabel          matlab.ui.control.Label
        spacelabelframeLabel         matlab.ui.control.Label
        epreviousLabel               matlab.ui.control.Label
        fnextLabel                   matlab.ui.control.Label
        KeystrokeshortcutsLabel      matlab.ui.control.Label
        ClearframelabelsButton       matlab.ui.control.Button
        SaveDataButton               matlab.ui.control.Button
        ShowCurrentFrameButton       matlab.ui.control.Button
        PlotCurrentLabelsButton      matlab.ui.control.Button
        DisplayOptionsPanel          matlab.ui.container.Panel
        HistorypointsEditField       matlab.ui.control.NumericEditField
        HistorypointsEditFieldLabel  matlab.ui.control.Label
        LastlabelinvideoButton       matlab.ui.control.Button
        FirstlabelinvideoButton      matlab.ui.control.Button
        ShowAllFliesButton           matlab.ui.control.StateButton
        FrameSelectionPanel          matlab.ui.container.Panel
        SkipBackwardsButton          matlab.ui.control.Button
        SkipForwardsButton           matlab.ui.control.Button
        NextButton                   matlab.ui.control.Button
        PreviousButton               matlab.ui.control.Button
        SkipSizeEditField            matlab.ui.control.EditField
        SkipSizeEditFieldLabel       matlab.ui.control.Label
        FrameEditField               matlab.ui.control.NumericEditField
        FrameEditFieldLabel          matlab.ui.control.Label
        VideoEditField_2             matlab.ui.control.EditField
        VideoEditField_2Label        matlab.ui.control.Label
        LoadDataButton               matlab.ui.control.StateButton
        LabelFrameButton             matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function fig = displayImage(app)
            global currentImg xlimit ylimit fig T vidDir
            try
                figure(fig)
            catch
                fig = figure; set(fig, 'color','k');                
            end
            % Get the frame info & image data
            frame = app.FrameEditField.Value;
            vid = T.vidNums(frame);
            vid_frame = T.vidFrame(frame);
            videoPath = [vidDir num2str(vid) '.avi'];
            app.VideoEditField_2.Value = videoPath;
            movieInfo = VideoReader(videoPath); %read in video
            img = rgb2gray(read(movieInfo,vid_frame));
            currentImg = imadjust(img, [50/255,150/255]);
            % display current image
            imshow(currentImg)
            xlim(xlimit); ylim(ylimit); 

            % show previous tracked points
            displayTacks(app)

        end

        
        function saveData(app)
            global trackPoints SaveFile 
            save(SaveFile,'trackPoints');
            disp('Data saved')
        end
        
        function pts = markPoints(app,idx)
            global fig CList
            
            if nargin < 2
                n = Inf;
                pts = zeros(2, 0);
            else
                n = length(idx);
                pts = zeros(2, n);%ShowAllFliesButton
            end
            figure(fig);
            xold = 0;
            yold = 0;
            k = 0;
            hold on; 
            while 1
                [xi, yi, but] = crosshairs(1,{'black', 'black', CList(k+1,:), CList(k+1,:)});      % get a point
                if ~isequal(but, 1)  % stop if not button 1
                    break
                end
                k = k + 1;
                pts(1,k) = xi;
                pts(2,k) = yi;

                scatter(xi, yi, 75, 'w','filled'); % first point on its own
                scatter(xi, yi, 40, (CList(k,:)),'filled'); % first point on its own
%                   if xold
%                       scatter([xold xi], [yold yi], 75, 'w','filled');  % draw as we go
%                       scatter([xold xi], [yold yi], 40, Color(CList{idx(k)}),'filled');  % draw as we go
%                   else
%                       scatter(xi, yi, 75, 'w','filled'); % first point on its own
%                       scatter(xi, yi, 40, Color(CList{idx(k)}),'filled'); % first point on its own
%                   end
                  if isequal(k, n)
                      break
                  end
                  xold = xi;
                  yold = yi;
            end
            hold off;
            if k < size(pts,2)
                pts = pts(:, 1:k);
            end
            figure(app.UIFigure);
        end

        
        function displayTacks(app)
            global trackPoints fig CList
             
            n = app.HistorypointsEditField.Value;
            frame = app.FrameEditField.Value;
            figure(fig); hold on
            for fly = 1:2
                pts = [];
                if n>0 %display previously tracked points
                    loc = find(~isnan(trackPoints.x(1:frame,fly))); %location of tracked points
                    loc(loc<frame-20) = []; %remove data points from more than 20 frames past
                    if ~isempty(loc)
                        if length(loc)>=n
                            roi = loc(end-(n-1):end);
                            pts.x = trackPoints.x(roi,fly);
                            pts.y = trackPoints.y(roi,fly);
                        else
                            %account for under/over number of tracked points
                            pts.x = trackPoints.x(loc,fly);
                            pts.y = trackPoints.y(loc,fly);
                        end
                        scatter(pts.x,pts.y,15,CList(fly,:))
                        
                        if loc(end)==frame
                            scatter(pts.x(end),pts.y(end),25,CList(fly,:),'filled')
                        end
                    end
                else %display no previous history
                    pts.x = trackPoints.x(frame,fly);
                    pts.y = trackPoints.y(frame,fly);
                    scatter(pts.x,pts.y,25,CList(fly,:),'filled')
                end
            end
        end

        function results = plotSLEAPpoints(app,editable)
            global trackPoints CList SLEAP
            frame = app.FrameEditField.Value;
            t_x = trackPoints.x(frame,:);
            t_y = trackPoints.y(frame,:);
            
            % priority show the manually selected labels, but if nothing,
            % show the SLEAP tracked points for editing
            if isnan(t_x)
                x = SLEAP.x_loc(frame,1:2);
                y = SLEAP.y_loc(frame,1:2);
            else
                x = t_x;
                y = t_y;
            end

            % if there aren't 2 points...replace with avg.
            if any(isnan(x)) || isempty(x)
                % set positions to be roughly center of arena
                x = mean(SLEAP.x_loc(:,1), 'omitnan');
                x = [x,x];
                y = mean(SLEAP.y_loc(:,1), 'omitnan');
                y = [y,y];
            end

            points = cell(1, 2);
            for i = 1:2
                points{i} = drawpoint('Position', [x(i), y(i)],'color', (CList(i,:)));% Create a draggable point and store it in a cell array
                % set a callback for when the point is moved
                % addlistener(points{i}, 'MovingROI', @(src, evt) disp(['Point ' num2str(i) ' moved to: ' num2str(evt.CurrentPosition)]));
            end

            % Pause execution until the 'Enter' key is pressed
            if editable 
                disp('Press "Enter" to continue...');
    
                while true
                    % Wait for keypress
                    w = waitforbuttonpress; % returns 1 for keypress and 0 for mouse click
    
                    if w == 1
                        % Get the key pressed
                        keyPressed = get(gcf, 'CurrentCharacter');
    
                        % ASCII code for 'return' (Enter) is 13
                        if keyPressed == char(13)
                            disp('Enter key detected. Continuing...');
                            break;  % Exit loop and continue when 'Enter' is pressed
                        else
                            disp('Please press "Enter" to continue...');
                        end
                    end
                end
            end

            % Collect the final positions of all points asynchronously
            
            results = cell2mat(cellfun(@(p) p.Position, points, 'UniformOutput', false)');

            % trackPoints(XY,frame,fly)
            trackPoints.x(frame,:) = results(:,1);
            trackPoints.y(frame,:) = results(:,2);
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
            value = app.LoadDataButton.Value;
            if value 
                global T expName vidDir xlimit ylimit SaveFile trackPoints fig SLEAP
                % Load Experiment Data
                baseFolder = app.TrialBaseEditField.Value;
                folder = app.ExpFolderEditField.Value;
                expName = app.ExpNameEditField.Value;
                arena = app.ArenaEditField_2.Value;
                full_path = [baseFolder, folder, '/',  expName, arena ' timecourse data.mat'];
                temp = load(full_path);
                
                % Save video access information:
                vidFolder = app.VideoBaseEditField.Value;
                vidDir = [vidFolder app.DateEditField_2.Value '/' expName '_'];
                
                % Extract data needed for this tracking
                T = temp.data.T;
                % Set axis limits for the selected arena
                x = temp.data.centre(1);
                y = temp.data.centre(2);
                r = temp.data.r;
                xlimit = [x-(r+50),x+(r+50)];
                ylimit = [y-(r+50),y+50+r];
                
                % Officially Tracked Flies: 
                SLEAP = [];
                SLEAP.x_loc = temp.data.x_loc;
                SLEAP.y_loc = temp.data.y_loc;

                % check for existing data structures
                SaveFile = [baseFolder, folder, '/',  expName ' manual tracks.mat'];
                if ~exist(SaveFile,'file')
                   trackPoints = [];
                   trackPoints.x = nan([size(temp.data.x_loc,1),2]);
                   trackPoints.y = nan([size(temp.data.x_loc,1),2]);
                   saveData(app);
                else
                    load(SaveFile);
                end

                % % Load fly options
                % nflies = temp.data.nflies;
                % flyList = strsplit(num2str(1:nflies));
                % app.FliesListBox.Items = flyList;

                % Bring up frame image
                displayImage(app);
                set(fig,'pos',[724 67 1101 922]);
                figure(app.UIFigure);
            else
                close all
                app.VideoEditField_2.Value = '';
                switch questdlg('Save Data?')
                    case 'Yes'
                       saveData(app); 
                end
            end

        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            displayImage(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % global trackPoints
            curr = app.FrameEditField.Value;
            app.FrameEditField.Value = curr + 1;
            displayImage(app);
            
            % % save current labels to the trackPoints structure
            plotSLEAPpoints(app,false);

            figure(app.UIFigure);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % global trackPoints
            curr = app.FrameEditField.Value;
            if curr==1
                new_value = 1;
            else
                new_value = curr -1;
            end
            app.FrameEditField.Value = new_value;
            displayImage(app);

            % % save current labels to the trackPoints structure
            plotSLEAPpoints(app,false);

            figure(app.UIFigure);
        end

        % Button pushed function: SkipBackwardsButton
        function SkipBackwardsButtonPushed(app, event)
            % global trackPoints
            curr = app.FrameEditField.Value;
            skipSize = str2double(app.SkipSizeEditField.Value);
            if curr==1
                new_value = 1;
            else
                new_value = curr - skipSize;
            end
            app.FrameEditField.Value = new_value;
            displayImage(app);

            plotSLEAPpoints(app,false);

            figure(app.UIFigure);
        end

        % Button pushed function: SkipForwardsButton
        function SkipForwardsButtonPushed(app, event)
            % global trackPoints
            curr = app.FrameEditField.Value;
            skipSize = app.SkipSizeEditField.Value;
            new_value = curr + str2double(skipSize);

            app.FrameEditField.Value = new_value; 
            displayImage(app);
            plotSLEAPpoints(app,false);
            figure(app.UIFigure);
        end

        % Button pushed function: LabelFrameButton
        function LabelFrameButtonPushed(app, event)
            % global trackPoints
            % frame = app.FrameEditField.Value;
            % plot editable points from SLEAP or Manual
            plotSLEAPpoints(app,true); 

            figure(app.UIFigure);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            saveData(app)
        end

        % Button pushed function: PlotCurrentLabelsButton
        function PlotCurrentLabelsButtonPushed(app, event)
            global T trackPoints CList
            frame = app.FrameEditField.Value;
            z = T.temperature;
            time = T.frame;
            sb(1).idx = 1;
            sb(2).idx = 2:4;
            r = 4;
            c = 1;
            h = figure; set(h, 'pos', [342 273 942 637])
            subplot(r,c,sb(1).idx)
                plot(time,z,'color', 'w')
                xlim([-1000,time(end)])
                ylabel('temp \circC')
            subplot(r,c,sb(2).idx)
                hold on
                for fly = 1:2
                    x = trackPoints.x(:,fly)';
                    x = find(~isnan(x));
                    y = repmat([fly-.25,fly+0.25],[length(x),1]);
                    line([x;x],y','Color',(CList(fly,:)),'linewidth', 1)
                end
                xlim([-1000,time(end)])
                ylim([0,3])
                ylabel('fly tracks')
                xlabel('frames')
            formatFig(h,true,[r,c],sb);
            subplot(r,c,sb(1).idx); set(gca, 'XColor','k')
            v_line(frame,'white',':',1)

        end

        % Button pushed function: ShowCurrentFrameButton
        function ShowCurrentFrameButtonPushed(app, event)
            % set color
           global CList
           CList = [];
           kolor = {'LightCyan', 'BlueViolet'};
           for i = 1:2 
                CList(i,:) = Color(kolor{i});
           end
            displayImage(app);
            figure(app.UIFigure);
        end

        % Value changed function: ShowAllFliesButton
        function ShowAllFliesButtonValueChanged(app, event)
            value = app.ShowAllFliesButton.Value;
            if value
                displayTacks(app)
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'f'
                    app.NextButtonPushed(app)
                case 'e'
                    app.PreviousButtonPushed(app)
                case 'space'
                    app.LabelFrameButtonPushed(app)
                case 's'
                    app.SkipForwardsButtonPushed(app)
                case 'q'
                    app.SkipBackwardsButtonPushed(app)
            end
           figure(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            switch questdlg('Save data?')
                case 'Yes'
                    saveData(app);
                case 'No'
                case 'Cancel'
                    return
            end
            delete(app)
        end

        % Button pushed function: LastlabelinvideoButton
        function LastlabelinvideoButtonPushed(app, event)
            global trackPoints
            loc = ~isnan(trackPoints.x(:,1));
            frameList = find(loc==true);
            frame = max(frameList);

            app.FrameEditField.Value = frame;
            app.FrameEditFieldValueChanged(app);
            figure(app.UIFigure);
        end

        % Button pushed function: FirstlabelinvideoButton
        function FirstlabelinvideoButtonPushed(app, event)
            global trackPoints
            loc = ~isnan(trackPoints.x(:,1));
            frameList = find(loc==true);
            if isempty(frameList)
                frame = 1;
            else
                frame = min(frameList);
            end
            app.FrameEditField.Value = frame;
            app.FrameEditFieldValueChanged(app);
            figure(app.UIFigure);
        end

        % Callback function
        function ClearframelabelsButtonValueChanged(app, event)
            value = app.ClearframelabelsButton.Value;
            % This function no longer exists...
        end

        % Button pushed function: ClearframelabelsButton
        function ClearframelabelsButtonPushed(app, event)
            global trackPoints
            frame = app.FrameEditField.Value;
            trackPoints.x(frame,:) = [nan,nan];
            trackPoints.y(frame,:) = [nan,nan];
            figure(app.UIFigure);
        end

        % Button pushed function: SelectExperimentButton
        function SelectExperimentButtonPushed(app, event)
            % Get the base folder for the videos
            % paths = getPathNames;
            vidBase = getDataPath(2,0,'Select the video location'); % select video path base
            app.VideoBaseEditField.Value = vidBase;
            trialBase = getDataPath(1,0,'Select the trial data location'); % select trial path base
            app.TrialBaseEditField.Value = trialBase;
            folderPath = selectFolder(trialBase);
            folderPath = folderPath{1};
            app.ExpFolderEditField.Value = folderPath;
            app.DateEditField_2.Value = folderPath(1:10);
            app.ExpNameEditField.Value = folderPath(12:end-2);
            app.ArenaEditField_2.Value = folderPath(end); 
            
            % set color
           global CList
           CList = [];
           kolor = {'LightCyan', 'BlueViolet'};
           for i = 1:2 
                CList(i,:) = Color(kolor{i});
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.LabelFrameButton = uibutton(app.UIFigure, 'push');
            app.LabelFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LabelFrameButtonPushed, true);
            app.LabelFrameButton.Text = 'Label Frame';
            app.LoadDataButton = uibutton(app.UIFigure, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Text = 'Load Data';
            app.VideoEditField_2Label = uilabel(app.UIFigure);
            app.VideoEditField_2Label.Text = 'Video';
            app.VideoEditField_2 = uieditfield(app.UIFigure, 'text');
            app.FrameSelectionPanel = uipanel(app.UIFigure);
            app.FrameSelectionPanel.Title = 'Frame Selection';
            app.FrameEditFieldLabel = uilabel(app.FrameSelectionPanel);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.FrameSelectionPanel, 'numeric');
            app.FrameEditField.ValueDisplayFormat = '%.0f';
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Value = 1;
            app.SkipSizeEditFieldLabel = uilabel(app.FrameSelectionPanel);
            app.SkipSizeEditFieldLabel.Text = 'Skip Size';
            app.SkipSizeEditField = uieditfield(app.FrameSelectionPanel, 'text');
            app.SkipSizeEditField.Value = '5';
            app.PreviousButton = uibutton(app.FrameSelectionPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.FrameSelectionPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SkipForwardsButton = uibutton(app.FrameSelectionPanel, 'push');
            app.SkipForwardsButton.ButtonPushedFcn = createCallbackFcn(app, @SkipForwardsButtonPushed, true);
            app.SkipForwardsButton.Text = 'Skip Forwards';
            app.SkipBackwardsButton = uibutton(app.FrameSelectionPanel, 'push');
            app.SkipBackwardsButton.ButtonPushedFcn = createCallbackFcn(app, @SkipBackwardsButtonPushed, true);
            app.SkipBackwardsButton.Text = 'Skip Backwards';
            app.DisplayOptionsPanel = uipanel(app.UIFigure);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.ShowAllFliesButton = uibutton(app.DisplayOptionsPanel, 'state');
            app.ShowAllFliesButton.ValueChangedFcn = createCallbackFcn(app, @ShowAllFliesButtonValueChanged, true);
            app.ShowAllFliesButton.Text = 'Show All Flies';
            app.FirstlabelinvideoButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.FirstlabelinvideoButton.ButtonPushedFcn = createCallbackFcn(app, @FirstlabelinvideoButtonPushed, true);
            app.FirstlabelinvideoButton.Text = 'First label in video';
            app.LastlabelinvideoButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.LastlabelinvideoButton.ButtonPushedFcn = createCallbackFcn(app, @LastlabelinvideoButtonPushed, true);
            app.LastlabelinvideoButton.Text = 'Last label in video';
            app.HistorypointsEditFieldLabel = uilabel(app.DisplayOptionsPanel);
            app.HistorypointsEditFieldLabel.Text = 'History points';
            app.HistorypointsEditField = uieditfield(app.DisplayOptionsPanel, 'numeric');
            app.HistorypointsEditField.Value = 3;
            app.PlotCurrentLabelsButton = uibutton(app.UIFigure, 'push');
            app.PlotCurrentLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentLabelsButtonPushed, true);
            app.PlotCurrentLabelsButton.Text = 'Plot Current Labels';
            app.ShowCurrentFrameButton = uibutton(app.UIFigure, 'push');
            app.ShowCurrentFrameButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCurrentFrameButtonPushed, true);
            app.ShowCurrentFrameButton.Text = 'Show Current Frame';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.ClearframelabelsButton = uibutton(app.UIFigure, 'push');
            app.ClearframelabelsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearframelabelsButtonPushed, true);
            app.ClearframelabelsButton.Text = 'Clear frame labels';
            app.KeystrokeshortcutsLabel = uilabel(app.UIFigure);
            app.KeystrokeshortcutsLabel.Text = 'Keystroke shortcuts:';
            app.fnextLabel = uilabel(app.UIFigure);
            app.fnextLabel.Text = 'f --> next';
            app.epreviousLabel = uilabel(app.UIFigure);
            app.epreviousLabel.Text = 'e --> previous';
            app.spacelabelframeLabel = uilabel(app.UIFigure);
            app.spacelabelframeLabel.Text = 'space --> label frame';
            app.qskipbackwardsLabel = uilabel(app.UIFigure);
            app.qskipbackwardsLabel.Text = 'q --> skip backwards';
            app.sskipforwardsLabel = uilabel(app.UIFigure);
            app.sskipforwardsLabel.Text = 's --> skip forwards';
            app.SelectExperimentButton = uibutton(app.UIFigure, 'push');
            app.SelectExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentButtonPushed, true);
            app.SelectExperimentButton.Text = 'Select Experiment';
            app.VideoBaseEditFieldLabel = uilabel(app.UIFigure);
            app.VideoBaseEditFieldLabel.Text = 'Video Base';
            app.VideoBaseEditField = uieditfield(app.UIFigure, 'text');
            app.TrialBaseEditFieldLabel = uilabel(app.UIFigure);
            app.TrialBaseEditFieldLabel.Text = 'Trial Base';
            app.TrialBaseEditField = uieditfield(app.UIFigure, 'text');
            app.DateEditField_2Label = uilabel(app.UIFigure);
            app.DateEditField_2Label.Text = 'Date';
            app.DateEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ExpNameEditFieldLabel = uilabel(app.UIFigure);
            app.ExpNameEditFieldLabel.Text = 'Exp Name';
            app.ExpNameEditField = uieditfield(app.UIFigure, 'text');
            app.ArenaEditField_2Label = uilabel(app.UIFigure);
            app.ArenaEditField_2Label.Text = 'Arena';
            app.ArenaEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ExpFolderEditFieldLabel = uilabel(app.UIFigure);
            app.ExpFolderEditFieldLabel.Text = 'Exp Folder';
            app.ExpFolderEditField = uieditfield(app.UIFigure, 'text');
            app.pointapprovalButton = uibutton(app.UIFigure, 'push');
            app.pointapprovalButton.Text = 'point approval';
            app.swappointsButton = uibutton(app.UIFigure, 'push');
            app.swappointsButton.Text = 'swap points';
            app.showSLEAPpointsButton = uibutton(app.UIFigure, 'push');
            app.showSLEAPpointsButton.Text = 'show SLEAP points';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manual_Courtship_Tracking_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end