classdef DataTransferGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MatchBtoAButton             matlab.ui.control.Button
        DataTypeforTransferListBox  matlab.ui.control.ListBox
        DataTypeforTransferListBoxLabel  matlab.ui.control.Label
        SynchronizeDataButton       matlab.ui.control.Button
        LoadOptionsButton           matlab.ui.control.Button
        VersioncontrolButton        matlab.ui.control.StateButton
        TransferTypeButtonGroup     matlab.ui.container.ButtonGroup
        BidirectionalButton         matlab.ui.control.ToggleButton
        UnidirectionalButton        matlab.ui.control.ToggleButton
        LocationBListBox            matlab.ui.control.ListBox
        LocationBListBoxLabel       matlab.ui.control.Label
        LocationAListBox            matlab.ui.control.ListBox
        LocationAListBoxLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadOptionsButton
        function LoadOptionsButtonPushed(app, event)
            global availableDrives availablePaths optionNames  
            % set the name 'transform' from list to file location
            optionNames.permanent = 'Local Drive';
            optionNames.portable = 'On The Go Data';
            optionNames.server = 'Server';
            optionNames.other = 'Other';
            
            % Set the names into the appropriate locations
            [availableDrives, availablePaths] = getDataFolderOptions;
            optionsList = cell(1,1);
            optionsList{1} = optionNames.other;
            % Find the available drives from the above function
            if availableDrives.permanent
                optionsList{end+1} = optionNames.permanent;
            end
            if availableDrives.portable
                optionsList{end+1} = optionNames.portable;
            end 
            if availableDrives.server
                optionsList{end+1} = optionNames.server;
            end 
            
            % Update the list options for the available drives: 
            app.LocationAListBox.Items = optionsList;
            app.LocationAListBox.Value = optionNames.other;

            app.LocationBListBox.Items = optionsList;
            app.LocationBListBox.Value = optionNames.other;

            % Update the DATA TYPE list box
            optionNames.singletrial = 'Single Trial';
            optionNames.rawdata = 'Raw Data';
            optionNames.groupedTrials = 'Grouped Trials';
            optionNames.groupComparison = 'Group Comparisions';
            optionNames.other_matching = 'Other Matching';
            optionNames.other_unmatched = 'Other Unmatched';
            
            dataOptionList = cell(5,1);
            dataOptionList{1} = optionNames.rawdata;
            dataOptionList{2} = optionNames.singletrial;
            dataOptionList{3} = optionNames.groupedTrials;
            dataOptionList{4} = optionNames.groupComparison;
            dataOptionList{5} = optionNames.other_matching;
            dataOptionList{6} = optionNames.other_unmatched;

            app.DataTypeforTransferListBox.Items = dataOptionList;

        end

        % Button pushed function: SynchronizeDataButton
        function SynchronizeDataButtonPushed(app, event)
            global availablePaths optionNames
            
            % pull the start locations for the data transfer:
            loc_A = app.LocationAListBox.Value;
            switch loc_A
                case optionNames.permanent
                    rootPath_A = availablePaths.permanentPath;
                case optionNames.server
                    rootPath_A = availablePaths.serverPath;
                case optionNames.portable
                    rootPath_A = availablePaths.onthegoPath;
                case optionNames.other
                    if strcmp(optionNames.other_unmatched,app.DataTypeforTransferListBox.Value)
                       rootPath_A = '';
                    else
                       temp = uigetdir('','Select drive location A');
                       rootPath_A = [temp '/'];
                    end
            end

            % pull the end locations for the data transfer: 
            loc_B = app.LocationBListBox.Value;
            switch loc_B
                case optionNames.permanent
                    rootPath_B = availablePaths.permanentPath;
                case optionNames.server
                    rootPath_B = availablePaths.serverPath;
                case optionNames.portable
                    rootPath_B = availablePaths.onthegoPath;
                case optionNames.other
                    if strcmp(optionNames.other_unmatched,app.DataTypeforTransferListBox.Value)
                       rootPath_B = '';
                    else
                       temp = uigetdir('','Select drive location B');
                       rootPath_B = [temp '/'];
                    end
            end
            
            % for single folder transfer from differently named locations: 
             if strcmp(optionNames.other_unmatched,app.DataTypeforTransferListBox.Value)
                   temp = uigetdir(rootPath_A,'Select ''Folder A'' for data synchronization (this is the ACTUAL folder to transfer data FROM)');
                   rootPath_A = [temp '/'];
                   temp = uigetdir(rootPath_B,'Select ''Folder B'' for data synchronization (this is the ACTUAL folder to transfer data TO)');
                   rootPath_B = [temp '/'];
                   different_target_folder_names = true;
             else
                 different_target_folder_names = false;
             end

            % Assemble the full file type 
            pathExtensions = getPathNames;
            switch app.DataTypeforTransferListBox.Value
                case optionNames.singletrial
                    type_str_A = pathExtensions.single_trial;
                    type_str_B = type_str_A;
                case optionNames.rawdata
                    type_str_A = pathExtensions.raw_data;
                    type_str_B = type_str_A;
                case optionNames.groupedTrials
                    type_str_A = pathExtensions.grouped_trials;
                    type_str_B = type_str_A;
                case optionNames.groupComparison
                    type_str_A = pathExtensions.group_comparision;
                    type_str_B = type_str_A;
                case optionNames.other_matching % folders to sync across will share the same name or be created to have the same names
                    temp = selectFolder(rootPath_A,'single','Select the pen-ultimate folder to transfer FROM');
                    type_str_A = [temp{:}, '/'];
                    temp = selectFolder(rootPath_B,'single','Select the pen-ultimate folder to transfer TO');
                    type_str_B = [temp{:}, '/'];
                case optionNames.other_unmatched % can sync two folders with different names
                    type_str_A = '';
                    type_str_B = '';
                    different_target_folder_names = true;
            end

            % Set the full path name:
            path_A = [rootPath_A, type_str_A];
            path_B = [rootPath_B, type_str_B];

            % Display the selected folders:
            disp('Selected location A: ')
            disp(path_A)
            disp('Selected location B: ')
            disp(path_B)
    
            % Determine what direction the data is being transfered
            switch app.TransferTypeButtonGroup.SelectedObject.Text
                case 'Unidirectional'
                   bi_direction = false;
                case 'Bidirectional'
                   bi_direction = true;
            end
            
            % Select possible subfolders to transfer:
            if ~different_target_folder_names
                promptString = ['Select folder(s) to sync FROM ' loc_A ' TO ' loc_B];
                folders_in_A = selectFolder(path_A,'mulitple',promptString);
                % if no subfolders selected or exist, give option for primary folder to be the selected folder
                if isempty(folders_in_A) 
                    warndlg('No folder selected in location A for transfer, try again with uni-directional transfer')
                    app.UnidirectionalButton.Value = true;
                    return
                end
            else % for single folder unmatched transfer
                folders_in_A = [];
            end
            % select folders to transfer from B to A in bidirectional transfer mode
            if bi_direction && ~different_target_folder_names
                promptString = ['Select folder(s) to sync FROM ' loc_B ' TO ' loc_A];
                folders_in_B = selectFolder(path_B,'mulitple',promptString); 
                % if no folders selected or available, switch to
                % uni-directional transfer
                if isempty(folders_in_B)
                    disp('No folders in B selected or found, skipping to uni-directional transfer from A to B')
                    folders_in_B = [];
                    bi_direction = false;
                    app.UnidirectionalButton.Value = true;
                end
            else 
                folders_in_B = [];
            end
            
            % check for version control
            version_control = app.VersioncontrolButton.Value;

            % sort number of folders to process
            if isempty(folders_in_A) && isempty(folders_in_B) && ~different_target_folder_names
                warndlg('No folders selected for synchronization')
                return
            elseif different_target_folder_names
                nA = 1; %only single folder to run
                nB = 1;
            else
                nA = length(folders_in_A);
                nB = length(folders_in_B);
            end
            n = nA + nB; % total number of folders to sync

            % Add final check before initialzing sync and transfer:
            if version_control 
                sync_str = ' and sync';
            else
                sync_str = ' ';
            end
            if bi_direction
                dir_str = {' between ','and '}; 
            else
                dir_str = {' from ',' to '}; 
            end
            promptString = {['Commence transfer' sync_str ' of ' num2str(n) ' folders ' dir_str{1}];...
                                        ['<' path_A '>']; dir_str{2}; ['<' path_B '>?']};
            switch questdlg(promptString,['Start transfer' sync_str '?'], 'Commence', 'Cancel','Commence')
                case 'Cancel'
                    return
                case 'Commence'
                    disp(['Commencing transfer' sync_str])
            end

            f = waitbar(0,['Syncing from ' loc_A ' to ' loc_B '...0/' num2str(n)]); %setup a loading status bar
            idx = 0;

            % ============== GOING FROM A to B ==============
            folderSelected = folders_in_A;
            TO_base = path_B;
            FROM_base = path_A;
            
            for i = 1:nA
                if different_target_folder_names
                    disp(['Syncing data from '  FROM_base ' ...'])
                    targetPath = TO_base;
                    startPath = FROM_base;
                else
                    disp(['Syncing data from '  folderSelected{i} ' ...'])
                    targetPath = [TO_base, folderSelected{i} '/'];
                    startPath = [FROM_base, folderSelected{i} '/'];
                end

                % check that the start folder actually exists (if the root folder doesn't exist in unidirection, 
                % then cancel, else, make the folder for the other direction pass)
                if ~isfolder(startPath) 
                    if bi_direction
                        mkdir(startPath);
                    else
                        warndlg('Selected folder to transfer data from does not exist, check path')
                        disp(startPath)
                    end
                end
                % check that the target folder exists -- if it doesn't, create it:
                 if ~isfolder(targetPath)
                        mkdir(targetPath);
                 end  

                % COPY MISSING FILES:
                success = SyncFolderandSubfolder(startPath, targetPath,version_control);
                % check for copy failures
                if ~success
                    warndlg(['Error in copying ' folderSelected{i}])
                    return
                end       
                % UPDATE THE WAITBAR
                idx = idx+1;
                waitbar(idx/n, f, ['Syncing from ' loc_A ' to ' loc_B '...' num2str(idx) '/' num2str(n)])
            end

            % ============ GOING FROM B to A ==============
            if bi_direction
                folderSelected = folders_in_B;
                TO_base = path_A;
                FROM_base = path_B;
                for i = 1:nB
                     if different_target_folder_names
                        disp(['Syncing data from '  FROM_base ' ...'])
                        targetPath = TO_base;
                        startPath = FROM_base;
                    else
                        disp(['Syncing data from '  folderSelected{i} ' ...'])
                        targetPath = [TO_base, folderSelected{i} '/'];
                        startPath = [FROM_base, folderSelected{i} '/'];
                     end
                    success = SyncFolderandSubfolder(startPath, targetPath,version_control);
                    % check for copy failures
                    if ~success
                        warndlg(['Error in copying ' folderSelected{i}])
                        return
                    end       
                    % update waitbar
                    idx = idx+1;
                    waitbar(idx/n, f, ['Syncing from ' loc_B ' to ' loc_A '...' num2str(idx) '/' num2str(n)])
                end
            end
            close(f)
            disp('All files finished synchronizing')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LocationAListBoxLabel = uilabel(app.UIFigure);
            app.LocationAListBoxLabel.Text = 'Location A';
            app.LocationAListBox = uilistbox(app.UIFigure);
            app.LocationAListBox.Items = {'Other'};
            app.LocationAListBox.Value = 'Other';
            app.LocationBListBoxLabel = uilabel(app.UIFigure);
            app.LocationBListBoxLabel.Text = 'Location B';
            app.LocationBListBox = uilistbox(app.UIFigure);
            app.LocationBListBox.Items = {'Other'};
            app.LocationBListBox.Value = 'Other';
            app.TransferTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TransferTypeButtonGroup.Title = 'Transfer Type';
            app.UnidirectionalButton = uitogglebutton(app.TransferTypeButtonGroup);
            app.UnidirectionalButton.Text = 'Unidirectional';
            app.UnidirectionalButton.Value = true;
            app.BidirectionalButton = uitogglebutton(app.TransferTypeButtonGroup);
            app.BidirectionalButton.Text = 'Bidirectional';
            app.VersioncontrolButton = uibutton(app.UIFigure, 'state');
            app.VersioncontrolButton.Text = 'Version control';
            app.LoadOptionsButton = uibutton(app.UIFigure, 'push');
            app.LoadOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOptionsButtonPushed, true);
            app.LoadOptionsButton.Text = 'Load Options';
            app.SynchronizeDataButton = uibutton(app.UIFigure, 'push');
            app.SynchronizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @SynchronizeDataButtonPushed, true);
            app.SynchronizeDataButton.Text = 'Synchronize Data';
            app.DataTypeforTransferListBoxLabel = uilabel(app.UIFigure);
            app.DataTypeforTransferListBoxLabel.Text = 'Data Type for Transfer';
            app.DataTypeforTransferListBox = uilistbox(app.UIFigure);
            app.DataTypeforTransferListBox.Items = {'Other'};
            app.DataTypeforTransferListBox.Value = 'Other';
            app.MatchBtoAButton = uibutton(app.UIFigure, 'push');
            app.MatchBtoAButton.Text = 'Match B to A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataTransferGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end