classdef Arena2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        C1Tab                           matlab.ui.container.Tab
        ArenaAPanel                     matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        DropDown_A1                     matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DropDown_A2                     matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        DropDown_A3                     matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DropDown_A4                     matlab.ui.control.DropDown
        GenotypeDropDown_A              matlab.ui.control.DropDown
        WellLabel                       matlab.ui.control.Label
        GenotypeLabel                   matlab.ui.control.Label
        ContentsLabel                   matlab.ui.control.Label
        SexDropDownLabel                matlab.ui.control.Label
        SexDropDown_A                   matlab.ui.control.DropDown
        StarvedhoursEditFieldLabel      matlab.ui.control.Label
        StarvedhoursEditField_A         matlab.ui.control.NumericEditField
        ArenaBPanel                     matlab.ui.container.Panel
        DropDown_5Label                 matlab.ui.control.Label
        DropDown_B1                     matlab.ui.control.DropDown
        DropDown_6Label                 matlab.ui.control.Label
        DropDown_B2                     matlab.ui.control.DropDown
        DropDown_7Label                 matlab.ui.control.Label
        DropDown_B3                     matlab.ui.control.DropDown
        DropDown_8Label                 matlab.ui.control.Label
        DropDown_B4                     matlab.ui.control.DropDown
        GenotypeDropDown_B              matlab.ui.control.DropDown
        WellLabel_2                     matlab.ui.control.Label
        GenotypeLabel_2                 matlab.ui.control.Label
        ContentsLabel_2                 matlab.ui.control.Label
        SexDropDown_2Label              matlab.ui.control.Label
        SexDropDown_B                   matlab.ui.control.DropDown
        StarvedhoursEditField_2Label    matlab.ui.control.Label
        StarvedhoursEditField_B         matlab.ui.control.NumericEditField
        ArenaCPanel                     matlab.ui.container.Panel
        DropDown_9Label                 matlab.ui.control.Label
        DropDown_C1                     matlab.ui.control.DropDown
        DropDown_10Label                matlab.ui.control.Label
        DropDown_C2                     matlab.ui.control.DropDown
        DropDown_11Label                matlab.ui.control.Label
        DropDown_C3                     matlab.ui.control.DropDown
        DropDown_12Label                matlab.ui.control.Label
        DropDown_C4                     matlab.ui.control.DropDown
        GenotypeDropDown_C              matlab.ui.control.DropDown
        WellLabel_3                     matlab.ui.control.Label
        GenotypeLabel_3                 matlab.ui.control.Label
        ContentsLabel_3                 matlab.ui.control.Label
        SexDropDown_3Label              matlab.ui.control.Label
        SexDropDown_C                   matlab.ui.control.DropDown
        StarvedhoursEditField_3Label    matlab.ui.control.Label
        StarvedhoursEditField_C         matlab.ui.control.NumericEditField
        ArenaDPanel                     matlab.ui.container.Panel
        DropDown_13Label                matlab.ui.control.Label
        DropDown_D1                     matlab.ui.control.DropDown
        DropDown_14Label                matlab.ui.control.Label
        DropDown_D2                     matlab.ui.control.DropDown
        DropDown_15Label                matlab.ui.control.Label
        DropDown_D3                     matlab.ui.control.DropDown
        DropDown_16Label                matlab.ui.control.Label
        DropDown_D4                     matlab.ui.control.DropDown
        GenotypeDropDown_D              matlab.ui.control.DropDown
        WellLabel_4                     matlab.ui.control.Label
        GenotypeLabel_4                 matlab.ui.control.Label
        ContentsLabel_4                 matlab.ui.control.Label
        SexDropDown_4Label              matlab.ui.control.Label
        SexDropDown_D                   matlab.ui.control.DropDown
        StarvedhoursEditField_4Label    matlab.ui.control.Label
        StarvedhoursEditField_D         matlab.ui.control.NumericEditField
        ArenaAMasterSwitchesPanel_2     matlab.ui.container.Panel
        WellsLabel_3                    matlab.ui.control.Label
        ArenaAMasterSwitch_Wells_2      matlab.ui.control.Switch
        GenotypeLabel_11                matlab.ui.control.Label
        ArenaAMasterSwitch_Genotype_2   matlab.ui.control.Switch
        SexSwitch_2Label                matlab.ui.control.Label
        SexSwitch_2                     matlab.ui.control.Switch
        StarvedSwitch_2Label            matlab.ui.control.Label
        StarvedSwitch_2                 matlab.ui.control.Switch
        C2Tab                           matlab.ui.container.Tab
        ArenaAPanel_2                   matlab.ui.container.Panel
        Label_5                         matlab.ui.control.Label
        DropDown_A1_2                   matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        DropDown_A2_2                   matlab.ui.control.DropDown
        Label_7                         matlab.ui.control.Label
        DropDown_A3_2                   matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        DropDown_A4_2                   matlab.ui.control.DropDown
        GenotypeDropDown_A_2            matlab.ui.control.DropDown
        WellLabel_5                     matlab.ui.control.Label
        GenotypeLabel_7                 matlab.ui.control.Label
        ContentsLabel_5                 matlab.ui.control.Label
        SexDropDownLabel_2              matlab.ui.control.Label
        SexDropDown_A_2                 matlab.ui.control.DropDown
        StarvedhoursEditFieldLabel_2    matlab.ui.control.Label
        StarvedhoursEditField_A_2       matlab.ui.control.NumericEditField
        ArenaBPanel_2                   matlab.ui.container.Panel
        DropDown_5Label_2               matlab.ui.control.Label
        DropDown_B1_2                   matlab.ui.control.DropDown
        DropDown_6Label_2               matlab.ui.control.Label
        DropDown_B2_2                   matlab.ui.control.DropDown
        DropDown_7Label_2               matlab.ui.control.Label
        DropDown_B3_2                   matlab.ui.control.DropDown
        DropDown_8Label_2               matlab.ui.control.Label
        DropDown_B4_2                   matlab.ui.control.DropDown
        GenotypeDropDown_B_2            matlab.ui.control.DropDown
        WellLabel_6                     matlab.ui.control.Label
        GenotypeLabel_8                 matlab.ui.control.Label
        ContentsLabel_6                 matlab.ui.control.Label
        SexDropDown_2Label_2            matlab.ui.control.Label
        SexDropDown_B_2                 matlab.ui.control.DropDown
        StarvedhoursEditField_2Label_2  matlab.ui.control.Label
        StarvedhoursEditField_B_2       matlab.ui.control.NumericEditField
        ArenaCPanel_2                   matlab.ui.container.Panel
        DropDown_9Label_2               matlab.ui.control.Label
        DropDown_C1_2                   matlab.ui.control.DropDown
        DropDown_10Label_2              matlab.ui.control.Label
        DropDown_C2_2                   matlab.ui.control.DropDown
        DropDown_11Label_2              matlab.ui.control.Label
        DropDown_C3_2                   matlab.ui.control.DropDown
        DropDown_12Label_2              matlab.ui.control.Label
        DropDown_C4_2                   matlab.ui.control.DropDown
        GenotypeDropDown_C_2            matlab.ui.control.DropDown
        WellLabel_7                     matlab.ui.control.Label
        GenotypeLabel_9                 matlab.ui.control.Label
        ContentsLabel_7                 matlab.ui.control.Label
        SexDropDown_3Label_2            matlab.ui.control.Label
        SexDropDown_C_2                 matlab.ui.control.DropDown
        StarvedhoursEditField_3Label_2  matlab.ui.control.Label
        StarvedhoursEditField_C_2       matlab.ui.control.NumericEditField
        ArenaDPanel_2                   matlab.ui.container.Panel
        DropDown_13Label_2              matlab.ui.control.Label
        DropDown_D1_2                   matlab.ui.control.DropDown
        DropDown_14Label_2              matlab.ui.control.Label
        DropDown_D2_2                   matlab.ui.control.DropDown
        DropDown_15Label_2              matlab.ui.control.Label
        DropDown_D3_2                   matlab.ui.control.DropDown
        DropDown_16Label_2              matlab.ui.control.Label
        DropDown_D4_2                   matlab.ui.control.DropDown
        GenotypeDropDown_D_2            matlab.ui.control.DropDown
        WellLabel_8                     matlab.ui.control.Label
        GenotypeLabel_10                matlab.ui.control.Label
        ContentsLabel_8                 matlab.ui.control.Label
        SexDropDown_4Label_2            matlab.ui.control.Label
        SexDropDown_D_2                 matlab.ui.control.DropDown
        StarvedhoursEditField_4Label_2  matlab.ui.control.Label
        StarvedhoursEditField_D_2       matlab.ui.control.NumericEditField
        ArenaAMasterSwitchesPanel       matlab.ui.container.Panel
        WellsLabel_2                    matlab.ui.control.Label
        ArenaAMasterSwitch_Wells        matlab.ui.control.Switch
        GenotypeLabel_6                 matlab.ui.control.Label
        ArenaAMasterSwitch_Genotype     matlab.ui.control.Switch
        SexSwitchLabel                  matlab.ui.control.Label
        SexSwitch                       matlab.ui.control.Switch
        StarvedSwitchLabel              matlab.ui.control.Label
        StarvedSwitch                   matlab.ui.control.Switch
        RightPanel                      matlab.ui.container.Panel
        RecordingParametersPanel        matlab.ui.container.Panel
        VideolengthSpinnerLabel         matlab.ui.control.Label
        VideolengthSpinner              matlab.ui.control.Spinner
        SessionTimeSpinnerLabel         matlab.ui.control.Label
        SessionTimeSpinner              matlab.ui.control.Spinner
        VideoFPSSpinnerLabel            matlab.ui.control.Label
        VideoFPSSpinner                 matlab.ui.control.Spinner
        PreviewButton                   matlab.ui.control.StateButton
        VideocountLabel                 matlab.ui.control.Label
        VideocountEditField             matlab.ui.control.NumericEditField
        Camera1SwitchLabel              matlab.ui.control.Label
        Camera1Switch                   matlab.ui.control.Switch
        InitializeButton                matlab.ui.control.Button
        InitializeButton_2              matlab.ui.control.Button
        Camera2SwitchLabel              matlab.ui.control.Label
        Camera2Switch                   matlab.ui.control.Switch
        PreviewButton_2                 matlab.ui.control.StateButton
        VideoNameEdit                   matlab.ui.control.EditField
        C1Label                         matlab.ui.control.Label
        VideoNameEdit_2                 matlab.ui.control.EditField
        C2Label                         matlab.ui.control.Label
        camresetButton                  matlab.ui.control.Button
        OutlineButton                   matlab.ui.control.Button
        OutlineButton_2                 matlab.ui.control.Button
        CountButton                     matlab.ui.control.Button
        CountButton_2                   matlab.ui.control.Button
        ExperimenterDropDownLabel       matlab.ui.control.Label
        ExperimenterDropDown            matlab.ui.control.DropDown
        IncubatorDropDownLabel          matlab.ui.control.Label
        IncubatorDropDown               matlab.ui.control.DropDown
        BackCamCheckBox                 matlab.ui.control.CheckBox
        TemperatureProtocolLabel        matlab.ui.control.Label
        TemperatureProtocolDropDown     matlab.ui.control.DropDown
        SaveParametersButton            matlab.ui.control.StateButton
        StartupLoadButton               matlab.ui.control.Button
        SendtoExcelButton               matlab.ui.control.Button
        Gauge                           matlab.ui.control.LinearGauge
        Lamp                            matlab.ui.control.Lamp
        RUNButton                       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown_A1
        function DropDown_A1ValueChanged(app, event)
            % match all other well 1 values to Arena A1 values
            if strcmpi(app.ArenaAMasterSwitch_Wells_2.Value,'On') %well toggle 'On'
                app.DropDown_B1.Value = app.DropDown_A1.Value;
                app.DropDown_C1.Value = app.DropDown_A1.Value;
                app.DropDown_D1.Value = app.DropDown_A1.Value;
            end
        end

        % Button pushed function: StartupLoadButton
        function StartupLoadButtonPushed(app, event)
           baseGoogleDrive = 'G:\My Drive\Jeanne Lab\';
            % Import and Load well options     
            file_id = fopen([baseGoogleDrive 'FoodWellOptions.txt']);
            A = fscanf(file_id, '%s');
            WellList = strsplit(A,',');  % pull the list of food options
                % TAB 1
                for arena = 1:4
                    for well = 1:4
                        app.(['DropDown_' Alphabet(arena) num2str(well)]).Items = WellList;
                    end
                end
                % TAB 2
                for arena = 1:4
                    for well = 1:4
                        app.(['DropDown_' Alphabet(arena) num2str(well) '_2']).Items = WellList;
                    end
                end
            % Import and Load Genotype options
            file_id = fopen([baseGoogleDrive 'cross.txt']);
            A = fscanf(file_id, '%s');
            genotypeList = strsplit(A,','); 
            % TAB 1
            for arena = 1:4
                app.(['GenotypeDropDown_' Alphabet(arena)]).Items = genotypeList;
            end
            % TAB 2
            for arena = 1:4
                app.(['GenotypeDropDown_' Alphabet(arena) '_2']).Items = genotypeList;
            end

            % Add Experimenter options    
            app.ExperimenterDropDown.Items = {'Becca', 'Evyn'};
            
            % Add day/night incubator options    
            app.IncubatorDropDown.Items = {'Day', 'Night'};
           
            % Import and Load Temperature Protocol Options
            xlFile = [baseGoogleDrive 'Quad Bowl Experiments.xlsx'];
            [~,~,excelfile] = xlsread(xlFile, 'Temp Protocols');
            protocolList = excelfile(:,1);
            app.TemperatureProtocolDropDown.Items = protocolList;

            % Set the options for Sex
            sexList = {'Mixed', 'Female', 'Male', 'V. Female', 'V. Male', 'Unknown'};
            for arena = 1:4
                app.(['SexDropDown_' Alphabet(arena)]).Items = sexList;
            end
            for arena = 1:4
                app.(['SexDropDown_' Alphabet(arena) '_2']).Items = sexList;
            end
            
        end

        % Value changed function: DropDown_A2
        function DropDown_A2ValueChanged(app, event)
            % match all other well 2 values to Arena A2 values
            if strcmpi(app.ArenaAMasterSwitch_Wells_2.Value,'On') %well toggle 'On'
                app.DropDown_B2.Value = app.DropDown_A2.Value;
                app.DropDown_C2.Value = app.DropDown_A2.Value;
                app.DropDown_D2.Value = app.DropDown_A2.Value;
            end
            
        end

        % Value changed function: DropDown_A3
        function DropDown_A3ValueChanged(app, event)
            % match all other well 3 values to Arena A3 values
            if strcmpi(app.ArenaAMasterSwitch_Wells_2.Value,'On') %well toggle 'On'
                app.DropDown_B3.Value = app.DropDown_A3.Value;
                app.DropDown_C3.Value = app.DropDown_A3.Value;
                app.DropDown_D3.Value = app.DropDown_A3.Value;
            end
        end

        % Value changed function: DropDown_A4
        function DropDown_A4ValueChanged(app, event)
           % match all other well 4 values to Arena A4 values
            if strcmpi(app.ArenaAMasterSwitch_Wells_2.Value,'On') %well toggle 'On'
                app.DropDown_B4.Value = app.DropDown_A4.Value;
                app.DropDown_C4.Value = app.DropDown_A4.Value;
                app.DropDown_D4.Value = app.DropDown_A4.Value;
            end
        end

        % Value changed function: GenotypeDropDown_A
        function GenotypeDropDown_AValueChanged(app, event)
            % match all other arena genotypes to Arena A genotype
            if strcmpi(app.ArenaAMasterSwitch_Genotype_2.Value,'On') %well toggle 'On'
                app.GenotypeDropDown_B.Value = app.GenotypeDropDown_A.Value;
                app.GenotypeDropDown_C.Value = app.GenotypeDropDown_A.Value;
                app.GenotypeDropDown_D.Value = app.GenotypeDropDown_A.Value;
            end
        end

        % Value changed function: ArenaAMasterSwitch_Wells
        function ArenaAMasterSwitch_WellsValueChanged(app, event)
            % adjust all the well selections to match Arena A's selection
            if strcmpi(app.ArenaAMasterSwitch_Wells_2.Value,'On') %well toggle 'On'
                DropDown_A1_2ValueChanged(app, event)
                DropDown_A2_2ValueChanged(app, event)
                DropDown_A3_2ValueChanged(app, event)
                DropDown_A4_2ValueChanged(app, event)
            end
            
        end

        % Value changed function: ArenaAMasterSwitch_Genotype
        function ArenaAMasterSwitch_GenotypeValueChanged(app, event)
            if strcmpi(app.ArenaAMasterSwitch_Genotype.Value,'On') %well toggle 'On'
                GenotypeDropDown_A_2ValueChanged(app, event)
            end
        end

        % Value changed function: SaveParametersButton
        function SaveParametersButtonValueChanged(app, event)
            % Save all the selected parameters 
            parameters = [];
            parameters.FPS = app.VideoFPSSpinner.Value;
            parameters.session_length = app.SessionTimeSpinner.Value;
            parameters.video_length = app.VideolengthSpinner.Value;
            parameters.protocol = app.TemperatureProtocolDropDown.Value;
            parameters.numVids = app.VideocountEditField.Value;
            parameters.experimenter = app.ExperimenterDropDown.Value;
            DN = app.IncubatorDropDown.Value;
            switch DN
                case 'Day'
                    parameters.day_night = 'D';
                case 'Night'
                    parameters.day_night = 'N';
            end
            
            
            % Export the saved information to the folder for the experiment
            global video_path tempLogPath
            basePath = 'F:\Evyn\DATA\';
            dirName = strrep(datestr(datetime, 'mm-dd-yyyy'), '-', '.');
            parameters.date = dirName;
            video_path = [basePath, dirName, '\'];
            if ~isfolder(video_path)
                mkdir(video_path);
            end
            cam1 = strcmp(app.Camera1Switch.Value,'On');
            cam2 = strcmp(app.Camera2Switch.Value,'On');
            
            global xlRows
            xlRows = []; %reset this so that you can't overwrite a previous experiment

            % PURPLE CAM:
            if cam1    
                % Experiment:
                parameters.expID = app.VideoNameEdit.Value;
                parameters.videoName = app.VideoNameEdit.Value;
                % Arena Information:
                for arena = 1:4
                    %genotype
                    parameters.(['Arena' Alphabet(arena)]).genotype = ...
                        app.(['GenotypeDropDown_' Alphabet(arena)]).Value; 
                    %sex
                    parameters.(['Arena' Alphabet(arena)]).sex = ...
                        app.(['SexDropDown_' Alphabet(arena)]).Value; 
                    %starved hours
                    parameters.(['Arena' Alphabet(arena)]).starved_hours = ...
                        app.(['StarvedhoursEditField_' Alphabet(arena)]).Value; 
                    for well = 1:4
                        % well contents
                        parameters.(['Arena' Alphabet(arena)]).(['well_' num2str(well)]) = ...
                            app.(['DropDown_' Alphabet(arena) num2str(well)]).Value;
                    end
                end  
                % save parameter file
                if isfile([video_path parameters.videoName 'dataMat.mat']) %prevent overwriting the file
                    warndlg('Check the experiment name to not overwrite files')
                    return
                end
                save([video_path parameters.videoName ' parameters.mat'],"parameters")
                
                % Make a temperature log file:
                tempLogPath = [video_path parameters.videoName '_RampLog.csv'];
                fclose(fopen(tempLogPath, 'w+'));
            end

            % BLUE CAM:
            if cam2         
                % Experiment:
                parameters.expID = app.VideoNameEdit_2.Value;
                parameters.videoName = app.VideoNameEdit_2.Value;
                % Arena Information:
                for arena = 1:4
                    %genotype
                    parameters.(['Arena' Alphabet(arena)]).genotype = ...
                        app.(['GenotypeDropDown_' Alphabet(arena) '_2']).Value; 
                    %sex
                    parameters.(['Arena' Alphabet(arena)]).sex = ...
                        app.(['SexDropDown_' Alphabet(arena) '_2']).Value; 
                    %starved hours
                    parameters.(['Arena' Alphabet(arena)]).starved_hours = ...
                        app.(['StarvedhoursEditField_' Alphabet(arena) '_2']).Value; 
                    for well = 1:4
                        % well contents
                        parameters.(['Arena' Alphabet(arena)]).(['well_' num2str(well)]) = ...
                            app.(['DropDown_' Alphabet(arena) num2str(well) '_2']).Value;
                    end
                end  
                % save parameter file
                if isfile([video_path parameters.videoName 'dataMat.mat']) %prevent overwriting the file
                    warndlg('Check the experiment name to not overwrite files')
                    return
                end
                save([video_path parameters.videoName ' parameters.mat'],"parameters")
                
                % Make a temperature log file ONLY if there is not already
                % one from the first camera
                if ~cam1
                    tempLogPath = [video_path parameters.videoName '_RampLog.csv'];
                    fclose(fopen(tempLogPath, 'w+'));
                end
            end
            disp('Parameters saved')
            
        end

        % Value changed function: SessionTimeSpinner
        function SessionTimeSpinnerValueChanged(app, event)
            % Update the number of videos that will recorded 
            totalTime = app.SessionTimeSpinner.Value;
            vidLength = app.VideolengthSpinner.Value;      
            numVids = ceil(totalTime/vidLength); 
            app.VideocountEditField.Value = numVids;
        end

        % Value changed function: VideolengthSpinner
        function VideolengthSpinnerValueChanged(app, event)
            % Update the number of videos that will recorded 
            totalTime = app.SessionTimeSpinner.Value;
            vidLength = app.VideolengthSpinner.Value;      
            numVids = ceil(totalTime/vidLength); 
            app.VideocountEditField.Value = numVids;
            
        end

        % Value changed function: PreviewButton
        function PreviewButtonValueChanged(app, event)
            global vid1 
            value = app.PreviewButton.Value;
            if value==0
                closepreview(vid1)
            elseif value==1
                try 
                    preview(vid1)
                catch 
                    warndlg('Initialize camera first')
                end
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % RUN THE EXPERIMENT: %TODO
            global vid1 vid2 video_path tempLogPath src1 src2
            closepreview(vid1); closepreview(vid2)   
            app.Lamp.Color = 'red';
            
            % pull key parameters
            numVids = app.VideocountEditField.Value;
            app.Gauge.Value = 0;
            
            disk_log = 'disk';
            vid_quality = 65;
            FPS = app.VideoFPSSpinner.Value;
            vid_length_pause = app.VideolengthSpinner.Value*60;
            %reup the cam FPS in case it has changed since the cams were initialized
            src1.FrameRate = FPS; 
            src2.FrameRate = FPS;
            
            % camera selections
            cam1 = strcmp(app.Camera1Switch.Value,'On');
            cam2 = strcmp(app.Camera2Switch.Value,'On');


            [tempLogStart_1, tempLogStart_2, tempLogEnd1, tempLogEnd2] = deal([]);
                for n = 1:numVids
                   % Set video 1 saving
                    if cam1
                        tempLogStart_1(n,:) = [n, logTempNow(tempLogPath)];
                        diskLogger1 = VideoWriter([video_path app.VideoNameEdit.Value '_' num2str(n) '.avi'], 'Motion JPEG AVI');
                        vid1.LoggingMode = disk_log;   
                        diskLogger1.Quality = vid_quality;
                        diskLogger1.FrameRate = FPS; 
                        vid1.DiskLogger = diskLogger1;  
                        start(vid1);
                    end
                    % Set video 2 saving
                    if cam2
                        tempLogStart_2(n,:) = [n, logTempNow(tempLogPath)];
                        diskLogger2 = VideoWriter([video_path app.VideoNameEdit_2.Value '_' num2str(n) '.avi'], 'Motion JPEG AVI');
                        vid2.LoggingMode = disk_log;   
                        diskLogger2.Quality = vid_quality;
                        diskLogger2.FrameRate = FPS; 
                        vid2.DiskLogger = diskLogger2;  
                        start(vid2)
                    end
                    % recording
                    pause(vid_length_pause) % pause for the duration of each video
                    if cam1
                        stop(vid1)
                        tempLogEnd1(n,:) = [n, logTempNow(tempLogPath)];
                    end
                    if cam2
                        stop(vid2)
                        tempLogEnd2(n,:) = [n, logTempNow(tempLogPath)];
                    end
                    fprintf(['\n' num2str(n) ' / ' num2str(numVids)  '\n'])
                    app.Gauge.Value = (n/numVids)*100;
                end
               
                 % save exp 1 data
                 if cam1
                     load([video_path app.VideoNameEdit.Value ' parameters.mat'],"parameters")
                     parameters.numVids = numVids;
                     parameters.video_path = video_path;
                     tempLogStart = tempLogStart_1; tempLogEnd = tempLogEnd1;
                     save([video_path parameters.expID ' dataMat.mat'],"parameters", "tempLogEnd", "tempLogStart")
                 end
                 % save exp 2 data
                 if cam2
                     load([video_path app.VideoNameEdit_2.Value ' parameters.mat'],"parameters")
                     parameters.numVids = numVids;
                     parameters.video_path = video_path;
                     tempLogStart = tempLogStart_2; tempLogEnd = tempLogEnd2;
                     save([video_path parameters.expID ' dataMat.mat'],"parameters", "tempLogEnd", "tempLogStart")
                 end

            fprintf(['\n' 'Done' '\n']) 
            app.Lamp.Color = 'green';

            % % determine which cameras are selected
            % if strcmp(app.Camera1Switch.Value,'On') && strcmp(app.Camera2Switch.Value,'On') % DUAL CAM
            %     tempLogStart_1 = []; tempLogStart_2 = [];
            %     for n = 1:numVids
            %         % pull the current temp reading from the chiller log
            %         tempLogStart_1(n,:) = [n, logTempNow(tempLogPath_1)];
            %         tempLogStart_2(n,:) = [n, logTempNow(tempLogPath_2)];
            %         % Set video 1 saving
            %         diskLogger1 = VideoWriter([video_path app.VideoNameEdit.Value '_' num2str(n) '.avi'], 'Motion JPEG AVI');
            %         vid1.LoggingMode = disk_log;   
            %         diskLogger1.Quality = vid_quality;
            %         diskLogger1.FrameRate = FPS; 
            %         vid1.DiskLogger = diskLogger1;  
            %         % Set video 2 saving
            %         diskLogger2 = VideoWriter([video_path app.VideoNameEdit_2.Value '_' num2str(n) '.avi'], 'Motion JPEG AVI');
            %         vid2.LoggingMode = disk_log;   
            %         diskLogger2.Quality = vid_quality;
            %         diskLogger2.FrameRate = FPS; 
            %         vid2.DiskLogger = diskLogger2;  
            %         % start recording
            %         start(vid1); start(vid2) 
            %         pause(vid_length_pause) % pause for the duration of each video
            %         stop(vid1); 
            %         tempLogEnd1(n,:) = [n, logTempNow(tempLogPath_1)];
            %         stop(vid2);
            %         tempLogEnd2(n,:) = [n, logTempNow(tempLogPath_2)];
            %         fprintf(['\n' num2str(n) ' / ' num2str(numVids)  '\n'])
            %         app.Gauge.Value = (n/numVids)*100;
            %     end
            %     % save exp 1 data
            %      load([video_path app.VideoNameEdit.Value ' parameters.mat'],"parameters")
            %      parameters.numVids = numVids;
            %      parameters.video_path = video_path;
            %      tempLogStart = tempLogStart_1; tempLogEnd = tempLogEnd1;
            %      save([video_path parameters.expID ' dataMat.mat'],"parameters", "tempLogEnd", "tempLogStart")
            %      % save exp 2 data
            %      load([video_path app.VideoNameEdit_2.Value ' parameters.mat'],"parameters")
            %      parameters.numVids = numVids;
            %      parameters.video_path = video_path;
            %      tempLogStart = tempLogStart_2; tempLogEnd = tempLogEnd2;
            %      save([video_path parameters.expID ' dataMat.mat'],"parameters", "tempLogEnd", "tempLogStart")
            % 
            % 
            % 
            % elseif strcmp(app.Camera1Switch.Value,'On') && strcmp(app.Camera2Switch.Value,'Off') % PURPLE CAM ONLY
            %          tempLogStart_1 = [];
            %         for n = 1:numVids
            %             % pull the current temp reading from the chiller log
            %             tempLogStart_1(n,:) = [n, logTempNow(tempLogPath_1)];
            %             % Set video 1 saving
            %             diskLogger1 = VideoWriter([video_path app.VideoNameEdit.Value '_' num2str(n) '.avi'], 'Motion JPEG AVI');
            %             vid1.LoggingMode = disk_log;   
            %             diskLogger1.Quality = vid_quality;
            %             diskLogger1.FrameRate = FPS; 
            %             vid1.DiskLogger = diskLogger1;   
            %             % start recording
            %             start(vid1);
            %             pause(vid_length_pause) % pause for the duration of each video
            %             stop(vid1); 
            %             % pull the current temp reading from the chiller log
            %             tempLogEnd1(n,:) = [n, logTempNow(tempLogPath_1)];
            %             fprintf(['\n' num2str(n) ' / ' num2str(numVids)  '\n'])
            %             app.Gauge.Value = (n/numVids)*100;
            %         end
            % elseif strcmp(app.Camera1Switch.Value,'Off') && strcmp(app.Camera2Switch.Value,'On') % BLUE CAM ONLY
            %          tempLogStart_2 = [];
            %          for n = 1:numVids
            %             % pull the current temp reading from the chiller log
            %             tempLogStart_2(n,:) = [n, logTempNow(tempLogPath_2)];
            %             % Set video 2 saving
            %             diskLogger2 = VideoWriter([video_path app.VideoNameEdit_2.Value '_' num2str(n) '.avi'], 'Motion JPEG AVI');
            %             vid2.LoggingMode = disk_log;   
            %             diskLogger2.Quality = vid_quality;
            %             diskLogger2.FrameRate = FPS; 
            %             vid2.DiskLogger = diskLogger2;  
            %             % start recording
            %             start(vid2) 
            %             pause(vid_length_pause) % pause for the duration of each video
            %             stop(vid2)
            %             % pull the current temp reading from the chiller log
            %             tempLogEnd2(n,:) = [n, logTempNow(tempLogPath_2)];
            %             fprintf(['\n' num2str(n) ' / ' num2str(numVids)  '\n'])
            %             app.Gauge.Value = (n/numVids)*100;
            %          end
            % end
            % 
        end

        % Button pushed function: SendtoExcelButton
        function SendtoExcelButtonPushed(app, event)
            global video_path xlRows
            parameters = [];
            if strcmp(app.Camera1Switch.Value,'On')
                load([video_path app.VideoNameEdit.Value ' parameters.mat'],"parameters")
                rows = writeQuadExptoExcel(parameters);
                xlRows(1).rows = rows;
            end
            parameters = [];
            if strcmp(app.Camera2Switch.Value,'On')
                load([video_path app.VideoNameEdit_2.Value ' parameters.mat'],"parameters")
                rows = writeQuadExptoExcel(parameters);
                xlRows(2).rows = rows;
            end
        end

        % Value changed function: SexDropDown_A
        function SexDropDown_AValueChanged(app, event)
            value = app.SexDropDown_A.Value;
            if strcmpi(app.SexSwitch_2.Value,'On')
                app.SexDropDown_B.Value = value;
                app.SexDropDown_C.Value = value;
                app.SexDropDown_D.Value = value;
            end
        end

        % Value changed function: StarvedhoursEditField_A
        function StarvedhoursEditField_AValueChanged(app, event)
            value = app.StarvedhoursEditField_A.Value;
            if strcmp(app.StarvedSwitch_2.Value,'On')
                app.StarvedhoursEditField_B.Value = value;
                app.StarvedhoursEditField_C.Value = value;
                app.StarvedhoursEditField_D.Value = value;
            end
        end

        % Value changed function: StarvedSwitch
        function StarvedSwitchValueChanged(app, event)
            
            if strcmp(app.StarvedSwitch.Value,'On')
                value = app.StarvedhoursEditField_A_2.Value;
                app.StarvedhoursEditField_B_2.Value = value;
                app.StarvedhoursEditField_C_2.Value = value;
                app.StarvedhoursEditField_D_2.Value = value;
            end
        end

        % Value changed function: SexSwitch
        function SexSwitchValueChanged(app, event)
            if strcmpi(app.SexSwitch.Value,'On')
                value = app.SexDropDown_A.Value;
                app.SexDropDown_B_2.Value = value;
                app.SexDropDown_C_2.Value = value;
                app.SexDropDown_D_2.Value = value;
            end
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            global src1 vid1
            FPS = app.VideoFPSSpinner.Value; %pull the FPS from the recording parameters section
            camNum = 1;
            if app.BackCamCheckBox.Value
                camNum = 3;
            end
            try [src1, vid1] = Initialize2Cameras(FPS,camNum);
            catch 
                imaqreset 
                warndlg('Reset cameras, reinitialize the cameras')
                    [src1, vid1] = Initialize2Cameras(FPS,1);
            end
        end

        % Button pushed function: InitializeButton_2
        function InitializeButton_2Pushed(app, event)
            global src2 vid2
            FPS = app.VideoFPSSpinner.Value; %pull the FPS from the recording parameters section
            try [src2, vid2] = Initialize2Cameras(FPS,2);
            catch 
                imaqreset 
                warndlg('Reset cameras, reinitialize the cameras')
                    [src2, vid2] = Initialize2Cameras(FPS,2);
            end
        end

        % Value changed function: PreviewButton_2
        function PreviewButton_2ValueChanged(app, event)
           global vid2 
           value = app.PreviewButton_2.Value;
            if value==0
                closepreview(vid2)
            elseif value==1
                try 
                    preview(vid2)
                catch 
                    warndlg('Initialize camera first')
                end
            end
            
        end

        % Button pushed function: camresetButton
        function camresetButtonPushed(app, event)
            imaqreset
        end

        % Value changed function: DropDown_A1_2
        function DropDown_A1_2ValueChanged(app, event)
            value = app.DropDown_A1_2.Value;
            % match all other well 1 values to Arena A1 values
            if strcmpi(app.ArenaAMasterSwitch_Wells.Value,'On') %well toggle 'On'
                app.DropDown_B1_2.Value = value;
                app.DropDown_C1_2.Value = value;
                app.DropDown_D1_2.Value = value;
            end
        end

        % Value changed function: GenotypeDropDown_A_2
        function GenotypeDropDown_A_2ValueChanged(app, event)
            value = app.GenotypeDropDown_A_2.Value;
            if strcmpi(app.ArenaAMasterSwitch_Genotype.Value,'On') %well toggle 'On'
                app.GenotypeDropDown_B_2.Value = value;
                app.GenotypeDropDown_C_2.Value = value;
                app.GenotypeDropDown_D_2.Value = value;
            end
        end

        % Value changed function: SexDropDown_A_2
        function SexDropDown_A_2ValueChanged(app, event)
            value = app.SexDropDown_A_2.Value;
            if strcmpi(app.SexSwitch.Value,'On')
                app.SexDropDown_B.Value = value;
                app.SexDropDown_C.Value = value;
                app.SexDropDown_D.Value = value;
            end
        end

        % Value changed function: DropDown_A2_2
        function DropDown_A2_2ValueChanged(app, event)
            value = app.DropDown_A2_2.Value;
            % match all other well 1 values to Arena A1 values
            if strcmpi(app.ArenaAMasterSwitch_Wells.Value,'On') %well toggle 'On'
                app.DropDown_B2_2.Value = value;
                app.DropDown_C2_2.Value = value;
                app.DropDown_D2_2.Value = value;
            end
        end

        % Value changed function: DropDown_A3_2
        function DropDown_A3_2ValueChanged(app, event)
            value = app.DropDown_A3_2.Value;
            % match all other well 1 values to Arena A1 values
            if strcmpi(app.ArenaAMasterSwitch_Wells.Value,'On') %well toggle 'On'
                app.DropDown_B3_2.Value = value;
                app.DropDown_C3_2.Value = value;
                app.DropDown_D3_2.Value = value;
            end
        end

        % Value changed function: DropDown_A4_2
        function DropDown_A4_2ValueChanged(app, event)
            value = app.DropDown_A4_2.Value;
            % match all other well 1 values to Arena A1 values
            if strcmpi(app.ArenaAMasterSwitch_Wells.Value,'On') %well toggle 'On'
                app.DropDown_B4_2.Value = value;
                app.DropDown_C4_2.Value = value;
                app.DropDown_D4_2.Value = value;
            end
        end

        % Value changed function: StarvedhoursEditField_A_2
        function StarvedhoursEditField_A_2ValueChanged(app, event)
            value = app.StarvedhoursEditField_A_2.Value;
            if strcmp(app.StarvedSwitch.Value,'On')
                app.StarvedhoursEditField_B.Value = value;
                app.StarvedhoursEditField_C.Value = value;
                app.StarvedhoursEditField_D.Value = value;
            end
        end

        % Value changed function: ArenaAMasterSwitch_Wells_2
        function ArenaAMasterSwitch_Wells_2ValueChanged(app, event)
           
            % adjust all the well selections to match Arena A's selection
            if strcmpi(app.ArenaAMasterSwitch_Wells_2.Value,'On') %well toggle 'On'
                DropDown_A1ValueChanged(app, event)
                DropDown_A2ValueChanged(app, event)
                DropDown_A3ValueChanged(app, event)
                DropDown_A4ValueChanged(app, event)
            end
        end

        % Value changed function: ArenaAMasterSwitch_Genotype_2
        function ArenaAMasterSwitch_Genotype_2ValueChanged(app, event)
%             value = app.ArenaAMasterSwitch_Genotype_2.Value;
             if strcmpi(app.ArenaAMasterSwitch_Genotype_2.Value,'On') %well toggle 'On'
                GenotypeDropDown_AValueChanged(app, event)
            end
        end

        % Value changed function: SexSwitch_2
        function SexSwitch_2ValueChanged(app, event)
            value = app.SexSwitch_2.Value;
            if strcmpi(app.SexSwitch_2.Value,'On')
                value = app.SexDropDown_A.Value;
                app.SexDropDown_B.Value = value;
                app.SexDropDown_C.Value = value;
                app.SexDropDown_D.Value = value;
            end
        end

        % Value changed function: StarvedSwitch_2
        function StarvedSwitch_2ValueChanged(app, event)
            
            if strcmp(app.StarvedSwitch_2.Value,'On')
                value = app.StarvedhoursEditField_A.Value;
                app.StarvedhoursEditField_B.Value = value;
                app.StarvedhoursEditField_C.Value = value;
                app.StarvedhoursEditField_D.Value = value;
            end
        end

        % Button pushed function: OutlineButton
        function OutlineButtonPushed(app, event)
            global vid1 video_path
            img = getsnapshot(vid1); % current frame from camera 1
            img = imadjust(img, [55/255,150/255]);
            savePath = [video_path app.VideoNameEdit.Value];
            getArenaOutlines(img,savePath)
        end

        % Button pushed function: OutlineButton_2
        function OutlineButton_2Pushed(app, event)
            global vid2 video_path
            img = getsnapshot(vid2); % current frame from camera 1
            img = imadjust(img, [55/255,150/255]);
            savePath = [video_path app.VideoNameEdit_2.Value];
            getArenaOutlines(img,savePath)
        end

        % Button pushed function: CountButton
        function CountButtonPushed(app, event)
            global vid1 video_path xlRows
            if isempty(xlRows) 
                disp('Write experiments to excel first then try again')
                return
            end
            try rows = (xlRows(1).rows);
            catch
                disp('Write experiments to excel first then try again')
                return
            end
            savePath = [video_path app.VideoNameEdit.Value];
            img = [];
            for i = 1:3
                a =  getsnapshot(vid1); % current frame from camera 1
                a = imadjust(a,[53/255,149/255]); % adjust the contrast
                img(i).data = a;
                pause(1)
            end
            getFlyCount(img,savePath,rows)
        end

        % Button pushed function: CountButton_2
        function CountButton_2Pushed(app, event)
            global vid2 video_path xlRows
            if isempty(xlRows) 
                disp('Write experiments to excel first then try again')
                return
            end
            try rows = (xlRows(2).rows);
            catch
                disp('Write experiments to excel first then try again')
                return
            end
            savePath = [video_path app.VideoNameEdit_2.Value];
           img = [];
            for i = 1:3
                a =  getsnapshot(vid2); % current frame from camera 2
                a = imadjust(a,[53/255,149/255]); % adjust the contrast
                img(i).data = a;
                pause(1)
            end
            getFlyCount(img,savePath,rows)
            getFlyCount(img,savePath,rows)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {603, 603};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {524, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.C1Tab = uitab(app.TabGroup);
            app.C1Tab.Title = 'C1';
            app.ArenaAPanel = uipanel(app.C1Tab);
            app.ArenaAPanel.Title = 'Arena A';
            app.Label = uilabel(app.ArenaAPanel);
            app.Label.Text = '1';
            app.DropDown_A1 = uidropdown(app.ArenaAPanel);
            app.DropDown_A1.ValueChangedFcn = createCallbackFcn(app, @DropDown_A1ValueChanged, true);
            app.Label_2 = uilabel(app.ArenaAPanel);
            app.Label_2.Text = '2';
            app.DropDown_A2 = uidropdown(app.ArenaAPanel);
            app.DropDown_A2.ValueChangedFcn = createCallbackFcn(app, @DropDown_A2ValueChanged, true);
            app.Label_3 = uilabel(app.ArenaAPanel);
            app.Label_3.Text = '3';
            app.DropDown_A3 = uidropdown(app.ArenaAPanel);
            app.DropDown_A3.ValueChangedFcn = createCallbackFcn(app, @DropDown_A3ValueChanged, true);
            app.Label_4 = uilabel(app.ArenaAPanel);
            app.Label_4.Text = '4';
            app.DropDown_A4 = uidropdown(app.ArenaAPanel);
            app.DropDown_A4.ValueChangedFcn = createCallbackFcn(app, @DropDown_A4ValueChanged, true);
            app.GenotypeDropDown_A = uidropdown(app.ArenaAPanel);
            app.GenotypeDropDown_A.ValueChangedFcn = createCallbackFcn(app, @GenotypeDropDown_AValueChanged, true);
            app.WellLabel = uilabel(app.ArenaAPanel);
            app.WellLabel.Text = 'Well';
            app.GenotypeLabel = uilabel(app.ArenaAPanel);
            app.GenotypeLabel.Text = 'Genotype';
            app.ContentsLabel = uilabel(app.ArenaAPanel);
            app.ContentsLabel.Text = 'Contents';
            app.SexDropDownLabel = uilabel(app.ArenaAPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown_A = uidropdown(app.ArenaAPanel);
            app.SexDropDown_A.ValueChangedFcn = createCallbackFcn(app, @SexDropDown_AValueChanged, true);
            app.StarvedhoursEditFieldLabel = uilabel(app.ArenaAPanel);
            app.StarvedhoursEditFieldLabel.Text = 'Starved hours';
            app.StarvedhoursEditField_A = uieditfield(app.ArenaAPanel, 'numeric');
            app.StarvedhoursEditField_A.ValueChangedFcn = createCallbackFcn(app, @StarvedhoursEditField_AValueChanged, true);
            app.ArenaBPanel = uipanel(app.C1Tab);
            app.ArenaBPanel.Title = 'Arena B';
            app.DropDown_5Label = uilabel(app.ArenaBPanel);
            app.DropDown_5Label.Text = '1';
            app.DropDown_B1 = uidropdown(app.ArenaBPanel);
            app.DropDown_6Label = uilabel(app.ArenaBPanel);
            app.DropDown_6Label.Text = '2';
            app.DropDown_B2 = uidropdown(app.ArenaBPanel);
            app.DropDown_7Label = uilabel(app.ArenaBPanel);
            app.DropDown_7Label.Text = '3';
            app.DropDown_B3 = uidropdown(app.ArenaBPanel);
            app.DropDown_8Label = uilabel(app.ArenaBPanel);
            app.DropDown_8Label.Text = '4';
            app.DropDown_B4 = uidropdown(app.ArenaBPanel);
            app.GenotypeDropDown_B = uidropdown(app.ArenaBPanel);
            app.WellLabel_2 = uilabel(app.ArenaBPanel);
            app.WellLabel_2.Text = 'Well';
            app.GenotypeLabel_2 = uilabel(app.ArenaBPanel);
            app.GenotypeLabel_2.Text = 'Genotype';
            app.ContentsLabel_2 = uilabel(app.ArenaBPanel);
            app.ContentsLabel_2.Text = 'Contents';
            app.SexDropDown_2Label = uilabel(app.ArenaBPanel);
            app.SexDropDown_2Label.Text = 'Sex';
            app.SexDropDown_B = uidropdown(app.ArenaBPanel);
            app.StarvedhoursEditField_2Label = uilabel(app.ArenaBPanel);
            app.StarvedhoursEditField_2Label.Text = 'Starved hours';
            app.StarvedhoursEditField_B = uieditfield(app.ArenaBPanel, 'numeric');
            app.ArenaCPanel = uipanel(app.C1Tab);
            app.ArenaCPanel.Title = 'Arena C';
            app.DropDown_9Label = uilabel(app.ArenaCPanel);
            app.DropDown_9Label.Text = '1';
            app.DropDown_C1 = uidropdown(app.ArenaCPanel);
            app.DropDown_10Label = uilabel(app.ArenaCPanel);
            app.DropDown_10Label.Text = '2';
            app.DropDown_C2 = uidropdown(app.ArenaCPanel);
            app.DropDown_11Label = uilabel(app.ArenaCPanel);
            app.DropDown_11Label.Text = '3';
            app.DropDown_C3 = uidropdown(app.ArenaCPanel);
            app.DropDown_12Label = uilabel(app.ArenaCPanel);
            app.DropDown_12Label.Text = '4';
            app.DropDown_C4 = uidropdown(app.ArenaCPanel);
            app.GenotypeDropDown_C = uidropdown(app.ArenaCPanel);
            app.WellLabel_3 = uilabel(app.ArenaCPanel);
            app.WellLabel_3.Text = 'Well';
            app.GenotypeLabel_3 = uilabel(app.ArenaCPanel);
            app.GenotypeLabel_3.Text = 'Genotype';
            app.ContentsLabel_3 = uilabel(app.ArenaCPanel);
            app.ContentsLabel_3.Text = 'Contents';
            app.SexDropDown_3Label = uilabel(app.ArenaCPanel);
            app.SexDropDown_3Label.Text = 'Sex';
            app.SexDropDown_C = uidropdown(app.ArenaCPanel);
            app.StarvedhoursEditField_3Label = uilabel(app.ArenaCPanel);
            app.StarvedhoursEditField_3Label.Text = 'Starved hours';
            app.StarvedhoursEditField_C = uieditfield(app.ArenaCPanel, 'numeric');
            app.ArenaDPanel = uipanel(app.C1Tab);
            app.ArenaDPanel.Title = 'Arena D';
            app.DropDown_13Label = uilabel(app.ArenaDPanel);
            app.DropDown_13Label.Text = '1';
            app.DropDown_D1 = uidropdown(app.ArenaDPanel);
            app.DropDown_14Label = uilabel(app.ArenaDPanel);
            app.DropDown_14Label.Text = '2';
            app.DropDown_D2 = uidropdown(app.ArenaDPanel);
            app.DropDown_15Label = uilabel(app.ArenaDPanel);
            app.DropDown_15Label.Text = '3';
            app.DropDown_D3 = uidropdown(app.ArenaDPanel);
            app.DropDown_16Label = uilabel(app.ArenaDPanel);
            app.DropDown_16Label.Text = '4';
            app.DropDown_D4 = uidropdown(app.ArenaDPanel);
            app.GenotypeDropDown_D = uidropdown(app.ArenaDPanel);
            app.WellLabel_4 = uilabel(app.ArenaDPanel);
            app.WellLabel_4.Text = 'Well';
            app.GenotypeLabel_4 = uilabel(app.ArenaDPanel);
            app.GenotypeLabel_4.Text = 'Genotype';
            app.ContentsLabel_4 = uilabel(app.ArenaDPanel);
            app.ContentsLabel_4.Text = 'Contents';
            app.SexDropDown_4Label = uilabel(app.ArenaDPanel);
            app.SexDropDown_4Label.Text = 'Sex';
            app.SexDropDown_D = uidropdown(app.ArenaDPanel);
            app.StarvedhoursEditField_4Label = uilabel(app.ArenaDPanel);
            app.StarvedhoursEditField_4Label.Text = 'Starved hours';
            app.StarvedhoursEditField_D = uieditfield(app.ArenaDPanel, 'numeric');
            app.ArenaAMasterSwitchesPanel_2 = uipanel(app.C1Tab);
            app.ArenaAMasterSwitchesPanel_2.Title = 'Arena A Master Switches';
            app.WellsLabel_3 = uilabel(app.ArenaAMasterSwitchesPanel_2);
            app.WellsLabel_3.Text = 'Wells';
            app.ArenaAMasterSwitch_Wells_2 = uiswitch(app.ArenaAMasterSwitchesPanel_2, 'slider');
            app.ArenaAMasterSwitch_Wells_2.ValueChangedFcn = createCallbackFcn(app, @ArenaAMasterSwitch_Wells_2ValueChanged, true);
            app.ArenaAMasterSwitch_Wells_2.Value = 'On';
            app.GenotypeLabel_11 = uilabel(app.ArenaAMasterSwitchesPanel_2);
            app.GenotypeLabel_11.Text = 'Genotype';
            app.ArenaAMasterSwitch_Genotype_2 = uiswitch(app.ArenaAMasterSwitchesPanel_2, 'slider');
            app.ArenaAMasterSwitch_Genotype_2.ValueChangedFcn = createCallbackFcn(app, @ArenaAMasterSwitch_Genotype_2ValueChanged, true);
            app.ArenaAMasterSwitch_Genotype_2.Value = 'On';
            app.SexSwitch_2Label = uilabel(app.ArenaAMasterSwitchesPanel_2);
            app.SexSwitch_2Label.Text = 'Sex';
            app.SexSwitch_2 = uiswitch(app.ArenaAMasterSwitchesPanel_2, 'slider');
            app.SexSwitch_2.ValueChangedFcn = createCallbackFcn(app, @SexSwitch_2ValueChanged, true);
            app.StarvedSwitch_2Label = uilabel(app.ArenaAMasterSwitchesPanel_2);
            app.StarvedSwitch_2Label.Text = 'Starved';
            app.StarvedSwitch_2 = uiswitch(app.ArenaAMasterSwitchesPanel_2, 'slider');
            app.StarvedSwitch_2.ValueChangedFcn = createCallbackFcn(app, @StarvedSwitch_2ValueChanged, true);
            app.C2Tab = uitab(app.TabGroup);
            app.C2Tab.Title = 'C2';
            app.ArenaAPanel_2 = uipanel(app.C2Tab);
            app.ArenaAPanel_2.Title = 'Arena A';
            app.Label_5 = uilabel(app.ArenaAPanel_2);
            app.Label_5.Text = '1';
            app.DropDown_A1_2 = uidropdown(app.ArenaAPanel_2);
            app.DropDown_A1_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_A1_2ValueChanged, true);
            app.Label_6 = uilabel(app.ArenaAPanel_2);
            app.Label_6.Text = '2';
            app.DropDown_A2_2 = uidropdown(app.ArenaAPanel_2);
            app.DropDown_A2_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_A2_2ValueChanged, true);
            app.Label_7 = uilabel(app.ArenaAPanel_2);
            app.Label_7.Text = '3';
            app.DropDown_A3_2 = uidropdown(app.ArenaAPanel_2);
            app.DropDown_A3_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_A3_2ValueChanged, true);
            app.Label_8 = uilabel(app.ArenaAPanel_2);
            app.Label_8.Text = '4';
            app.DropDown_A4_2 = uidropdown(app.ArenaAPanel_2);
            app.DropDown_A4_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_A4_2ValueChanged, true);
            app.GenotypeDropDown_A_2 = uidropdown(app.ArenaAPanel_2);
            app.GenotypeDropDown_A_2.ValueChangedFcn = createCallbackFcn(app, @GenotypeDropDown_A_2ValueChanged, true);
            app.WellLabel_5 = uilabel(app.ArenaAPanel_2);
            app.WellLabel_5.Text = 'Well';
            app.GenotypeLabel_7 = uilabel(app.ArenaAPanel_2);
            app.GenotypeLabel_7.Text = 'Genotype';
            app.ContentsLabel_5 = uilabel(app.ArenaAPanel_2);
            app.ContentsLabel_5.Text = 'Contents';
            app.SexDropDownLabel_2 = uilabel(app.ArenaAPanel_2);
            app.SexDropDownLabel_2.Text = 'Sex';
            app.SexDropDown_A_2 = uidropdown(app.ArenaAPanel_2);
            app.SexDropDown_A_2.ValueChangedFcn = createCallbackFcn(app, @SexDropDown_A_2ValueChanged, true);
            app.StarvedhoursEditFieldLabel_2 = uilabel(app.ArenaAPanel_2);
            app.StarvedhoursEditFieldLabel_2.Text = 'Starved hours';
            app.StarvedhoursEditField_A_2 = uieditfield(app.ArenaAPanel_2, 'numeric');
            app.StarvedhoursEditField_A_2.ValueChangedFcn = createCallbackFcn(app, @StarvedhoursEditField_A_2ValueChanged, true);
            app.ArenaBPanel_2 = uipanel(app.C2Tab);
            app.ArenaBPanel_2.Title = 'Arena B';
            app.DropDown_5Label_2 = uilabel(app.ArenaBPanel_2);
            app.DropDown_5Label_2.Text = '1';
            app.DropDown_B1_2 = uidropdown(app.ArenaBPanel_2);
            app.DropDown_6Label_2 = uilabel(app.ArenaBPanel_2);
            app.DropDown_6Label_2.Text = '2';
            app.DropDown_B2_2 = uidropdown(app.ArenaBPanel_2);
            app.DropDown_7Label_2 = uilabel(app.ArenaBPanel_2);
            app.DropDown_7Label_2.Text = '3';
            app.DropDown_B3_2 = uidropdown(app.ArenaBPanel_2);
            app.DropDown_8Label_2 = uilabel(app.ArenaBPanel_2);
            app.DropDown_8Label_2.Text = '4';
            app.DropDown_B4_2 = uidropdown(app.ArenaBPanel_2);
            app.GenotypeDropDown_B_2 = uidropdown(app.ArenaBPanel_2);
            app.WellLabel_6 = uilabel(app.ArenaBPanel_2);
            app.WellLabel_6.Text = 'Well';
            app.GenotypeLabel_8 = uilabel(app.ArenaBPanel_2);
            app.GenotypeLabel_8.Text = 'Genotype';
            app.ContentsLabel_6 = uilabel(app.ArenaBPanel_2);
            app.ContentsLabel_6.Text = 'Contents';
            app.SexDropDown_2Label_2 = uilabel(app.ArenaBPanel_2);
            app.SexDropDown_2Label_2.Text = 'Sex';
            app.SexDropDown_B_2 = uidropdown(app.ArenaBPanel_2);
            app.StarvedhoursEditField_2Label_2 = uilabel(app.ArenaBPanel_2);
            app.StarvedhoursEditField_2Label_2.Text = 'Starved hours';
            app.StarvedhoursEditField_B_2 = uieditfield(app.ArenaBPanel_2, 'numeric');
            app.ArenaCPanel_2 = uipanel(app.C2Tab);
            app.ArenaCPanel_2.Title = 'Arena C';
            app.DropDown_9Label_2 = uilabel(app.ArenaCPanel_2);
            app.DropDown_9Label_2.Text = '1';
            app.DropDown_C1_2 = uidropdown(app.ArenaCPanel_2);
            app.DropDown_10Label_2 = uilabel(app.ArenaCPanel_2);
            app.DropDown_10Label_2.Text = '2';
            app.DropDown_C2_2 = uidropdown(app.ArenaCPanel_2);
            app.DropDown_11Label_2 = uilabel(app.ArenaCPanel_2);
            app.DropDown_11Label_2.Text = '3';
            app.DropDown_C3_2 = uidropdown(app.ArenaCPanel_2);
            app.DropDown_12Label_2 = uilabel(app.ArenaCPanel_2);
            app.DropDown_12Label_2.Text = '4';
            app.DropDown_C4_2 = uidropdown(app.ArenaCPanel_2);
            app.GenotypeDropDown_C_2 = uidropdown(app.ArenaCPanel_2);
            app.WellLabel_7 = uilabel(app.ArenaCPanel_2);
            app.WellLabel_7.Text = 'Well';
            app.GenotypeLabel_9 = uilabel(app.ArenaCPanel_2);
            app.GenotypeLabel_9.Text = 'Genotype';
            app.ContentsLabel_7 = uilabel(app.ArenaCPanel_2);
            app.ContentsLabel_7.Text = 'Contents';
            app.SexDropDown_3Label_2 = uilabel(app.ArenaCPanel_2);
            app.SexDropDown_3Label_2.Text = 'Sex';
            app.SexDropDown_C_2 = uidropdown(app.ArenaCPanel_2);
            app.StarvedhoursEditField_3Label_2 = uilabel(app.ArenaCPanel_2);
            app.StarvedhoursEditField_3Label_2.Text = 'Starved hours';
            app.StarvedhoursEditField_C_2 = uieditfield(app.ArenaCPanel_2, 'numeric');
            app.ArenaDPanel_2 = uipanel(app.C2Tab);
            app.ArenaDPanel_2.Title = 'Arena D';
            app.DropDown_13Label_2 = uilabel(app.ArenaDPanel_2);
            app.DropDown_13Label_2.Text = '1';
            app.DropDown_D1_2 = uidropdown(app.ArenaDPanel_2);
            app.DropDown_14Label_2 = uilabel(app.ArenaDPanel_2);
            app.DropDown_14Label_2.Text = '2';
            app.DropDown_D2_2 = uidropdown(app.ArenaDPanel_2);
            app.DropDown_15Label_2 = uilabel(app.ArenaDPanel_2);
            app.DropDown_15Label_2.Text = '3';
            app.DropDown_D3_2 = uidropdown(app.ArenaDPanel_2);
            app.DropDown_16Label_2 = uilabel(app.ArenaDPanel_2);
            app.DropDown_16Label_2.Text = '4';
            app.DropDown_D4_2 = uidropdown(app.ArenaDPanel_2);
            app.GenotypeDropDown_D_2 = uidropdown(app.ArenaDPanel_2);
            app.WellLabel_8 = uilabel(app.ArenaDPanel_2);
            app.WellLabel_8.Text = 'Well';
            app.GenotypeLabel_10 = uilabel(app.ArenaDPanel_2);
            app.GenotypeLabel_10.Text = 'Genotype';
            app.ContentsLabel_8 = uilabel(app.ArenaDPanel_2);
            app.ContentsLabel_8.Text = 'Contents';
            app.SexDropDown_4Label_2 = uilabel(app.ArenaDPanel_2);
            app.SexDropDown_4Label_2.Text = 'Sex';
            app.SexDropDown_D_2 = uidropdown(app.ArenaDPanel_2);
            app.StarvedhoursEditField_4Label_2 = uilabel(app.ArenaDPanel_2);
            app.StarvedhoursEditField_4Label_2.Text = 'Starved hours';
            app.StarvedhoursEditField_D_2 = uieditfield(app.ArenaDPanel_2, 'numeric');
            app.ArenaAMasterSwitchesPanel = uipanel(app.C2Tab);
            app.ArenaAMasterSwitchesPanel.Title = 'Arena A Master Switches';
            app.WellsLabel_2 = uilabel(app.ArenaAMasterSwitchesPanel);
            app.WellsLabel_2.Text = 'Wells';
            app.ArenaAMasterSwitch_Wells = uiswitch(app.ArenaAMasterSwitchesPanel, 'slider');
            app.ArenaAMasterSwitch_Wells.ValueChangedFcn = createCallbackFcn(app, @ArenaAMasterSwitch_WellsValueChanged, true);
            app.ArenaAMasterSwitch_Wells.Value = 'On';
            app.GenotypeLabel_6 = uilabel(app.ArenaAMasterSwitchesPanel);
            app.GenotypeLabel_6.Text = 'Genotype';
            app.ArenaAMasterSwitch_Genotype = uiswitch(app.ArenaAMasterSwitchesPanel, 'slider');
            app.ArenaAMasterSwitch_Genotype.ValueChangedFcn = createCallbackFcn(app, @ArenaAMasterSwitch_GenotypeValueChanged, true);
            app.ArenaAMasterSwitch_Genotype.Value = 'On';
            app.SexSwitchLabel = uilabel(app.ArenaAMasterSwitchesPanel);
            app.SexSwitchLabel.Text = 'Sex';
            app.SexSwitch = uiswitch(app.ArenaAMasterSwitchesPanel, 'slider');
            app.SexSwitch.ValueChangedFcn = createCallbackFcn(app, @SexSwitchValueChanged, true);
            app.StarvedSwitchLabel = uilabel(app.ArenaAMasterSwitchesPanel);
            app.StarvedSwitchLabel.Text = 'Starved';
            app.StarvedSwitch = uiswitch(app.ArenaAMasterSwitchesPanel, 'slider');
            app.StarvedSwitch.ValueChangedFcn = createCallbackFcn(app, @StarvedSwitchValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.RecordingParametersPanel = uipanel(app.RightPanel);
            app.RecordingParametersPanel.Title = 'Recording Parameters';
            app.VideolengthSpinnerLabel = uilabel(app.RecordingParametersPanel);
            app.VideolengthSpinnerLabel.Text = 'Video length';
            app.VideolengthSpinner = uispinner(app.RecordingParametersPanel);
            app.VideolengthSpinner.ValueChangedFcn = createCallbackFcn(app, @VideolengthSpinnerValueChanged, true);
            app.VideolengthSpinner.Value = 10;
            app.SessionTimeSpinnerLabel = uilabel(app.RecordingParametersPanel);
            app.SessionTimeSpinnerLabel.Text = 'Session Time';
            app.SessionTimeSpinner = uispinner(app.RecordingParametersPanel);
            app.SessionTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @SessionTimeSpinnerValueChanged, true);
            app.VideoFPSSpinnerLabel = uilabel(app.RecordingParametersPanel);
            app.VideoFPSSpinnerLabel.Text = 'Video FPS';
            app.VideoFPSSpinner = uispinner(app.RecordingParametersPanel);
            app.VideoFPSSpinner.Value = 3;
            app.PreviewButton = uibutton(app.RecordingParametersPanel, 'state');
            app.PreviewButton.ValueChangedFcn = createCallbackFcn(app, @PreviewButtonValueChanged, true);
            app.PreviewButton.Text = 'Preview';
            app.VideocountLabel = uilabel(app.RecordingParametersPanel);
            app.VideocountLabel.Text = 'Video count';
            app.VideocountEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.Camera1SwitchLabel = uilabel(app.RecordingParametersPanel);
            app.Camera1SwitchLabel.Text = 'Camera 1';
            app.Camera1Switch = uiswitch(app.RecordingParametersPanel, 'slider');
            app.Camera1Switch.Value = 'On';
            app.InitializeButton = uibutton(app.RecordingParametersPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.InitializeButton_2 = uibutton(app.RecordingParametersPanel, 'push');
            app.InitializeButton_2.ButtonPushedFcn = createCallbackFcn(app, @InitializeButton_2Pushed, true);
            app.InitializeButton_2.Text = 'Initialize';
            app.Camera2SwitchLabel = uilabel(app.RecordingParametersPanel);
            app.Camera2SwitchLabel.Text = 'Camera 2';
            app.Camera2Switch = uiswitch(app.RecordingParametersPanel, 'slider');
            app.Camera2Switch.Value = 'On';
            app.PreviewButton_2 = uibutton(app.RecordingParametersPanel, 'state');
            app.PreviewButton_2.ValueChangedFcn = createCallbackFcn(app, @PreviewButton_2ValueChanged, true);
            app.PreviewButton_2.Text = 'Preview';
            app.VideoNameEdit = uieditfield(app.RecordingParametersPanel, 'text');
            app.VideoNameEdit.Value = 'C1_';
            app.C1Label = uilabel(app.RecordingParametersPanel);
            app.C1Label.Text = 'C1';
            app.VideoNameEdit_2 = uieditfield(app.RecordingParametersPanel, 'text');
            app.VideoNameEdit_2.Value = 'C2_';
            app.C2Label = uilabel(app.RecordingParametersPanel);
            app.C2Label.Text = 'C2';
            app.camresetButton = uibutton(app.RecordingParametersPanel, 'push');
            app.camresetButton.ButtonPushedFcn = createCallbackFcn(app, @camresetButtonPushed, true);
            app.camresetButton.Text = 'cam reset';
            app.OutlineButton = uibutton(app.RecordingParametersPanel, 'push');
            app.OutlineButton.ButtonPushedFcn = createCallbackFcn(app, @OutlineButtonPushed, true);
            app.OutlineButton.Text = 'Outline';
            app.OutlineButton_2 = uibutton(app.RecordingParametersPanel, 'push');
            app.OutlineButton_2.ButtonPushedFcn = createCallbackFcn(app, @OutlineButton_2Pushed, true);
            app.OutlineButton_2.Text = 'Outline';
            app.CountButton = uibutton(app.RecordingParametersPanel, 'push');
            app.CountButton.ButtonPushedFcn = createCallbackFcn(app, @CountButtonPushed, true);
            app.CountButton.Text = 'Count';
            app.CountButton_2 = uibutton(app.RecordingParametersPanel, 'push');
            app.CountButton_2.ButtonPushedFcn = createCallbackFcn(app, @CountButton_2Pushed, true);
            app.CountButton_2.Text = 'Count';
            app.ExperimenterDropDownLabel = uilabel(app.RecordingParametersPanel);
            app.ExperimenterDropDownLabel.Text = 'Experimenter';
            app.ExperimenterDropDown = uidropdown(app.RecordingParametersPanel);
            app.IncubatorDropDownLabel = uilabel(app.RecordingParametersPanel);
            app.IncubatorDropDownLabel.Text = 'Incubator';
            app.IncubatorDropDown = uidropdown(app.RecordingParametersPanel);
            app.BackCamCheckBox = uicheckbox(app.RecordingParametersPanel);
            app.BackCamCheckBox.Text = 'Back Cam';
            app.TemperatureProtocolLabel = uilabel(app.RightPanel);
            app.TemperatureProtocolLabel.Text = 'Temperature Protocol';
            app.TemperatureProtocolDropDown = uidropdown(app.RightPanel);
            app.SaveParametersButton = uibutton(app.RightPanel, 'state');
            app.SaveParametersButton.ValueChangedFcn = createCallbackFcn(app, @SaveParametersButtonValueChanged, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.StartupLoadButton = uibutton(app.RightPanel, 'push');
            app.StartupLoadButton.ButtonPushedFcn = createCallbackFcn(app, @StartupLoadButtonPushed, true);
            app.StartupLoadButton.Text = 'Startup Load';
            app.SendtoExcelButton = uibutton(app.RightPanel, 'push');
            app.SendtoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoExcelButtonPushed, true);
            app.SendtoExcelButton.Text = 'Send to Excel';
            app.Gauge = uigauge(app.RightPanel, 'linear');
            app.Gauge.MajorTicks = [0 20 40 60 80 100];
            app.Lamp = uilamp(app.RightPanel);
            app.RUNButton = uibutton(app.RightPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Arena2GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end