classdef ArenaGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ArenaAPanel                   matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        DropDown_A1                   matlab.ui.control.DropDown
        Label_2                       matlab.ui.control.Label
        DropDown_A2                   matlab.ui.control.DropDown
        Label_3                       matlab.ui.control.Label
        DropDown_A3                   matlab.ui.control.DropDown
        Label_4                       matlab.ui.control.Label
        DropDown_A4                   matlab.ui.control.DropDown
        GenotypeDropDown_A            matlab.ui.control.DropDown
        WellLabel                     matlab.ui.control.Label
        GenotypeLabel                 matlab.ui.control.Label
        ContentsLabel                 matlab.ui.control.Label
        SexDropDownLabel              matlab.ui.control.Label
        SexDropDown_A                 matlab.ui.control.DropDown
        StarvedhoursEditFieldLabel    matlab.ui.control.Label
        StarvedhoursEditField_A       matlab.ui.control.NumericEditField
        ArenaBPanel                   matlab.ui.container.Panel
        DropDown_5Label               matlab.ui.control.Label
        DropDown_B1                   matlab.ui.control.DropDown
        DropDown_6Label               matlab.ui.control.Label
        DropDown_B2                   matlab.ui.control.DropDown
        DropDown_7Label               matlab.ui.control.Label
        DropDown_B3                   matlab.ui.control.DropDown
        DropDown_8Label               matlab.ui.control.Label
        DropDown_B4                   matlab.ui.control.DropDown
        GenotypeDropDown_B            matlab.ui.control.DropDown
        WellLabel_2                   matlab.ui.control.Label
        GenotypeLabel_2               matlab.ui.control.Label
        ContentsLabel_2               matlab.ui.control.Label
        SexDropDown_2Label            matlab.ui.control.Label
        SexDropDown_B                 matlab.ui.control.DropDown
        StarvedhoursEditField_2Label  matlab.ui.control.Label
        StarvedhoursEditField_B       matlab.ui.control.NumericEditField
        ArenaCPanel                   matlab.ui.container.Panel
        DropDown_9Label               matlab.ui.control.Label
        DropDown_C1                   matlab.ui.control.DropDown
        DropDown_10Label              matlab.ui.control.Label
        DropDown_C2                   matlab.ui.control.DropDown
        DropDown_11Label              matlab.ui.control.Label
        DropDown_C3                   matlab.ui.control.DropDown
        DropDown_12Label              matlab.ui.control.Label
        DropDown_C4                   matlab.ui.control.DropDown
        GenotypeDropDown_C            matlab.ui.control.DropDown
        WellLabel_3                   matlab.ui.control.Label
        GenotypeLabel_3               matlab.ui.control.Label
        ContentsLabel_3               matlab.ui.control.Label
        SexDropDown_3Label            matlab.ui.control.Label
        SexDropDown_C                 matlab.ui.control.DropDown
        StarvedhoursEditField_3Label  matlab.ui.control.Label
        StarvedhoursEditField_C       matlab.ui.control.NumericEditField
        ArenaDPanel                   matlab.ui.container.Panel
        DropDown_13Label              matlab.ui.control.Label
        DropDown_D1                   matlab.ui.control.DropDown
        DropDown_14Label              matlab.ui.control.Label
        DropDown_D2                   matlab.ui.control.DropDown
        DropDown_15Label              matlab.ui.control.Label
        DropDown_D3                   matlab.ui.control.DropDown
        DropDown_16Label              matlab.ui.control.Label
        DropDown_D4                   matlab.ui.control.DropDown
        GenotypeDropDown_D            matlab.ui.control.DropDown
        WellLabel_4                   matlab.ui.control.Label
        GenotypeLabel_4               matlab.ui.control.Label
        ContentsLabel_4               matlab.ui.control.Label
        SexDropDown_4Label            matlab.ui.control.Label
        SexDropDown_D                 matlab.ui.control.DropDown
        StarvedhoursEditField_4Label  matlab.ui.control.Label
        StarvedhoursEditField_D       matlab.ui.control.NumericEditField
        ExperimentControlsPanel       matlab.ui.container.Panel
        RUNButton                     matlab.ui.control.Button
        SendtoExcelButton             matlab.ui.control.Button
        ProgressGauge                 matlab.ui.control.SemicircularGauge
        Lamp                          matlab.ui.control.Lamp
        RightPanel                    matlab.ui.container.Panel
        ExperimentInformationLabel    matlab.ui.control.Label
        VideoNameEdit                 matlab.ui.control.EditField
        VideoNameLabel                matlab.ui.control.Label
        TemperatureProtocolLabel      matlab.ui.control.Label
        TemperatureProtocolDropDown   matlab.ui.control.DropDown
        SaveParametersButton          matlab.ui.control.StateButton
        StartupLoadButton             matlab.ui.control.Button
        RecordingParametersPanel      matlab.ui.container.Panel
        VideolengthSpinnerLabel       matlab.ui.control.Label
        VideolengthSpinner            matlab.ui.control.Spinner
        SessionTimeSpinnerLabel       matlab.ui.control.Label
        SessionTimeSpinner            matlab.ui.control.Spinner
        VideoFPSSpinnerLabel          matlab.ui.control.Label
        VideoFPSSpinner               matlab.ui.control.Spinner
        CameraPreviewButton           matlab.ui.control.StateButton
        CameraSwitch                  matlab.ui.control.ToggleSwitch
        VideocountLabel               matlab.ui.control.Label
        VideocountEditField           matlab.ui.control.NumericEditField
        ArenaAMasterSwitchesPanel     matlab.ui.container.Panel
        WellsLabel_2                  matlab.ui.control.Label
        ArenaAMasterSwitch_Wells      matlab.ui.control.Switch
        GenotypeLabel_6               matlab.ui.control.Label
        ArenaAMasterSwitch_Genotype   matlab.ui.control.Switch
        SexSwitchLabel                matlab.ui.control.Label
        SexSwitch                     matlab.ui.control.Switch
        StarvedSwitchLabel            matlab.ui.control.Label
        StarvedSwitch                 matlab.ui.control.Switch
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown_A1
        function DropDown_A1ValueChanged(app, event)
            % match all other well 1 values to Arena A1 values
            if strcmpi(app.ArenaAMasterSwitch_Wells.Value,'on') %well toggle 'on'
                app.DropDown_B1.Value = app.DropDown_A1.Value;
                app.DropDown_C1.Value = app.DropDown_A1.Value;
                app.DropDown_D1.Value = app.DropDown_A1.Value;
            end
        end

        % Button pushed function: StartupLoadButton
        function StartupLoadButtonPushed(app, event)
           baseGoogleDrive = 'G:\My Drive\Jeanne Lab\';
            % Import and Load well options     
            file_id = fopen([baseGoogleDrive 'FoodWellOptions.txt']);
            A = fscanf(file_id, '%s');
            WellList = strsplit(A,','); 
            for well = 1:4
                app.(['DropDown_A' num2str(well)]).Items = WellList;
                app.(['DropDown_B' num2str(well)]).Items = WellList;
                app.(['DropDown_C' num2str(well)]).Items = WellList;
                app.(['DropDown_D' num2str(well)]).Items = WellList;
            end
            
            % Import and Load Genotype options
            file_id = fopen([baseGoogleDrive 'cross.txt']);
            A = fscanf(file_id, '%s');
            genotypeList = strsplit(A,','); 
            app.GenotypeDropDown_A.Items = genotypeList;
            app.GenotypeDropDown_B.Items = genotypeList;
            app.GenotypeDropDown_C.Items = genotypeList;
            app.GenotypeDropDown_D.Items = genotypeList;
            
            % Import and Load Temperature Protocol Options
            xlFile = [baseGoogleDrive 'Quad Bowl Experiments.xlsx'];
            [~,~,excelfile] = xlsread(xlFile, 'Temp Protocols');
            protocolList = excelfile(:,1);
            app.TemperatureProtocolDropDown.Items = protocolList;

            % Set the options for Sex
            sexList = {'Mixed', 'Female', 'Male', 'V. Female', 'V. Male', 'Unknown'};
            app.SexDropDown_A.Items = sexList;
            app.SexDropDown_B.Items = sexList;
            app.SexDropDown_C.Items = sexList;
            app.SexDropDown_D.Items = sexList;
            
        end

        % Value changed function: DropDown_A2
        function DropDown_A2ValueChanged(app, event)
            % match all other well 2 values to Arena A2 values
            if strcmpi(app.ArenaAMasterSwitch_Wells.Value,'on') %well toggle 'on'
                app.DropDown_B2.Value = app.DropDown_A2.Value;
                app.DropDown_C2.Value = app.DropDown_A2.Value;
                app.DropDown_D2.Value = app.DropDown_A2.Value;
            end
            
        end

        % Value changed function: DropDown_A3
        function DropDown_A3ValueChanged(app, event)
            % match all other well 3 values to Arena A3 values
            if strcmpi(app.ArenaAMasterSwitch_Wells.Value,'on') %well toggle 'on'
                app.DropDown_B3.Value = app.DropDown_A3.Value;
                app.DropDown_C3.Value = app.DropDown_A3.Value;
                app.DropDown_D3.Value = app.DropDown_A3.Value;
            end
        end

        % Value changed function: DropDown_A4
        function DropDown_A4ValueChanged(app, event)
           % match all other well 4 values to Arena A4 values
            if strcmpi(app.ArenaAMasterSwitch_Wells.Value,'on') %well toggle 'on'
                app.DropDown_B4.Value = app.DropDown_A4.Value;
                app.DropDown_C4.Value = app.DropDown_A4.Value;
                app.DropDown_D4.Value = app.DropDown_A4.Value;
            end
        end

        % Value changed function: GenotypeDropDown_A
        function GenotypeDropDown_AValueChanged(app, event)
            % match all other arena genotypes to Arena A genotype
            if strcmpi(app.ArenaAMasterSwitch_Genotype.Value,'on') %well toggle 'on'
                app.GenotypeDropDown_B.Value = app.GenotypeDropDown_A.Value;
                app.GenotypeDropDown_C.Value = app.GenotypeDropDown_A.Value;
                app.GenotypeDropDown_D.Value = app.GenotypeDropDown_A.Value;
            end
        end

        % Value changed function: ArenaAMasterSwitch_Wells
        function ArenaAMasterSwitch_WellsValueChanged(app, event)
            if strcmpi(app.ArenaAMasterSwitch_Wells.Value,'on') %well toggle 'on'
                DropDown_A1ValueChanged(app, event)
                DropDown_A2ValueChanged(app, event)
                DropDown_A3ValueChanged(app, event)
                DropDown_A4ValueChanged(app, event)
            end
            
        end

        % Value changed function: ArenaAMasterSwitch_Genotype
        function ArenaAMasterSwitch_GenotypeValueChanged(app, event)
            if strcmpi(app.ArenaAMasterSwitch_Genotype.Value,'on') %well toggle 'on'
                GenotypeDropDown_AValueChanged(app, event)
            end
        end

        % Value changed function: SaveParametersButton
        function SaveParametersButtonValueChanged(app, event)
         % Save all the selected parameters 
            % Experiment:
            parameters.videoName = app.VideoNameEdit.Value;
            parameters.FPS = app.VideoFPSSpinner.Value;
            parameters.session_length = app.SessionTimeSpinner.Value;
            parameters.video_length = app.VideolengthSpinner.Value;
            parameters.protocol = app.TemperatureProtocolDropDown.Value;
            parameters.numVids = app.VideocountEditField.Value;
            parameters.expID = app.VideoNameEdit.Value;
            
            
            % Arena Information:
            arenaList = {'A', 'B', 'C', 'D'};
            for arena = 1:4
                %genotype
                parameters.(['Arena' arenaList{arena}]).genotype = ...
                    app.(['GenotypeDropDown_' arenaList{arena}]).Value; 
                %sex
                parameters.(['Arena' arenaList{arena}]).sex = ...
                    app.(['SexDropDown_' arenaList{arena}]).Value; 
                %starved hours
                parameters.(['Arena' arenaList{arena}]).starved_hours = ...
                    app.(['StarvedhoursEditField_' arenaList{arena}]).Value; 
                for well = 1:4
                    % well contents
                    parameters.(['Arena' arenaList{arena}]).(['well_' num2str(well)]) = ...
                        app.(['DropDown_' arenaList{arena} num2str(well)]).Value;
                end
            end  
            
            % Export the saved information to the folder for the experiment
            global video_path tempLogPath
            basePath = 'F:\Evyn\DATA\';
            dirName = strrep(datestr(datetime, 'mm-dd-yyyy'), '-', '.');
            parameters.date = dirName;
            video_path = [basePath, dirName, '\'];
            if ~isfolder(video_path)
                mkdir(video_path);
            end
            if isfile([video_path parameters.videoName 'dataMat.mat']) %prevent overwriting the file
                warndlg('Check the experiment name to not overwrite files')
                return
            end
            save([video_path parameters.videoName ' parameters.mat'],"parameters")
            
            % Make a temperature log file:
            tempLogPath = [video_path parameters.videoName '_RampLog.csv'];
            fclose(fopen(tempLogPath, 'w+'));
        end

        % Value changed function: CameraSwitch
        function CameraSwitchValueChanged(app, event)
            value = app.CameraSwitch.Value;
            global src vid
            if strcmpi(value,'Initialized')
                FPS = app.VideoFPSSpinner.Value;
                [src, vid] = InitializeCamera(FPS);
            else
                try
                    imaqreset
                catch
                end
            end
        end

        % Value changed function: SessionTimeSpinner
        function SessionTimeSpinnerValueChanged(app, event)
            % Update the number of videos that will recorded 
            totalTime = app.SessionTimeSpinner.Value;
            vidLength = app.VideolengthSpinner.Value;      
            numVids = ceil(totalTime/vidLength); 
            app.VideocountEditField.Value = numVids;
        end

        % Value changed function: VideolengthSpinner
        function VideolengthSpinnerValueChanged(app, event)
            % Update the number of videos that will recorded 
            totalTime = app.SessionTimeSpinner.Value;
            vidLength = app.VideolengthSpinner.Value;      
            numVids = ceil(totalTime/vidLength); 
            app.VideocountEditField.Value = numVids;
            
        end

        % Value changed function: CameraPreviewButton
        function CameraPreviewButtonValueChanged(app, event)
            global vid 
            if strcmpi(app.CameraSwitch.Value,'Initialized')
                preview(vid)
            else
                warndlg('Initialize camera first')
            end     
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % RUN THE EXPERIMENT:
            global vid video_path tempLogPath
            closepreview(vid)
            app.Lamp.Color = 'red';
            % pull key parameters
            numVids = app.VideocountEditField.Value;
            app.ProgressGauge.Value = 0;
            for n = 1:numVids
                % pull the current temp reading from the chiller log
                tempLogStart(n,:) = [n, logTempNow(tempLogPath)];
                
                % Set video saving
                diskLogger = VideoWriter([video_path app.VideoNameEdit.Value '_' num2str(n) '.avi'], 'Motion JPEG AVI');
                vid.LoggingMode = 'disk';   %'disk&memory'; 
                diskLogger.Quality = 65;
                diskLogger.FrameRate = app.VideoFPSSpinner.Value; 
                vid.DiskLogger = diskLogger;  
            
                start(vid)
                pause(app.VideolengthSpinner.Value*60) % pause for the duration of each video
                stop(vid)
                
                % pull the current temp reading from the chiller log
                tempLogEnd(n,:) = [n, logTempNow(tempLogPath)];
                
                fprintf(['\n' num2str(n) ' / ' num2str(numVids)  '\n'])
                app.ProgressGauge.Value = (n/numVids)*100;
            end
            load([video_path app.VideoNameEdit.Value ' parameters.mat'],"parameters")
            parameters.numVids = numVids;
            parameters.video_path = video_path;
            
            save([video_path parameters.expID ' dataMat.mat'],"parameters", "tempLogEnd", "tempLogStart")
            
            % Write to excel...
            fprintf(['\n' 'Done' '\n']) 
            app.Lamp.Color = 'green';
        end

        % Button pushed function: SendtoExcelButton
        function SendtoExcelButtonPushed(app, event)
            global video_path
            load([video_path app.VideoNameEdit.Value ' parameters.mat'],"parameters")
            writeQuadExptoExcel(parameters)
        end

        % Value changed function: SexDropDown_A
        function SexDropDown_AValueChanged(app, event)
            value = app.SexDropDown_A.Value;
            if strcmpi(app.SexSwitch.Value,'On')
                app.SexDropDown_B.Value = value;
                app.SexDropDown_C.Value = value;
                app.SexDropDown_D.Value = value;
            end
        end

        % Value changed function: StarvedhoursEditField_A
        function StarvedhoursEditField_AValueChanged(app, event)
            value = app.StarvedhoursEditField_A.Value;
            if strcmp(app.StarvedSwitch.Value,'On')
                app.StarvedhoursEditField_B.Value = value;
                app.StarvedhoursEditField_C.Value = value;
                app.StarvedhoursEditField_D.Value = value;
            end
        end

        % Value changed function: StarvedSwitch
        function StarvedSwitchValueChanged(app, event)
            
            if strcmp(app.StarvedSwitch.Value,'On')
                value = app.StarvedhoursEditField_A.Value;
                app.StarvedhoursEditField_B.Value = value;
                app.StarvedhoursEditField_C.Value = value;
                app.StarvedhoursEditField_D.Value = value;
            end
        end

        % Value changed function: SexSwitch
        function SexSwitchValueChanged(app, event)
            if strcmpi(app.SexSwitch.Value,'On')
                value = app.SexDropDown_A.Value;
                app.SexDropDown_B.Value = value;
                app.SexDropDown_C.Value = value;
                app.SexDropDown_D.Value = value;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {534, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ArenaAPanel = uipanel(app.LeftPanel);
            app.ArenaAPanel.Title = 'Arena A';
            app.Label = uilabel(app.ArenaAPanel);
            app.Label.Text = '1';
            app.DropDown_A1 = uidropdown(app.ArenaAPanel);
            app.DropDown_A1.ValueChangedFcn = createCallbackFcn(app, @DropDown_A1ValueChanged, true);
            app.Label_2 = uilabel(app.ArenaAPanel);
            app.Label_2.Text = '2';
            app.DropDown_A2 = uidropdown(app.ArenaAPanel);
            app.DropDown_A2.ValueChangedFcn = createCallbackFcn(app, @DropDown_A2ValueChanged, true);
            app.Label_3 = uilabel(app.ArenaAPanel);
            app.Label_3.Text = '3';
            app.DropDown_A3 = uidropdown(app.ArenaAPanel);
            app.DropDown_A3.ValueChangedFcn = createCallbackFcn(app, @DropDown_A3ValueChanged, true);
            app.Label_4 = uilabel(app.ArenaAPanel);
            app.Label_4.Text = '4';
            app.DropDown_A4 = uidropdown(app.ArenaAPanel);
            app.DropDown_A4.ValueChangedFcn = createCallbackFcn(app, @DropDown_A4ValueChanged, true);
            app.GenotypeDropDown_A = uidropdown(app.ArenaAPanel);
            app.GenotypeDropDown_A.ValueChangedFcn = createCallbackFcn(app, @GenotypeDropDown_AValueChanged, true);
            app.WellLabel = uilabel(app.ArenaAPanel);
            app.WellLabel.Text = 'Well';
            app.GenotypeLabel = uilabel(app.ArenaAPanel);
            app.GenotypeLabel.Text = 'Genotype';
            app.ContentsLabel = uilabel(app.ArenaAPanel);
            app.ContentsLabel.Text = 'Contents';
            app.SexDropDownLabel = uilabel(app.ArenaAPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown_A = uidropdown(app.ArenaAPanel);
            app.SexDropDown_A.ValueChangedFcn = createCallbackFcn(app, @SexDropDown_AValueChanged, true);
            app.StarvedhoursEditFieldLabel = uilabel(app.ArenaAPanel);
            app.StarvedhoursEditFieldLabel.Text = 'Starved hours';
            app.StarvedhoursEditField_A = uieditfield(app.ArenaAPanel, 'numeric');
            app.StarvedhoursEditField_A.ValueChangedFcn = createCallbackFcn(app, @StarvedhoursEditField_AValueChanged, true);
            app.ArenaBPanel = uipanel(app.LeftPanel);
            app.ArenaBPanel.Title = 'Arena B';
            app.DropDown_5Label = uilabel(app.ArenaBPanel);
            app.DropDown_5Label.Text = '1';
            app.DropDown_B1 = uidropdown(app.ArenaBPanel);
            app.DropDown_6Label = uilabel(app.ArenaBPanel);
            app.DropDown_6Label.Text = '2';
            app.DropDown_B2 = uidropdown(app.ArenaBPanel);
            app.DropDown_7Label = uilabel(app.ArenaBPanel);
            app.DropDown_7Label.Text = '3';
            app.DropDown_B3 = uidropdown(app.ArenaBPanel);
            app.DropDown_8Label = uilabel(app.ArenaBPanel);
            app.DropDown_8Label.Text = '4';
            app.DropDown_B4 = uidropdown(app.ArenaBPanel);
            app.GenotypeDropDown_B = uidropdown(app.ArenaBPanel);
            app.WellLabel_2 = uilabel(app.ArenaBPanel);
            app.WellLabel_2.Text = 'Well';
            app.GenotypeLabel_2 = uilabel(app.ArenaBPanel);
            app.GenotypeLabel_2.Text = 'Genotype';
            app.ContentsLabel_2 = uilabel(app.ArenaBPanel);
            app.ContentsLabel_2.Text = 'Contents';
            app.SexDropDown_2Label = uilabel(app.ArenaBPanel);
            app.SexDropDown_2Label.Text = 'Sex';
            app.SexDropDown_B = uidropdown(app.ArenaBPanel);
            app.StarvedhoursEditField_2Label = uilabel(app.ArenaBPanel);
            app.StarvedhoursEditField_2Label.Text = 'Starved hours';
            app.StarvedhoursEditField_B = uieditfield(app.ArenaBPanel, 'numeric');
            app.ArenaCPanel = uipanel(app.LeftPanel);
            app.ArenaCPanel.Title = 'Arena C';
            app.DropDown_9Label = uilabel(app.ArenaCPanel);
            app.DropDown_9Label.Text = '1';
            app.DropDown_C1 = uidropdown(app.ArenaCPanel);
            app.DropDown_10Label = uilabel(app.ArenaCPanel);
            app.DropDown_10Label.Text = '2';
            app.DropDown_C2 = uidropdown(app.ArenaCPanel);
            app.DropDown_11Label = uilabel(app.ArenaCPanel);
            app.DropDown_11Label.Text = '3';
            app.DropDown_C3 = uidropdown(app.ArenaCPanel);
            app.DropDown_12Label = uilabel(app.ArenaCPanel);
            app.DropDown_12Label.Text = '4';
            app.DropDown_C4 = uidropdown(app.ArenaCPanel);
            app.GenotypeDropDown_C = uidropdown(app.ArenaCPanel);
            app.WellLabel_3 = uilabel(app.ArenaCPanel);
            app.WellLabel_3.Text = 'Well';
            app.GenotypeLabel_3 = uilabel(app.ArenaCPanel);
            app.GenotypeLabel_3.Text = 'Genotype';
            app.ContentsLabel_3 = uilabel(app.ArenaCPanel);
            app.ContentsLabel_3.Text = 'Contents';
            app.SexDropDown_3Label = uilabel(app.ArenaCPanel);
            app.SexDropDown_3Label.Text = 'Sex';
            app.SexDropDown_C = uidropdown(app.ArenaCPanel);
            app.StarvedhoursEditField_3Label = uilabel(app.ArenaCPanel);
            app.StarvedhoursEditField_3Label.Text = 'Starved hours';
            app.StarvedhoursEditField_C = uieditfield(app.ArenaCPanel, 'numeric');
            app.ArenaDPanel = uipanel(app.LeftPanel);
            app.ArenaDPanel.Title = 'Arena D';
            app.DropDown_13Label = uilabel(app.ArenaDPanel);
            app.DropDown_13Label.Text = '1';
            app.DropDown_D1 = uidropdown(app.ArenaDPanel);
            app.DropDown_14Label = uilabel(app.ArenaDPanel);
            app.DropDown_14Label.Text = '2';
            app.DropDown_D2 = uidropdown(app.ArenaDPanel);
            app.DropDown_15Label = uilabel(app.ArenaDPanel);
            app.DropDown_15Label.Text = '3';
            app.DropDown_D3 = uidropdown(app.ArenaDPanel);
            app.DropDown_16Label = uilabel(app.ArenaDPanel);
            app.DropDown_16Label.Text = '4';
            app.DropDown_D4 = uidropdown(app.ArenaDPanel);
            app.GenotypeDropDown_D = uidropdown(app.ArenaDPanel);
            app.WellLabel_4 = uilabel(app.ArenaDPanel);
            app.WellLabel_4.Text = 'Well';
            app.GenotypeLabel_4 = uilabel(app.ArenaDPanel);
            app.GenotypeLabel_4.Text = 'Genotype';
            app.ContentsLabel_4 = uilabel(app.ArenaDPanel);
            app.ContentsLabel_4.Text = 'Contents';
            app.SexDropDown_4Label = uilabel(app.ArenaDPanel);
            app.SexDropDown_4Label.Text = 'Sex';
            app.SexDropDown_D = uidropdown(app.ArenaDPanel);
            app.StarvedhoursEditField_4Label = uilabel(app.ArenaDPanel);
            app.StarvedhoursEditField_4Label.Text = 'Starved hours';
            app.StarvedhoursEditField_D = uieditfield(app.ArenaDPanel, 'numeric');
            app.ExperimentControlsPanel = uipanel(app.LeftPanel);
            app.ExperimentControlsPanel.Title = 'Experiment Controls';
            app.RUNButton = uibutton(app.ExperimentControlsPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.SendtoExcelButton = uibutton(app.ExperimentControlsPanel, 'push');
            app.SendtoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoExcelButtonPushed, true);
            app.SendtoExcelButton.Text = 'Send to Excel';
            app.ProgressGauge = uigauge(app.LeftPanel, 'semicircular');
            app.Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.ExperimentInformationLabel = uilabel(app.RightPanel);
            app.ExperimentInformationLabel.Text = 'Experiment Information';
            app.VideoNameEdit = uieditfield(app.RightPanel, 'text');
            app.VideoNameEdit.Value = 'ExpName';
            app.VideoNameLabel = uilabel(app.RightPanel);
            app.VideoNameLabel.Text = 'Video Name';
            app.TemperatureProtocolLabel = uilabel(app.RightPanel);
            app.TemperatureProtocolLabel.Text = 'Temperature Protocol';
            app.TemperatureProtocolDropDown = uidropdown(app.RightPanel);
            app.SaveParametersButton = uibutton(app.RightPanel, 'state');
            app.SaveParametersButton.ValueChangedFcn = createCallbackFcn(app, @SaveParametersButtonValueChanged, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.StartupLoadButton = uibutton(app.RightPanel, 'push');
            app.StartupLoadButton.ButtonPushedFcn = createCallbackFcn(app, @StartupLoadButtonPushed, true);
            app.StartupLoadButton.Text = 'Startup Load';
            app.RecordingParametersPanel = uipanel(app.RightPanel);
            app.RecordingParametersPanel.Title = 'Recording Parameters';
            app.VideolengthSpinnerLabel = uilabel(app.RecordingParametersPanel);
            app.VideolengthSpinnerLabel.Text = 'Video length';
            app.VideolengthSpinner = uispinner(app.RecordingParametersPanel);
            app.VideolengthSpinner.ValueChangedFcn = createCallbackFcn(app, @VideolengthSpinnerValueChanged, true);
            app.VideolengthSpinner.Value = 10;
            app.SessionTimeSpinnerLabel = uilabel(app.RecordingParametersPanel);
            app.SessionTimeSpinnerLabel.Text = 'Session Time';
            app.SessionTimeSpinner = uispinner(app.RecordingParametersPanel);
            app.SessionTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @SessionTimeSpinnerValueChanged, true);
            app.VideoFPSSpinnerLabel = uilabel(app.RecordingParametersPanel);
            app.VideoFPSSpinnerLabel.Text = 'Video FPS';
            app.VideoFPSSpinner = uispinner(app.RecordingParametersPanel);
            app.VideoFPSSpinner.Value = 3;
            app.CameraPreviewButton = uibutton(app.RecordingParametersPanel, 'state');
            app.CameraPreviewButton.ValueChangedFcn = createCallbackFcn(app, @CameraPreviewButtonValueChanged, true);
            app.CameraPreviewButton.Text = {'Camera '; 'Preview'};
            app.CameraSwitch = uiswitch(app.RecordingParametersPanel, 'toggle');
            app.CameraSwitch.Items = {'Initialized', 'Off'};
            app.CameraSwitch.ValueChangedFcn = createCallbackFcn(app, @CameraSwitchValueChanged, true);
            app.VideocountLabel = uilabel(app.RecordingParametersPanel);
            app.VideocountLabel.Text = {'Video '; 'count'};
            app.VideocountEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.ArenaAMasterSwitchesPanel = uipanel(app.RightPanel);
            app.ArenaAMasterSwitchesPanel.Title = 'Arena A Master Switches';
            app.WellsLabel_2 = uilabel(app.ArenaAMasterSwitchesPanel);
            app.WellsLabel_2.Text = 'Wells';
            app.ArenaAMasterSwitch_Wells = uiswitch(app.ArenaAMasterSwitchesPanel, 'slider');
            app.ArenaAMasterSwitch_Wells.ValueChangedFcn = createCallbackFcn(app, @ArenaAMasterSwitch_WellsValueChanged, true);
            app.ArenaAMasterSwitch_Wells.Value = 'On';
            app.GenotypeLabel_6 = uilabel(app.ArenaAMasterSwitchesPanel);
            app.GenotypeLabel_6.Text = 'Genotype';
            app.ArenaAMasterSwitch_Genotype = uiswitch(app.ArenaAMasterSwitchesPanel, 'slider');
            app.ArenaAMasterSwitch_Genotype.ValueChangedFcn = createCallbackFcn(app, @ArenaAMasterSwitch_GenotypeValueChanged, true);
            app.ArenaAMasterSwitch_Genotype.Value = 'On';
            app.SexSwitchLabel = uilabel(app.ArenaAMasterSwitchesPanel);
            app.SexSwitchLabel.Text = 'Sex';
            app.SexSwitch = uiswitch(app.ArenaAMasterSwitchesPanel, 'slider');
            app.SexSwitch.ValueChangedFcn = createCallbackFcn(app, @SexSwitchValueChanged, true);
            app.StarvedSwitchLabel = uilabel(app.ArenaAMasterSwitchesPanel);
            app.StarvedSwitchLabel.Text = 'Starved';
            app.StarvedSwitch = uiswitch(app.ArenaAMasterSwitchesPanel, 'slider');
            app.StarvedSwitch.ValueChangedFcn = createCallbackFcn(app, @StarvedSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArenaGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end