classdef CourtshipExpGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExperimentDetailsPanel          matlab.ui.container.Panel
        TempProtocolDropDownLabel       matlab.ui.control.Label
        TempProtocolDropDown            matlab.ui.control.DropDown
        FlySexDropDownLabel             matlab.ui.control.Label
        FlySexDropDown                  matlab.ui.control.DropDown
        FlyNumberDropDownLabel          matlab.ui.control.Label
        FlyNumberDropDown               matlab.ui.control.DropDown
        IncubatorDropDownLabel          matlab.ui.control.Label
        IncubatorDropDown               matlab.ui.control.DropDown
        ExperimenterDropDownLabel       matlab.ui.control.Label
        ExperimenterDropDown            matlab.ui.control.DropDown
        GenotypeDropDownLabel           matlab.ui.control.Label
        GenotypeDropDown                matlab.ui.control.DropDown
        ExperimentNameEditFieldLabel    matlab.ui.control.Label
        ExperimentNameEditField         matlab.ui.control.EditField
        ArenaWellContentsPanel          matlab.ui.container.Panel
        Well1DropDownLabel              matlab.ui.control.Label
        Well1DropDown                   matlab.ui.control.DropDown
        Well2DropDownLabel              matlab.ui.control.Label
        Well2DropDown                   matlab.ui.control.DropDown
        Well3DropDownLabel              matlab.ui.control.Label
        Well3DropDown                   matlab.ui.control.DropDown
        Well4DropDownLabel              matlab.ui.control.Label
        Well4DropDown                   matlab.ui.control.DropDown
        RecordingParametersPanel        matlab.ui.container.Panel
        FPSEditFieldLabel               matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        FragmentLengthsecEditFieldLabel  matlab.ui.control.Label
        FragmentLengthsecEditField      matlab.ui.control.NumericEditField
        IntertrialintervalminEditFieldLabel  matlab.ui.control.Label
        IntertrialintervalminEditField  matlab.ui.control.NumericEditField
        RecordingBoutsEditFieldLabel    matlab.ui.control.Label
        RecordingBoutsEditField         matlab.ui.control.NumericEditField
        StartPauseminEditFieldLabel     matlab.ui.control.Label
        StartPauseminEditField          matlab.ui.control.NumericEditField
        PreviewButton                   matlab.ui.control.Button
        CameraresetButton               matlab.ui.control.Button
        TotalVideoLengthminEditFieldLabel  matlab.ui.control.Label
        TotalVideoLengthminEditField    matlab.ui.control.NumericEditField
        StartupLoadButton               matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        SendtoExcelButton               matlab.ui.control.Button
        SaveParamsButton                matlab.ui.control.Button
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartupLoadButton
        function StartupLoadButtonPushed(app, event)
           baseGoogleDrive = 'G:\My Drive\Jeanne Lab\';
            % Import and Load well options     
            file_id = fopen([baseGoogleDrive 'FoodWellOptions.txt']);
            A = fscanf(file_id, '%s');
            WellList = strsplit(A,',');  % pull the list of food options
            for well = 1:4
                app.(['Well' num2str(well) 'DropDown']).Items = WellList; 
            end
            % Import and Load Genotype options
            file_id = fopen([baseGoogleDrive 'cross.txt']);
            A = fscanf(file_id, '%s');
            genotypeList = strsplit(A,','); 
            app.GenotypeDropDown.Items = genotypeList;

            % Add Experimenter options    
            app.ExperimenterDropDown.Items = {'Becca', 'Evyn'};
            
            % Add day/night incubator options    
            app.IncubatorDropDown.Items = {'Day', 'Night'};
           
            % Import and Load Temperature Protocol Options
            xlFile = [baseGoogleDrive 'Quad Bowl Experiments.xlsx'];
            [~,~,excelfile] = xlsread(xlFile, 'Temp Protocols');
            protocolList = excelfile(:,1);
            app.TempProtocolDropDown.Items = protocolList;

            % Set the options for Sex
            sexList = {'MF','MM','FF','Mixed', 'Female', 'Male', 'V. Female', 'V. Male', 'Unknown'};
            app.FlySexDropDown.Items = sexList;
            
            % Fly number options
            flynums = 1:15;
            flynum_list = [];
            for i = 1:length(flynums)
                flynum_list{i} = num2str(flynums(i));
            end
            app.FlyNumberDropDown.Items = flynum_list;
            app.FlyNumberDropDown.Value = '2';
            
        end

        % Button pushed function: SaveParamsButton
        function SaveParamsButtonPushed(app, event)
            dateStr = char(datetime('now','Format','MM.dd.yyyy'));
            baseFolder = ['E:\Evyn\Courtship Tracking\' dateStr '\'];
            if ~exist(baseFolder)
                mkdir(baseFolder)
            end
            cd(baseFolder) % set current path to video folder
            
            % Save all the selected parameters 
            parameters = [];
            parameters.ramps = app.RecordingBoutsEditField.Value;
%             parameters.nSections = app.RecordingBoutsEditField.Value;
            parameters.ITI = app.IntertrialintervalminEditField.Value;
            parameters.FPS = app.FPSEditField.Value;
%             parameters.recordingduration = app.TotalVideoLengthminEditField.Value;
            parameters.ramplength = app.TotalVideoLengthminEditField.Value;
%             parameters.fragmentduration = app.FragmentLengthsecEditField.Value;
            parameters.fragmentlength = app.FragmentLengthsecEditField.Value;
            parameters.protocol = app.TempProtocolDropDown.Value;%'courtship_F_LRR_25-17'; 
            nsamples = ceil((parameters.ramplength*60)/parameters.fragmentlength);
            parameters.numFrag = nsamples; % number of video fragments
            parameters.experimenter = app.ExperimenterDropDown.Value; 
            parameters.daynight = app.IncubatorDropDown.Value;
            parameters.date = dateStr;
            parameters.genotype = app.GenotypeDropDown.Value;
            
            % Experiment:
            expName = app.ExperimentNameEditField.Value;
            parameters.expID = expName;
            parameters.videoName = expName;
            parameters.genotype = app.GenotypeDropDown.Value; 
            parameters.sex = app.FlySexDropDown.Value;
            parameters.starved_hours = 0;
            parameters.well_1 = app.Well1DropDown.Value;
            parameters.well_2 = app.Well2DropDown.Value;
            parameters.well_3 = app.Well3DropDown.Value;
            parameters.well_4 = app.Well4DropDown.Value;
            parameters.numflies = app.FlyNumberDropDown.Value;
            
            % save parameter file
            if isfile([baseFolder expName 'dataMat.mat']) %prevent overwriting the file
                warndlg('Check the experiment name to not overwrite files')
                return
            end
            save([expName ' parameters.mat'],"parameters")
            
            % Make a temperature log file:
            tempLogPath = [parameters.videoName '_RampLog.csv'];
            fclose(fopen(tempLogPath, 'w+'));
            
            disp('Parameters saved')
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
%             disp('error')
            vid = videoinput('pointgrey', 1, 'F7_Raw8_2048x2048_Mode0');
            src = getselectedsource(vid);
            [~, vid] = initialize_CourtshipCamera(src,vid,app.FPSEditField.Value);
%             disp('Purple BACK camera initialized')
            preview(vid);
        end

        % Button pushed function: CameraresetButton
        function CameraresetButtonPushed(app, event)
            imaqreset
        end

        % Button pushed function: SendtoExcelButton
        function SendtoExcelButtonPushed(app, event)
%             global xlRows
%             parameters = [];

            load([app.ExperimentNameEditField.Value ' parameters.mat'],"parameters")
            rows = writeCourtshipExptoExcel(parameters);

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            disp('Starting experiment')
            % Make a temperature log file:
            expName = app.ExperimentNameEditField.Value;
            tempLogPath = [expName '_RampLog.csv'];
             
            fragLen = app.FragmentLengthsecEditField.Value;
            hz = app.FPSEditField.Value;
            numSections = app.RecordingBoutsEditField.Value;
            nsamples = ceil((app.TotalVideoLengthminEditField.Value*60)/fragLen);
            nFrames = fragLen*hz*nsamples;
            tempLogStart = [];

            % start delay on the experiment
            pause(app.StartPauseminEditField.Value*60)
            
            for n = 1:numSections
                % save start temp log information    
                tempLogStart(n,:) = [n, logTempNow(tempLogPath)];
                disp(['Start time log ramp ' num2str(n)])
                disp(tempLogStart(n,:))
                disp('Videos recording...')
                save([expName ' RampStartTempLog.mat'],'tempLogStart')
                
                % acquire videos
                get_samples_v3(nFrames,fragLen*hz,expName,tempLogPath,hz)
                
                % pause for ITI (if more than 1 section)
                if numSections>1 && n<numSections
                    disp(['Inter-trial-interval pause : ' num2str(app.IntertrialintervalminEditField.Value)])
                    pause(app.IntertrialintervalminEditField.Value*60) % pause for the duration of each video
                elseif n==numSections
                    disp('Pausing 1 min')
                    pause(60)
                else 
                    disp('this hook isn''t working')
                    
                end
            end
            disp('Videos done recording')
            save([expName ' RampStartTempLog.mat'],'tempLogStart')
            fprintf(['\n' 'Experiment Done' '\n']) 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExperimentDetailsPanel = uipanel(app.UIFigure);
            app.ExperimentDetailsPanel.Title = 'Experiment Details';
            app.TempProtocolDropDownLabel = uilabel(app.ExperimentDetailsPanel);
            app.TempProtocolDropDownLabel.Text = 'Temp Protocol';
            app.TempProtocolDropDown = uidropdown(app.ExperimentDetailsPanel);
            app.FlySexDropDownLabel = uilabel(app.ExperimentDetailsPanel);
            app.FlySexDropDownLabel.Text = 'Fly Sex';
            app.FlySexDropDown = uidropdown(app.ExperimentDetailsPanel);
            app.FlyNumberDropDownLabel = uilabel(app.ExperimentDetailsPanel);
            app.FlyNumberDropDownLabel.Text = 'Fly Number';
            app.FlyNumberDropDown = uidropdown(app.ExperimentDetailsPanel);
            app.IncubatorDropDownLabel = uilabel(app.ExperimentDetailsPanel);
            app.IncubatorDropDownLabel.Text = 'Incubator';
            app.IncubatorDropDown = uidropdown(app.ExperimentDetailsPanel);
            app.ExperimenterDropDownLabel = uilabel(app.ExperimentDetailsPanel);
            app.ExperimenterDropDownLabel.Text = 'Experimenter';
            app.ExperimenterDropDown = uidropdown(app.ExperimentDetailsPanel);
            app.GenotypeDropDownLabel = uilabel(app.ExperimentDetailsPanel);
            app.GenotypeDropDownLabel.Text = 'Genotype';
            app.GenotypeDropDown = uidropdown(app.ExperimentDetailsPanel);
            app.ExperimentNameEditFieldLabel = uilabel(app.ExperimentDetailsPanel);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name';
            app.ExperimentNameEditField = uieditfield(app.ExperimentDetailsPanel, 'text');
            app.ArenaWellContentsPanel = uipanel(app.UIFigure);
            app.ArenaWellContentsPanel.Title = 'Arena Well Contents';
            app.Well1DropDownLabel = uilabel(app.ArenaWellContentsPanel);
            app.Well1DropDownLabel.Text = 'Well 1';
            app.Well1DropDown = uidropdown(app.ArenaWellContentsPanel);
            app.Well2DropDownLabel = uilabel(app.ArenaWellContentsPanel);
            app.Well2DropDownLabel.Text = 'Well 2';
            app.Well2DropDown = uidropdown(app.ArenaWellContentsPanel);
            app.Well3DropDownLabel = uilabel(app.ArenaWellContentsPanel);
            app.Well3DropDownLabel.Text = 'Well 3';
            app.Well3DropDown = uidropdown(app.ArenaWellContentsPanel);
            app.Well4DropDownLabel = uilabel(app.ArenaWellContentsPanel);
            app.Well4DropDownLabel.Text = 'Well 4';
            app.Well4DropDown = uidropdown(app.ArenaWellContentsPanel);
            app.RecordingParametersPanel = uipanel(app.UIFigure);
            app.RecordingParametersPanel.Title = 'Recording Parameters';
            app.FPSEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.FPSEditField.Value = 30;
            app.FragmentLengthsecEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.FragmentLengthsecEditFieldLabel.Text = 'Fragment Length (sec)';
            app.FragmentLengthsecEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.FragmentLengthsecEditField.Value = 5;
            app.IntertrialintervalminEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.IntertrialintervalminEditFieldLabel.Text = 'Inter-trial-interval (min)';
            app.IntertrialintervalminEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.IntertrialintervalminEditField.Value = 48;
            app.RecordingBoutsEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.RecordingBoutsEditFieldLabel.Text = 'Recording Bouts';
            app.RecordingBoutsEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.RecordingBoutsEditField.Value = 1;
            app.StartPauseminEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.StartPauseminEditFieldLabel.Text = 'Start Pause (min)';
            app.StartPauseminEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.StartPauseminEditField.Value = 5;
            app.PreviewButton = uibutton(app.RecordingParametersPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.CameraresetButton = uibutton(app.RecordingParametersPanel, 'push');
            app.CameraresetButton.ButtonPushedFcn = createCallbackFcn(app, @CameraresetButtonPushed, true);
            app.CameraresetButton.Text = 'Camera reset';
            app.TotalVideoLengthminEditFieldLabel = uilabel(app.RecordingParametersPanel);
            app.TotalVideoLengthminEditFieldLabel.Text = 'Total Video Length (min)';
            app.TotalVideoLengthminEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.TotalVideoLengthminEditField.Value = 64;
            app.StartupLoadButton = uibutton(app.UIFigure, 'push');
            app.StartupLoadButton.ButtonPushedFcn = createCallbackFcn(app, @StartupLoadButtonPushed, true);
            app.StartupLoadButton.Text = 'Startup Load';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SendtoExcelButton = uibutton(app.UIFigure, 'push');
            app.SendtoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoExcelButtonPushed, true);
            app.SendtoExcelButton.Text = 'Send to Excel';
            app.SaveParamsButton = uibutton(app.UIFigure, 'push');
            app.SaveParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamsButtonPushed, true);
            app.SaveParamsButton.Text = 'Save Params';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CourtshipExpGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end