classdef Courtship_Tracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        VideoTab                  matlab.ui.container.Tab
        VideoFrameEditField       matlab.ui.control.NumericEditField
        VideoFrameEditFieldLabel  matlab.ui.control.Label
        VideonumberSpinner        matlab.ui.control.Spinner
        VideonumberSpinnerLabel   matlab.ui.control.Label
        previousButton            matlab.ui.control.Button
        nextButton                matlab.ui.control.Button
        FrameSkipEditField        matlab.ui.control.NumericEditField
        FrameSkipEditFieldLabel   matlab.ui.control.Label
        PlaybackFPSSpinner        matlab.ui.control.Spinner
        PlaybackFPSSpinnerLabel   matlab.ui.control.Label
        ExpFrameEditField         matlab.ui.control.NumericEditField
        ExpFrameEditFieldLabel    matlab.ui.control.Label
        skipbackwardsButton       matlab.ui.control.Button
        skipforwardsButton        matlab.ui.control.Button
        LoadFrameButton           matlab.ui.control.Button
        PlayButton                matlab.ui.control.Button
        Tab2                      matlab.ui.container.Tab
        ExpDateEditField          matlab.ui.control.EditField
        ExpDateEditFieldLabel     matlab.ui.control.Label
        ArenaEditField            matlab.ui.control.EditField
        ArenaEditFieldLabel       matlab.ui.control.Label
        LabelsPanel               matlab.ui.container.Panel
        ApproachButton            matlab.ui.control.Button
        CourtshipButton           matlab.ui.control.Button
        WingsButton               matlab.ui.control.Button
        SelectexperimentButton    matlab.ui.control.Button
        ExpNameEditField          matlab.ui.control.EditField
        ExpNameEditFieldLabel     matlab.ui.control.Label
        FrameSlider               matlab.ui.control.Slider
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectexperimentButton
        function SelectexperimentButtonPushed(app, event)
            %TODO: make this all a bit more automated and drop-down etc 
            global isPaused T xlimit ylimit
            isPaused = true;
            baseFolder = getCloudPath;
            [expName, expPath] = uigetfile([baseFolder, '*timecourse data.mat'],'select data file for experiment to quantify');
            expParams = load([expPath, expName]);
            
            app.ArenaEditField.Value = [expParams.data.name];
            app.ExpDateEditField.Value = [expParams.expData.parameters.date];
            app.ExpNameEditField.Value  = [expParams.expName];
            T =  expParams.data.T;
            
            % get video crop details based on arena rotation etc. 
            x =  expParams.data.centre(1);
            y =  expParams.data.centre(2);
            r =  expParams.data.r;
            xlimit = [x-(r+25),x+(r+25)];
            ylimit = [y-(r+25),y+25+r];
        end

        % Button pushed function: LoadFrameButton
        function LoadFrameButtonPushed(app, event)
            global isPaused T vid xlimit ylimit
            
            % pull information on desired frame
            currFrame = app.ExpFrameEditField.Value;
            currLoc = find(T.frame==currFrame);
            % set the video num and frame info
            vidNum = T.vidNums(currLoc);
            vidFrame = T.vidFrame(currLoc);
            app.VideoFrameEditField.Value = vidFrame;
            app.VideonumberSpinner.Value = vidNum;
            % load image
            basePath = getCloudPath;
            vidPath = [basePath app.ExpDateEditField.Value '/' app.ExpNameEditField.Value '_' num2str(vidNum) '.avi'];
            vid = VideoReader(vidPath);
            % Pull video frame
            currTime = vidFrame *(1/vid.FrameRate);
            vid.CurrentTime = currTime;
            frame = readFrame(vid);
            Frame = frame(xlimit(1):xlimit(2),ylimit(1):ylimit(2), :);
            image(frame, 'Parent', app.UIAxes);
            app.UIAxes.XLim = (xlimit);
            app.UIAxes.YLim = (ylimit);
            % drawnow;


            vid_num = app.VideonumberSpinner.Value
            vid = VideoReader([])
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global isPaused
            % 1) check that video is already loaded...
            
            % 2) initialize 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {585, 585};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {363, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FrameSlider = uislider(app.LeftPanel);
            app.ExpNameEditFieldLabel = uilabel(app.LeftPanel);
            app.ExpNameEditFieldLabel.Text = 'Exp Name';
            app.ExpNameEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectexperimentButton = uibutton(app.LeftPanel, 'push');
            app.SelectexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SelectexperimentButtonPushed, true);
            app.SelectexperimentButton.Text = 'Select experiment';
            app.LabelsPanel = uipanel(app.LeftPanel);
            app.LabelsPanel.Title = 'Labels';
            app.WingsButton = uibutton(app.LabelsPanel, 'push');
            app.WingsButton.Text = 'Wings';
            app.CourtshipButton = uibutton(app.LabelsPanel, 'push');
            app.CourtshipButton.Text = 'Courtship';
            app.ApproachButton = uibutton(app.LabelsPanel, 'push');
            app.ApproachButton.Text = 'Approach';
            app.ArenaEditFieldLabel = uilabel(app.LeftPanel);
            app.ArenaEditFieldLabel.Text = 'Arena';
            app.ArenaEditField = uieditfield(app.LeftPanel, 'text');
            app.ExpDateEditFieldLabel = uilabel(app.LeftPanel);
            app.ExpDateEditFieldLabel.Text = 'Exp Date';
            app.ExpDateEditField = uieditfield(app.LeftPanel, 'text');
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.VideoTab = uitab(app.TabGroup);
            app.VideoTab.Title = 'Video';
            app.PlayButton = uibutton(app.VideoTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.LoadFrameButton = uibutton(app.VideoTab, 'push');
            app.LoadFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFrameButtonPushed, true);
            app.LoadFrameButton.Text = 'Load Frame';
            app.skipforwardsButton = uibutton(app.VideoTab, 'push');
            app.skipforwardsButton.Text = 'skip forwards';
            app.skipbackwardsButton = uibutton(app.VideoTab, 'push');
            app.skipbackwardsButton.Text = 'skip backwards';
            app.ExpFrameEditFieldLabel = uilabel(app.VideoTab);
            app.ExpFrameEditFieldLabel.Text = 'Exp Frame';
            app.ExpFrameEditField = uieditfield(app.VideoTab, 'numeric');
            app.ExpFrameEditField.Value = 1;
            app.PlaybackFPSSpinnerLabel = uilabel(app.VideoTab);
            app.PlaybackFPSSpinnerLabel.Text = 'Playback FPS';
            app.PlaybackFPSSpinner = uispinner(app.VideoTab);
            app.PlaybackFPSSpinner.Value = 6;
            app.FrameSkipEditFieldLabel = uilabel(app.VideoTab);
            app.FrameSkipEditFieldLabel.Text = 'Frame Skip';
            app.FrameSkipEditField = uieditfield(app.VideoTab, 'numeric');
            app.FrameSkipEditField.Value = 20;
            app.nextButton = uibutton(app.VideoTab, 'push');
            app.nextButton.Text = 'next';
            app.previousButton = uibutton(app.VideoTab, 'push');
            app.previousButton.Text = 'previous';
            app.VideonumberSpinnerLabel = uilabel(app.VideoTab);
            app.VideonumberSpinnerLabel.Text = 'Video number';
            app.VideonumberSpinner = uispinner(app.VideoTab);
            app.VideonumberSpinner.Value = 1;
            app.VideoFrameEditFieldLabel = uilabel(app.VideoTab);
            app.VideoFrameEditFieldLabel.Text = 'Video Frame';
            app.VideoFrameEditField = uieditfield(app.VideoTab, 'numeric');
            app.VideoFrameEditField.Value = 1;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Courtship_Tracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end