classdef Manual_Tracking_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        VideoEditField_2             matlab.ui.control.EditField
        VideoEditField_2Label        matlab.ui.control.Label
        LastlabelinvideoButton       matlab.ui.control.Button
        FirstlabelinvideoButton      matlab.ui.control.Button
        LoadDataButton               matlab.ui.control.StateButton
        SkipBackwardsButton          matlab.ui.control.Button
        SkipForwardsButton           matlab.ui.control.Button
        LabelButton                  matlab.ui.control.Button
        FliestotrackEditField        matlab.ui.control.NumericEditField
        FliestotrackEditFieldLabel   matlab.ui.control.Label
        HistorypointsEditField       matlab.ui.control.NumericEditField
        HistorypointsEditFieldLabel  matlab.ui.control.Label
        ShowOtherFliesButton         matlab.ui.control.StateButton
        FrameEditField               matlab.ui.control.NumericEditField
        FrameEditFieldLabel          matlab.ui.control.Label
        PreviousButton               matlab.ui.control.Button
        NextButton                   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function fig = displayImage(app)
            global currentImg xlimit ylimit fig
            try
                figure(fig); hold off
            catch
                fig = figure; set(fig, 'color','k');                
            end
            imshow(currentImg);
            xlim(xlimit); ylim(ylimit); 
            hold on
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
%             value = app.LoadDataButton.Value;
            global T expName vidDir currentImg xlimit ylimit
            load('G:\My Drive\Jeanne Lab\DATA\09.19.2022\Arena C\caviar_recovery_rampC timecourse data.mat');
                baseFolder = getCloudPath;
                
                vidDir = [baseFolder expData.parameters.date '/' expName '_'];
                T = data.T;

                movieInfo = VideoReader([vidDir '1.avi']); %read in video
                currentImg = rgb2gray(read(movieInfo,1));
                
                % Set axis limits
                x = data.centre(1);
                y = data.centre(2);
                r = data.r;
                xlimit = [x-(r+50),x+(r+50)];
                ylimit = [y-(r+50),y+50+r];
                
                displayImage(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.Text = 'Previous';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShowOtherFliesButton = uibutton(app.UIFigure, 'state');
            app.ShowOtherFliesButton.Text = 'Show Other Flies';
            app.HistorypointsEditFieldLabel = uilabel(app.UIFigure);
            app.HistorypointsEditFieldLabel.Text = 'History points';
            app.HistorypointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FliestotrackEditFieldLabel = uilabel(app.UIFigure);
            app.FliestotrackEditFieldLabel.Text = 'Flies to track';
            app.FliestotrackEditField = uieditfield(app.UIFigure, 'numeric');
            app.LabelButton = uibutton(app.UIFigure, 'push');
            app.LabelButton.Text = 'Label';
            app.SkipForwardsButton = uibutton(app.UIFigure, 'push');
            app.SkipForwardsButton.Text = 'Skip Forwards';
            app.SkipBackwardsButton = uibutton(app.UIFigure, 'push');
            app.SkipBackwardsButton.Text = 'Skip Backwards';
            app.LoadDataButton = uibutton(app.UIFigure, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Text = 'Load Data';
            app.FirstlabelinvideoButton = uibutton(app.UIFigure, 'push');
            app.FirstlabelinvideoButton.Text = 'First label in video';
            app.LastlabelinvideoButton = uibutton(app.UIFigure, 'push');
            app.LastlabelinvideoButton.Text = 'Last label in video';
            app.VideoEditField_2Label = uilabel(app.UIFigure);
            app.VideoEditField_2Label.Text = 'Video';
            app.VideoEditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manual_Tracking_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end