classdef Tracking_Courtship_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FramesSlider              matlab.ui.control.Slider
        FramesSliderLabel         matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        LoadTab                   matlab.ui.container.Tab
        FoodEditField             matlab.ui.control.EditField
        FoodEditFieldLabel        matlab.ui.control.Label
        GenortypeEditField        matlab.ui.control.EditField
        GenortypeEditFieldLabel   matlab.ui.control.Label
        ExpDateEditField          matlab.ui.control.EditField
        ExpDateEditFieldLabel     matlab.ui.control.Label
        ArenaEditField            matlab.ui.control.EditField
        ArenaEditFieldLabel       matlab.ui.control.Label
        ExpNameEditField          matlab.ui.control.EditField
        ExpNameEditFieldLabel     matlab.ui.control.Label
        SelectexperimentButton    matlab.ui.control.Button
        ControlTab                matlab.ui.container.Tab
        PlayButton                matlab.ui.control.StateButton
        LabelsPanel               matlab.ui.container.Panel
        ApproachButton            matlab.ui.control.Button
        CourtshipButton           matlab.ui.control.Button
        WingsButton               matlab.ui.control.Button
        VideonumberSpinner        matlab.ui.control.Spinner
        VideonumberSpinnerLabel   matlab.ui.control.Label
        PlaybackFPSSpinner        matlab.ui.control.Spinner
        PlaybackFPSSpinnerLabel   matlab.ui.control.Label
        VideoFrameEditField       matlab.ui.control.NumericEditField
        VideoFrameEditFieldLabel  matlab.ui.control.Label
        ExpFrameEditField         matlab.ui.control.NumericEditField
        ExpFrameEditFieldLabel    matlab.ui.control.Label
        FrameSkipEditField        matlab.ui.control.NumericEditField
        FrameSkipEditFieldLabel   matlab.ui.control.Label
        skipbackwardsButton       matlab.ui.control.Button
        skipforwardsButton        matlab.ui.control.Button
        nextButton                matlab.ui.control.Button
        previousButton            matlab.ui.control.Button
        LoadFrameButton           matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function  updateSliderLimits(app)
            global T
            vidNum = app.VideonumberSpinner.Value;
            loc = find(T.vidNums == vidNum);
            frameLimits = [1,T.vidFrame(loc(end))];
            app.FramesSlider.Limits = frameLimits;
            
        end
        
        function results = updateExpFrameNumber(app)
            % update frame number from frame slider set
            global T
            vidNum = app.VideonumberSpinner.Value;
            currFrame = round(app.FramesSlider.Value);
            loc = find((vidNum == T.vidNums) & (currFrame == T.vidFrame));
            expFrame = T.frame(loc);
            app.ExpFrameEditField.Value = expFrame;
        end
        
        function results = updateExpFrameImage(app)
            global vid xlimit ylimit
            % load image
            
            % TODO: add continency for the video number changing to the next/previous
            % make it something a quick load video function and add the commented
            % code below
            % basePath = getCloudPath;
            % vidPath = [basePath app.ExpDateEditField.Value '/' app.ExpNameEditField.Value '_' num2str(vidNum) '.avi'];
            % vid = VideoReader(vidPath);
            % Pull video frame
            vidFrame = app.VideoFrameEditField.Value;
            currTime = vidFrame *(1/vid.FrameRate);
            vid.CurrentTime = currTime;
            frame = readFrame(vid);
            image(frame, 'Parent', app.UIAxes);
            % Formatting
            app.UIAxes.XLim = (xlimit);
            app.UIAxes.YLim = (ylimit);
        end
        
        function playVideo(app)
            isPlay = app.PlayButton.Value;
            i = 0;
            global vid           
            while isPlay
                    while hasFrame(vid) % reads and displays each frame of the video
                        i = i+1;
                        frame = readFrame(vid);
                        image(frame, 'Parent', app.UIAxes); % Assuming app.UIAxes is the axes where you display the video
                        drawnow;
                        
                        % % Check if the pause button was pressed
                        % while isPaused
                        %     pause(0.01); % Small pause to prevent MATLAB from becoming unresponsive
                        % end
    
                        % You might want to include a small pause here to control the playback speed
                        pause(1/app.PlaybackFPSSpinner.Value); % Uncomment to control playback speed

                        % Check if the video status has been updated every ~10 frames
                        if i  == 10
                            isPlay = app.PlayButton.Value;
                            i = 0;
                            %update video frame and slider value
                            frame = vid.CurrentTime*vid.FrameRate;
                            app.VideoFrameEditField.Value = frame;
                            app.FramesSlider.Value = frame;
                            disp('frame reset')
                            disp(frame)
                            if frame > 3585
                                isPlay = false;
                                disp('artificial timer')
                            end
                        end
                     
                    end
            end
            % update experimental frame number
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectexperimentButton
        function SelectexperimentButtonPushed(app, event)
             %TODO: make this all a bit more automated and drop-down etc 
            global isPaused T xlimit ylimit
            isPaused = true;
            baseFolder = getCloudPath;
            [expName, expPath] = uigetfile([baseFolder, '*timecourse data.mat'],'select data file for experiment to quantify');
            expParams = load([expPath, expName]);
            
            app.ArenaEditField.Value = [expParams.data.name];
            app.ExpDateEditField.Value = [expParams.expData.parameters.date];
            app.ExpNameEditField.Value  = [expParams.expName];
            T =  expParams.data.T;

            % Update experiment information
            % disp('Loaded file')
            app.GenortypeEditField.Value = expParams.data.genotype;


            % get video crop details based on arena rotation etc. 
            x =  expParams.data.centre(1);
            y =  expParams.data.centre(2);
            r =  expParams.data.r;
            xlimit = [x-(r+25),x+(r+25)];
            ylimit = [y-(r+25),y+25+r];

            updateSliderLimits(app)
        end

        % Button pushed function: LoadFrameButton, previousButton
        function LoadFrameButtonPushed(app, event)
             global isPaused T vid xlimit ylimit
            
            % pull information on desired frame
            currFrame = app.ExpFrameEditField.Value;
            
            currLoc = find(T.frame==currFrame);
            % set the video num and frame info
            vidNum = T.vidNums(currLoc);
            vidFrame = T.vidFrame(currLoc);
            app.VideoFrameEditField.Value = vidFrame;
            app.VideonumberSpinner.Value = vidNum;
            % load image
            basePath = getCloudPath;
            vidPath = [basePath app.ExpDateEditField.Value '/' app.ExpNameEditField.Value '_' num2str(vidNum) '.avi'];
            vid = VideoReader(vidPath);
            % Pull video frame
            currTime = vidFrame *(1/vid.FrameRate);
            vid.CurrentTime = currTime;
            frame = readFrame(vid);
            % Frame = frame(xlimit(1):xlimit(2),ylimit(1):ylimit(2), :);
            image(frame, 'Parent', app.UIAxes);
            % Formatting
            app.UIAxes.XLim = (xlimit);
            app.UIAxes.YLim = (ylimit);

            updateSliderLimits(app);

            % drawnow;
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            
        end

        % Button pushed function: skipbackwardsButton
        function skipbackwardsButtonPushed(app, event)
            
        end

        % Button pushed function: skipforwardsButton
        function skipforwardsButtonPushed(app, event)
            
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            % updateSliderLimits(app)
            value = (app.FramesSlider.Value);
            frame = round(value);
            app.VideoFrameEditField.Value = frame;
            
            % update experimental frame number
            updateExpFrameNumber(app)

            % update image in figure
            updateExpFrameImage(app)

            disp(['Frame: ' num2str(frame)])
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            % Change string on the play/pause button to indicate the current state
            if strcmp(app.PlayButton.Text, 'Pause')
                app.PlayButton.Text = 'Play';
                app.PlayButton.Value = false;
                isPaused = true; % Pause the video
            elseif strcmp(app.PlayButton.Text, 'Play')
                app.PlayButton.Text = 'Pause';
                isPaused = false; % Resume the video
            end
            
            if ~isPaused
                playVideo(app)
            end

            % % isPaused = ~app.PlayButton.Value;
            % global vid           
            % if ~isPaused
            %     while hasFrame(vid) % reads and displays each frame of the video
            %         frame = readFrame(vid);
            %         image(frame, 'Parent', app.UIAxes); % Assuming app.UIAxes is the axes where you display the video
            %         drawnow;
            % 
            %         % Check if the pause button was pressed
            %         while isPaused
            %             pause(0.1); % Small pause to prevent MATLAB from becoming unresponsive
            %         end
            % 
            %         % You might want to include a small pause here to control the playback speed
            %         pause(1/app.PlaybackFPSSpinner.Value); % Uncomment to control playback speed
            %     end
            % end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.SelectexperimentButton = uibutton(app.LoadTab, 'push');
            app.SelectexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SelectexperimentButtonPushed, true);
            app.SelectexperimentButton.Text = 'Select experiment';
            app.ExpNameEditFieldLabel = uilabel(app.LoadTab);
            app.ExpNameEditFieldLabel.Text = 'Exp Name';
            app.ExpNameEditField = uieditfield(app.LoadTab, 'text');
            app.ArenaEditFieldLabel = uilabel(app.LoadTab);
            app.ArenaEditFieldLabel.Text = 'Arena';
            app.ArenaEditField = uieditfield(app.LoadTab, 'text');
            app.ExpDateEditFieldLabel = uilabel(app.LoadTab);
            app.ExpDateEditFieldLabel.Text = 'Exp Date';
            app.ExpDateEditField = uieditfield(app.LoadTab, 'text');
            app.GenortypeEditFieldLabel = uilabel(app.LoadTab);
            app.GenortypeEditFieldLabel.Text = 'Genortype';
            app.GenortypeEditField = uieditfield(app.LoadTab, 'text');
            app.FoodEditFieldLabel = uilabel(app.LoadTab);
            app.FoodEditFieldLabel.Text = 'Food';
            app.FoodEditField = uieditfield(app.LoadTab, 'text');
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.LoadFrameButton = uibutton(app.ControlTab, 'push');
            app.LoadFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFrameButtonPushed, true);
            app.LoadFrameButton.Text = 'Load Frame';
            app.previousButton = uibutton(app.ControlTab, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFrameButtonPushed, true);
            app.previousButton.Text = 'previous';
            app.nextButton = uibutton(app.ControlTab, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.skipforwardsButton = uibutton(app.ControlTab, 'push');
            app.skipforwardsButton.ButtonPushedFcn = createCallbackFcn(app, @skipforwardsButtonPushed, true);
            app.skipforwardsButton.Text = 'skip forwards';
            app.skipbackwardsButton = uibutton(app.ControlTab, 'push');
            app.skipbackwardsButton.ButtonPushedFcn = createCallbackFcn(app, @skipbackwardsButtonPushed, true);
            app.skipbackwardsButton.Text = 'skip backwards';
            app.FrameSkipEditFieldLabel = uilabel(app.ControlTab);
            app.FrameSkipEditFieldLabel.Text = 'Frame Skip';
            app.FrameSkipEditField = uieditfield(app.ControlTab, 'numeric');
            app.FrameSkipEditField.Value = 20;
            app.ExpFrameEditFieldLabel = uilabel(app.ControlTab);
            app.ExpFrameEditFieldLabel.Text = 'Exp Frame';
            app.ExpFrameEditField = uieditfield(app.ControlTab, 'numeric');
            app.ExpFrameEditField.Value = 1;
            app.VideoFrameEditFieldLabel = uilabel(app.ControlTab);
            app.VideoFrameEditFieldLabel.Text = 'Video Frame';
            app.VideoFrameEditField = uieditfield(app.ControlTab, 'numeric');
            app.VideoFrameEditField.Value = 1;
            app.PlaybackFPSSpinnerLabel = uilabel(app.ControlTab);
            app.PlaybackFPSSpinnerLabel.Text = 'Playback FPS';
            app.PlaybackFPSSpinner = uispinner(app.ControlTab);
            app.PlaybackFPSSpinner.Value = 6;
            app.VideonumberSpinnerLabel = uilabel(app.ControlTab);
            app.VideonumberSpinnerLabel.Text = 'Video number';
            app.VideonumberSpinner = uispinner(app.ControlTab);
            app.VideonumberSpinner.Value = 1;
            app.LabelsPanel = uipanel(app.ControlTab);
            app.LabelsPanel.Title = 'Labels';
            app.WingsButton = uibutton(app.LabelsPanel, 'push');
            app.WingsButton.Text = 'Wings';
            app.CourtshipButton = uibutton(app.LabelsPanel, 'push');
            app.CourtshipButton.Text = 'Courtship';
            app.ApproachButton = uibutton(app.LabelsPanel, 'push');
            app.ApproachButton.Text = 'Approach';
            app.PlayButton = uibutton(app.ControlTab, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tracking_Courtship_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end