classdef GroupDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BloodPressureAnalyisisUIFigure  matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        ExperimentIDListBoxLabel        matlab.ui.control.Label
        ExperimentIDListBox             matlab.ui.control.ListBox
        TemperatureProtocolListBoxLabel  matlab.ui.control.Label
        TemperatureProtocolListBox      matlab.ui.control.ListBox
        StructureNameListBoxLabel       matlab.ui.control.Label
        StructureNameListBox            matlab.ui.control.ListBox
        ArenaListBoxLabel               matlab.ui.control.Label
        ArenaListBox                    matlab.ui.control.ListBox
        SexListBoxLabel                 matlab.ui.control.Label
        SexListBox                      matlab.ui.control.ListBox
        GenotypeListBoxLabel            matlab.ui.control.Label
        GenotypeListBox                 matlab.ui.control.ListBox
        FoodOptionsListBoxLabel         matlab.ui.control.Label
        FoodOptionsListBox              matlab.ui.control.ListBox
        NumFliesListBoxLabel            matlab.ui.control.Label
        NumFliesListBox                 matlab.ui.control.ListBox
        LoadButton                      matlab.ui.control.Button
        SaveGroupingButton              matlab.ui.control.Button
        trialsEditFieldLabel            matlab.ui.control.Label
        trialsEditField                 matlab.ui.control.NumericEditField
    end


    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
            t = app.UITable.DisplayData;            
            
            % Plot modified data
            x2 = t.Age;
            y2 = t.BloodPressure(:,2);
            plot(app.UIAxes2,x2,y2,'-o');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            t = readtable('patients.xls');
            vars = {'Age','Systolic','Diastolic','SelfAssessedHealthStatus','Smoker'};
            
            % Select a subset of the table array
            t = t(1:20,vars);
            
            % Sort the data by age
            t = sortrows(t,'Age');
            
            % Combine Systolic and Diastolic into one variable
            t.BloodPressure = [t.Systolic t.Diastolic];
            t.Systolic = [];
            t.Diastolic = [];
            
            % Convert SelfAssessedHealthStatus to categorical
            cats = categorical(t.SelfAssessedHealthStatus,{'Poor','Fair','Good','Excellent'});
            t.SelfAssessedHealthStatus = cats;
            
            % Rearrange columns
            t = t(:,[1 4 3 2]);
            
            % Add data to the Table UI Component
            app.UITable.Data = t;
            
            % Plot the original data
            x1 = app.UITable.Data.Age;
            y1 = app.UITable.Data.BloodPressure(:,2);
            plot(app.UIAxes,x1,y1,'o-');
            
            % Plot the data
            updateplot(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % load current excel file
            [excelfile, Excel, ~] = load_QuadBowlExperiments;
            
            % number of flies
            temp = (excelfile(2:end,Excel.numflies));
            temp = cell2mat(temp);
            temp(isnan(temp)) = [];
            numberList = unique(temp);
            app.NumFliesListBox.Items = ["all"; string(numberList)];
            
            % genotypes
            loc = cellfun(@ischar, excelfile(2:end, Excel.genotype));
            geno = unique(excelfile([false;loc], Excel.genotype));
            app.GenotypeListBox.Items = geno;
            
            % food options:
            foodOpt = [];
            for ii = 1:4 %for each well
                loc = cellfun(@ischar, excelfile(2:end, Excel.(['well_' num2str(ii)])));
                well = unique(excelfile([false;loc], Excel.(['well_' num2str(ii)])));
                foodOpt = unique([well;foodOpt]);
            end
            app.FoodOptionsListBox.Items = ['all'; foodOpt];
            
            % exp ID
            loc = cellfun(@ischar, excelfile(2:end, Excel.expID));
            expID = unique(excelfile([false;loc], Excel.expID));
            expID = ['all'; expID];
            app.ExperimentIDListBox.Items = expID;

            % temp protocol
            loc = cellfun(@ischar, excelfile(2:end, Excel.protocol));
            proto = unique(excelfile([false;loc], Excel.protocol));
            proto(strcmpi(proto,'Protocol Name')) = []; 
            proto = ['all'; proto];
            app.TemperatureProtocolListBox.Items = proto;
            
            % structure names and options
            loc = cellfun(@ischar, excelfile(:, Excel.structure));
            structure_names = unique(excelfile(loc, Excel.structure));
            structure_names(strcmpi(structure_names,' ')) = []; %remove blank spaces for section headers
            structure_names(strcmpi(structure_names,'Structure')) = []; %remove blank spaces for section headers
            app.StructureNameListBox.Items = ['all';structure_names];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BloodPressureAnalyisisUIFigure = uifigure('Visible', 'off');
            app.BloodPressureAnalyisisUIFigure.Name = 'Blood Pressure Analyisis';
            app.UITable = uitable(app.BloodPressureAnalyisisUIFigure);
            app.UITable.ColumnName = {'Age'; 'Blood Pressure'; 'Smoker'; 'Health Status'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true true];
            app.UITable.ColumnEditable = [true false true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ExperimentIDListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ExperimentIDListBoxLabel.Text = 'Experiment ID';
            app.ExperimentIDListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.TemperatureProtocolListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.TemperatureProtocolListBoxLabel.Text = 'Temperature Protocol';
            app.TemperatureProtocolListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.StructureNameListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.StructureNameListBoxLabel.Text = 'Structure Name';
            app.StructureNameListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ArenaListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ArenaListBoxLabel.Text = 'Arena';
            app.ArenaListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ArenaListBox.Items = {'A', 'B', 'C', 'D'};
            app.ArenaListBox.Value = 'A';
            app.SexListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.SexListBoxLabel.Text = {'Sex'; ''};
            app.SexListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.SexListBox.Items = {'mixed', 'female', 'male', 'unknown'};
            app.SexListBox.Value = 'mixed';
            app.GenotypeListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.GenotypeListBoxLabel.Text = 'Genotype';
            app.GenotypeListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.FoodOptionsListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.FoodOptionsListBoxLabel.Text = 'Food Options';
            app.FoodOptionsListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.NumFliesListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.NumFliesListBoxLabel.Text = 'Num Flies';
            app.NumFliesListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.LoadButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveGroupingButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.SaveGroupingButton.Text = 'Save Grouping';
            app.trialsEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.trialsEditFieldLabel.Text = '# trials';
            app.trialsEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            % Show the figure after all components are created
            app.BloodPressureAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroupDataGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BloodPressureAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BloodPressureAnalyisisUIFigure)
        end
    end
end