classdef GroupDataGUI_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BloodPressureAnalyisisUIFigure  matlab.ui.Figure
        PlateListBox                    matlab.ui.control.ListBox
        PlateListBoxLabel               matlab.ui.control.Label
        ZeitgeberListBox                matlab.ui.control.ListBox
        ZeitgeberListBoxLabel           matlab.ui.control.Label
        ExperimenterListBox             matlab.ui.control.ListBox
        ExperimenterListBoxLabel        matlab.ui.control.Label
        ExperimentDateListBox           matlab.ui.control.ListBox
        ExperimentDateListBoxLabel      matlab.ui.control.Label
        GroupingNameEditField           matlab.ui.control.EditField
        GroupingNameLabel               matlab.ui.control.Label
        FoodExclusiveButton             matlab.ui.control.StateButton
        trialsEditField                 matlab.ui.control.NumericEditField
        trialsEditFieldLabel            matlab.ui.control.Label
        SaveGroupingButton              matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        NumFliesListBox                 matlab.ui.control.ListBox
        NumFliesLabel                   matlab.ui.control.Label
        FoodOptionsListBox              matlab.ui.control.ListBox
        FoodOptionsListBoxLabel         matlab.ui.control.Label
        GenotypeListBox                 matlab.ui.control.ListBox
        GenotypeListBoxLabel            matlab.ui.control.Label
        FlySexListBox                   matlab.ui.control.ListBox
        FlySexListBoxLabel              matlab.ui.control.Label
        ArenaListBox                    matlab.ui.control.ListBox
        ArenaListBoxLabel               matlab.ui.control.Label
        StructureNameListBox            matlab.ui.control.ListBox
        StructureNameListBoxLabel       matlab.ui.control.Label
        TemperatureProtocolListBox      matlab.ui.control.ListBox
        TemperatureProtocolListBoxLabel  matlab.ui.control.Label
        ExperimentIDListBox             matlab.ui.control.ListBox
        ExperimentIDListBoxLabel        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        excelfile
        Excel
        xlFile
        varsIdx  % column location of those selected for display
    end

    methods (Access = private)
        function updateTable(app)
            
            % start with only the processed files:
            loc_all = (strcmpi(app.excelfile(:,app.Excel.processed),'Y'));
        
            
            % number of flies:
            numb = app.NumFliesListBox.Value;
            loc = [];
            if strcmpi(numb,'all')
                num_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(:,app.Excel.numflies);
                for ii = 1:length(numb)
                    x = cellfun(@(x) x==str2double(numb{ii}),temp(loc_all), 'uniformOutput', false);                   
                    loc(:,ii) = cell2mat(x);
                end
                if length(numb)>1
                    num_loc  = sum(loc,2)>0;
                else
                    num_loc = loc;
                end
            end
  
            % genotypes
            geno = app.GenotypeListBox.Value;
            loc = [];
            if strcmpi(geno, 'all')
                geno_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.genotype);
                for ii = 1:length(geno)
                    loc(:,ii) = strcmpi(temp, geno{ii});
                end
                geno_loc  = sum(loc,2)>0;
            end
            
            % food options:
            foodOpt = app.FoodOptionsListBox.Value;
            loc = [];
            if strcmpi(foodOpt,'all')
                foodOpt_loc = true(sum(loc_all),1);
            elseif strcmpi(foodOpt, 'empty only')
                    temp = app.excelfile(loc_all,[app.Excel.well_1, app.Excel.well_2,app.Excel.well_3,app.Excel.well_4]);
                    foodOpt_loc = sum(strcmpi('empty',temp),2)==4; %all four wells must be 'empty' or 'Empty'
            else
                    for ii = 1:length(foodOpt) 
                        for well = 1:4
                             temp = app.excelfile(loc_all,app.Excel.(['well_' num2str(well)]));
                             well_loc(:,well) = strcmpi(temp, foodOpt{ii});
                        end
                        loc(:,ii) = sum(well_loc,2)>0; % logical for each food type
                    end
                        
                    if app.FoodExclusiveButton.Value
                        foodOpt_loc  = sum(loc,2) == length(foodOpt);
                    else 
                        foodOpt_loc  = sum(loc,2)>0; % if even one food was included
                    end
            end
            
            % experiment IDs
            expID = app.ExperimentIDListBox.Value;
            loc = [];
            if strcmpi(expID, 'all')
                expID_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.expID);
                
                for ii = 1:length(expID)
                    loc(:,ii) = strcmpi(temp, expID{ii});
                end
                expID_loc  = sum(loc,2)>0;
            end
            
                        
            % temp protocol
            proto = app.TemperatureProtocolListBox.Value;
            loc = [];
            if strcmpi(proto,'all')
                proto_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.protocol);
                
                for ii = 1:length(proto)
                    loc(:,ii) = strcmpi(temp, proto{ii});
                end
                proto_loc  = sum(loc,2)>0;
            end

            % sex
            sex = app.FlySexListBox.Value;
            loc = [];
            if strcmpi(sex,'all')
                sex_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.sex);
                
                for ii = 1:length(sex)
                    loc(:,ii) = strcmpi(temp, sex{ii});
                end
                sex_loc  = sum(loc,2)>0;
            end
            
            % arena
            arena = app.ArenaListBox.Value;
            loc = [];
            if strcmpi(arena,'all')
                arena_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.arena);
                
                for ii = 1:length(arena)
                    loc(:,ii) = strcmpi(temp, arena{ii});
                end
                arena_loc  = sum(loc,2)>0;
            end

            % structure
            struct = app.StructureNameListBox.Value;
            loc = [];
            if strcmpi(struct, 'all')
                struct_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.structure);
                for ii = 1:length(struct) %used to be 'expID' pre 1.23.24
                    loc(:,ii) = strcmpi(temp, struct{ii});
                end
                struct_loc  = sum(loc,2)>0;
            end
            
            % experiment dates
            date_list = app.ExperimentDateListBox.Value;
            loc = [];
            if strcmpi(date_list, 'all')
                date_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.date);
                for ii = 1:length(date_list)
                    loc(:,ii) = strcmpi(temp, date_list{ii});
                end
                date_loc  = sum(loc,2)>0;
            end

            % Zeitgeber time:
            time_list = [app.excelfile{loc_all,app.Excel.zeitgebertime}]; % time starts as doubles
            % find the locations where the value is within the selected
            % time regions
            sel_time = app.ZeitgeberListBox.Value;
            if any(strcmp(sel_time,'all'))
                zeitgeber_loc = true(sum(loc_all),1);
            else
                timeID = discretize(time_list,0:24);
                temploc = false(length(timeID), length(sel_time));
                for i = 1:length(sel_time)
                    temploc(:,i) = timeID==str2double(sel_time{i});
                end
                zeitgeber_loc = any(temploc,2);
            end

            % experimenter
            experimenter = app.ExperimenterListBox.Value;
            loc = [];
            if strcmpi(experimenter, 'all')
                experimenter_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.experimenter);
                for ii = 1:length(experimenter)
                    loc(:,ii) = strcmpi(temp, experimenter{ii});
                end
                experimenter_loc  = sum(loc,2)>0;
            end

            % plate
            plate = app.PlateListBox.Value;
            loc = [];
            if strcmpi(plate,'all')
                plate_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(:,app.Excel.plate);

                for ii = 1:length(plate)
                    x = cellfun(@(x) x==str2double(plate(ii)),temp(loc_all), 'uniformOutput', false);                   
                    loc(:,ii) = cell2mat(x);
                end
                if length(plate)>1
                    plate_loc  = sum(loc,2)>0;
                else
                    plate_loc = loc;
                end
            end

            % Find overlapping experiments
            selLoc = num_loc & geno_loc & foodOpt_loc & struct_loc...
                         & expID_loc & proto_loc & sex_loc & arena_loc...
                         & date_loc & experimenter_loc & zeitgeber_loc...
                         & plate_loc;
            numberSelected = sum(selLoc);
            app.trialsEditField.Value = numberSelected;
            
            % Update only the selected trials into the table:
            %turn the 'loc_all' that are excluded to false values
%             loc_all(1) = []; % remove the column headers
            idx = find(loc_all);
            idx(~selLoc) = [];
            loc = false(size(app.excelfile,1),1);
            loc(idx) = true;
            
            % Update the table:
            t = app.excelfile;
%             t = readtable(app.xlFile); 
            t = t(loc,app.varsIdx);
            app.UITable.Data = t;

        end


%         function updateplot(app)
%             % Get Table UI component data
%             t = app.UITable.DisplayData;            
%             
%             % Plot modified data
%             x2 = t.Age;
%             y2 = t.BloodPressure(:,2);
%             plot(app.UIAxes2,x2,y2,'-o');
%         end
%         
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BloodPressureAnalyisisUIFigure.Name = 'Group Quad Bowl Experiment Data';
            warning off
            % Read table array from file
            [excelfile, Excel, xlFile] = load_QuadBowlExperiments;
            app.excelfile = excelfile;
            app.Excel = Excel;
            app.xlFile = xlFile;
            
            loc_all = (strcmpi(excelfile(:,Excel.processed),'Y'));
%             loc_all(1) = []; % remove the headers row
            % Data columns to include in the table:
            vars = {'date', 'expID', 'protocol',  'arena', 'sex', 'genotype',...
                  'numflies', 'well_1', 'well_2', 'well_3', 'well_4','processed'};
            % pull the table column idx for the selected variables
            for ii = 1:length(vars)
                varsIdx(ii) = Excel.(vars{ii});          
            end
            % set up table headings:
            t = excelfile(loc_all,varsIdx);
            
%             opts = detectImportOptions(xlFile);
%             startRow = opts.DataRange
%             tbl = readtable('Importing.xls',opts)

%             t = readtable(xlFile); 
%             t = t(loc_all,varsIdx);
            app.varsIdx = varsIdx;
            % update the number of files that are selected:
            app.trialsEditField.Value = sum(loc_all);

            % Add data to the Table UI Component
            app.UITable.Data = t;
            app.UITable.ColumnName = strrep(excelfile(1,varsIdx),' ',''); %vars
     
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % load current excel file
            [excelfile, Excel, ~] = load_QuadBowlExperiments;
            
            % number of flies
            temp = (excelfile(2:end,Excel.numflies));
            temp = cell2mat(temp);
            temp(isnan(temp)) = [];
            numberList = unique(temp);
            app.NumFliesListBox.Items = ['all'; string(numberList)];
            app.NumFliesListBox.Multiselect = true;

            % % Plate
            % app.PlateListBox.Items = ['all'; '1'; '2'];
            % app.PlateListBox.Multiselect = true;
            % 
            % genotypes
            loc = cellfun(@ischar, excelfile(2:end, Excel.genotype));
            geno = unique(excelfile([false;loc], Excel.genotype));
            app.GenotypeListBox.Items = ['all'; geno];
            app.GenotypeListBox.Multiselect = true;
            
            % food options:
            foodOpt = [];
            for ii = 1:4 %for each well
                loc = cellfun(@ischar, excelfile(2:end, Excel.(['well_' num2str(ii)])));
                well = unique(excelfile([false;loc], Excel.(['well_' num2str(ii)])));
                foodOpt = unique([well;foodOpt]);
            end
            app.FoodOptionsListBox.Items = ['all'; 'empty only'; foodOpt];
            app.FoodOptionsListBox.Multiselect = true;
            
            % exp ID
            loc = cellfun(@ischar, excelfile(2:end, Excel.expID));
            expID = unique(excelfile([false;loc], Excel.expID));
            expID = ['all'; expID];
            app.ExperimentIDListBox.Items = expID;
            app.ExperimentIDListBox.Multiselect = true;

            % temp protocol
            loc = cellfun(@ischar, excelfile(2:end, Excel.protocol));
            proto = unique(excelfile([false;loc], Excel.protocol));
            proto(strcmpi(proto,'Protocol Name')) = []; 
            proto = ['all'; proto];
            app.TemperatureProtocolListBox.Items = proto;
            app.TemperatureProtocolListBox.Multiselect = true;
            
            % structure names and options
            loc = cellfun(@ischar, excelfile(:, Excel.structure));
            structure_names = unique(excelfile(loc, Excel.structure));
            structure_names(strcmpi(structure_names,' ')) = []; %remove blank spaces for section headers
            structure_names(strcmpi(structure_names,'Structure')) = []; %remove blank spaces for section headers
            app.StructureNameListBox.Items = ['all';structure_names];
            app.StructureNameListBox.Multiselect = true;
            
            % Sex of the flies
            loc = cellfun(@ischar, excelfile(2:end, Excel.sex));
            sex = excelfile([false;loc], Excel.sex);
            sex = unique(excelfile([false;loc], Excel.sex));
            sex(strcmpi(sex,'Sex')) = []; 
            sex = unique(strrep(sex, ' ' , '')); % remove any extra spaces in name
            sex = ['all'; sex];
            app.FlySexListBox.Items = sex;
            app.FlySexListBox.Multiselect = true;
            
            % Arena choice
            arena = {'all', 'A', 'B', 'C', 'D'};
            app.ArenaListBox.Items = arena;
            app.ArenaListBox.Multiselect = true;

            % Experimenter choice
            app.ExperimenterListBox.Items = {'all', 'Evyn', 'Becca'};
            app.ExperimenterListBox.Multiselect = true;

            % Zeitgeber time chioce
            time_list = 0:23;
            time_list = cellstr(string(time_list));
            time_list{end+1} = 'all';
            app.ZeitgeberListBox.Items = time_list;
            app.ZeitgeberListBox.Multiselect = true;
            app.ZeitgeberListBox.Value = {'0','1','2','3','23'};            

            % Experiment Date %deselect option
            loc = cellfun(@ischar, excelfile(:, Excel.date));
            date_names = unique(excelfile(loc, Excel.date),'stable');
            date_names(strcmpi(date_names,'Date')) = []; %remove blank spaces for section headers
            date_names = flip(date_names); %reverse order for chronologically newer at the top
            
            app.ExperimentDateListBox.Items = ['all';date_names];
            app.ExperimentDateListBox.Multiselect = true;
            
        end

        % Value changed function: NumFliesListBox
        function NumFliesListBoxValueChanged(app, event)
%             value = app.NumFliesListBox.Value;
            updateTable(app)
        end

        % Value changed function: ArenaListBox
        function ArenaListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Value changed function: FlySexListBox
        function FlySexListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Value changed function: FoodOptionsListBox
        function FoodOptionsListBoxValueChanged(app, event)
            updateTable(app)            
        end

        % Value changed function: GenotypeListBox
        function GenotypeListBoxValueChanged(app, event)
            updateTable(app)            
        end

        % Value changed function: ExperimentIDListBox
        function ExperimentIDListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Value changed function: TemperatureProtocolListBox
        function TemperatureProtocolListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Value changed function: StructureNameListBox
        function StructureNameListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Value changed function: FoodExclusiveButton
        function FoodExclusiveButtonValueChanged(app, event)
            updateTable(app)
        end

        % Button pushed function: SaveGroupingButton
        function SaveGroupingButtonPushed(app, event)
            T = app.UITable.Data; 
            T = cell2table(T, 'VariableNames', app.UITable.ColumnName);
            % [~, folder] = getCloudPath(3);
            folder = getDataPath(3,0);
            structureName = app.GroupingNameEditField.Value;
            folderDir = [folder, structureName];
            if ~exist(folderDir,'dir')
                mkdir(folderDir)
            end            
            save([folderDir '/fileList'], 'T');
        end

        % Value changed function: ExperimentDateListBox
        function ExperimentDateListBoxValueChanged(app, event)
            % value = app.ExperimentDateListBox.Value;
            updateTable(app)
        end

        % Value changed function: ExperimenterListBox
        function ExperimenterListBoxValueChanged(app, event)
            % value = app.ExperimenterListBox.Value;
             updateTable(app)
        end

        % Value changed function: ZeitgeberListBox
        function ZeitgeberListBoxValueChanged(app, event)
           updateTable(app)
        end

        % Value changed function: PlateListBox
        function PlateListBoxValueChanged(app, event)
            updateTable(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BloodPressureAnalyisisUIFigure = uifigure('Visible', 'off');
            app.BloodPressureAnalyisisUIFigure.Name = 'Blood Pressure Analyisis';
            app.UITable = uitable(app.BloodPressureAnalyisisUIFigure);
            app.UITable.ColumnName = {'date'; 'expID'; 'protocol'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true true];
            app.UITable.ColumnEditable = [true false true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ExperimentIDListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ExperimentIDListBoxLabel.Text = 'Experiment ID';
            app.ExperimentIDListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ExperimentIDListBox.Items = {'All'};
            app.ExperimentIDListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentIDListBoxValueChanged, true);
            app.ExperimentIDListBox.Value = 'All';
            app.TemperatureProtocolListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.TemperatureProtocolListBoxLabel.Text = 'Temperature Protocol';
            app.TemperatureProtocolListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.TemperatureProtocolListBox.Items = {'All'};
            app.TemperatureProtocolListBox.ValueChangedFcn = createCallbackFcn(app, @TemperatureProtocolListBoxValueChanged, true);
            app.TemperatureProtocolListBox.Value = 'All';
            app.StructureNameListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.StructureNameListBoxLabel.Text = 'Structure Name';
            app.StructureNameListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.StructureNameListBox.Items = {'All'};
            app.StructureNameListBox.ValueChangedFcn = createCallbackFcn(app, @StructureNameListBoxValueChanged, true);
            app.StructureNameListBox.Value = 'All';
            app.ArenaListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ArenaListBoxLabel.Text = 'Arena';
            app.ArenaListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ArenaListBox.Items = {'All'};
            app.ArenaListBox.ValueChangedFcn = createCallbackFcn(app, @ArenaListBoxValueChanged, true);
            app.ArenaListBox.Value = 'All';
            app.FlySexListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.FlySexListBoxLabel.Text = {'Fly Sex'; ''};
            app.FlySexListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.FlySexListBox.Items = {'All'};
            app.FlySexListBox.ValueChangedFcn = createCallbackFcn(app, @FlySexListBoxValueChanged, true);
            app.FlySexListBox.Value = 'All';
            app.GenotypeListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.GenotypeListBoxLabel.Text = 'Genotype';
            app.GenotypeListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.GenotypeListBox.Items = {'All'};
            app.GenotypeListBox.ValueChangedFcn = createCallbackFcn(app, @GenotypeListBoxValueChanged, true);
            app.GenotypeListBox.Value = 'All';
            app.FoodOptionsListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.FoodOptionsListBoxLabel.Text = 'Food Options';
            app.FoodOptionsListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.FoodOptionsListBox.Items = {'All'};
            app.FoodOptionsListBox.ValueChangedFcn = createCallbackFcn(app, @FoodOptionsListBoxValueChanged, true);
            app.FoodOptionsListBox.Value = 'All';
            app.NumFliesLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.NumFliesLabel.Text = {'Num'; 'Flies'};
            app.NumFliesListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.NumFliesListBox.Items = {'All'};
            app.NumFliesListBox.ValueChangedFcn = createCallbackFcn(app, @NumFliesListBoxValueChanged, true);
            app.NumFliesListBox.Value = 'All';
            app.LoadButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveGroupingButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.SaveGroupingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGroupingButtonPushed, true);
            app.SaveGroupingButton.Text = 'Save Grouping';
            app.trialsEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.trialsEditFieldLabel.Text = '# trials';
            app.trialsEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.FoodExclusiveButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'state');
            app.FoodExclusiveButton.ValueChangedFcn = createCallbackFcn(app, @FoodExclusiveButtonValueChanged, true);
            app.FoodExclusiveButton.Text = 'Food Exclusive';
            app.GroupingNameLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.GroupingNameLabel.Text = 'Grouping Name :';
            app.GroupingNameEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'text');
            app.ExperimentDateListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ExperimentDateListBoxLabel.Text = 'Experiment Date';
            app.ExperimentDateListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ExperimentDateListBox.Items = {'All'};
            app.ExperimentDateListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentDateListBoxValueChanged, true);
            app.ExperimentDateListBox.Value = 'All';
            app.ExperimenterListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ExperimenterListBoxLabel.Text = 'Experimenter';
            app.ExperimenterListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ExperimenterListBox.Items = {'All'};
            app.ExperimenterListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimenterListBoxValueChanged, true);
            app.ExperimenterListBox.Value = 'All';
            app.ZeitgeberListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ZeitgeberListBoxLabel.Text = 'Zeitgeber';
            app.ZeitgeberListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ZeitgeberListBox.Items = {'All'};
            app.ZeitgeberListBox.ValueChangedFcn = createCallbackFcn(app, @ZeitgeberListBoxValueChanged, true);
            app.ZeitgeberListBox.Value = 'All';
            app.PlateListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.PlateListBoxLabel.Text = 'Plate';
            app.PlateListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.PlateListBox.Items = {'all', '1', '2'};
            app.PlateListBox.ValueChangedFcn = createCallbackFcn(app, @PlateListBoxValueChanged, true);
            app.PlateListBox.Value = 'all';
            % Show the figure after all components are created
            app.BloodPressureAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroupDataGUI_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BloodPressureAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BloodPressureAnalyisisUIFigure)
        end
    end
end