classdef Group_HR_DataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BloodPressureAnalyisisUIFigure  matlab.ui.Figure
        ExperimenterListBox             matlab.ui.control.ListBox
        ExperimenterListBoxLabel        matlab.ui.control.Label
        ExperimentDateListBox           matlab.ui.control.ListBox
        ExperimentDateListBoxLabel      matlab.ui.control.Label
        GroupingNameEditField           matlab.ui.control.EditField
        GroupingNameLabel               matlab.ui.control.Label
        FoodExclusiveButton             matlab.ui.control.StateButton
        trialsEditField                 matlab.ui.control.NumericEditField
        trialsEditFieldLabel            matlab.ui.control.Label
        SaveGroupingButton              matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        FoodOptionsListBox              matlab.ui.control.ListBox
        FoodOptionsListBoxLabel         matlab.ui.control.Label
        GenotypeListBox                 matlab.ui.control.ListBox
        GenotypeListBoxLabel            matlab.ui.control.Label
        StorageDriveListBox             matlab.ui.control.ListBox
        StorageDriveListBoxLabel        matlab.ui.control.Label
        TemperatureProtocolListBox      matlab.ui.control.ListBox
        TemperatureProtocolListBoxLabel  matlab.ui.control.Label
        ExperimentIDListBox             matlab.ui.control.ListBox
        ExperimentIDListBoxLabel        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        excelfile
        Excel
        xlFile
        varsIdx  % column location of those selected for display
    end

    methods (Access = private)
        function updateTable(app)
            
            % start with only the processed files:
            loc_all = (strcmpi(app.excelfile(:,app.Excel.groupready),'Y'));
        
            % genotypes
            geno = app.GenotypeListBox.Value;
            if strcmpi(geno, 'all')
                geno_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.genotype);
                geno_loc = ismember(temp, geno); % faster vectorized version
            end
            
            % food options:
            foodOpt = app.FoodOptionsListBox.Value;
            loc = [];
            if strcmpi(foodOpt,'all')
                foodOpt_loc = true(sum(loc_all),1);
            elseif strcmpi(foodOpt, 'empty only')
                    temp = app.excelfile(loc_all,[app.Excel.well_1, app.Excel.well_2,app.Excel.well_3,app.Excel.well_4]);
                    foodOpt_loc = all(strcmpi('empty',temp),2); % all four wells must be 'empty'
            else
                    for ii = 1:length(foodOpt) 
                        for well = 1:4
                             temp = app.excelfile(loc_all,app.Excel.(['well_' num2str(well)]));
                             well_loc(:,well) = strcmpi(temp, foodOpt{ii});
                        end
                        loc(:,ii) = sum(well_loc,2)>0; % logical for each food type
                    end
                        
                    if app.FoodExclusiveButton.Value
                        foodOpt_loc  = sum(loc,2) == length(foodOpt);
                    else 
                        foodOpt_loc  = sum(loc,2)>0; % if even one food was included
                    end
            end
            
            % experiment IDs
            expID = app.ExperimentIDListBox.Value;
            if strcmpi(expID, 'all')
                expID_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.expID);
                expID_loc = ismember(temp, expID);
            end  
                        
            % temp protocol
            proto = app.TemperatureProtocolListBox.Value;
            if strcmpi(proto,'all')
                proto_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.protocol);
                proto_loc = ismember(temp, proto);
            end
            
            % experiment dates
            date_list = app.ExperimentDateListBox.Value;
            if strcmpi(date_list, 'all')
                date_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.date);
                date_loc = ismember(temp, date_list);
            end
          
            % experimenter
            experimenter = app.ExperimenterListBox.Value;
            if strcmpi(experimenter, 'all')
                experimenter_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.experimenter);
                experimenter_loc =  ismember(temp, experimenter);
            end

            % storage drive
            storageDrive = app.StorageDriveListBox.Value; % selected drive
            if strcmpi(storageDrive, 'all')
                drive_loc = true(sum(loc_all),1);
            else
                temp = app.excelfile(loc_all,app.Excel.storagedrive);
                drive_loc = ismember(temp, storageDrive);
            end
           

            % Find overlapping experiments
            selLoc = geno_loc & foodOpt_loc ...
                         & expID_loc & proto_loc ...
                         & date_loc & experimenter_loc...
                         & drive_loc;
            numberSelected = sum(selLoc);
            app.trialsEditField.Value = numberSelected;
            
            % Update only the selected trials into the table:
            %turn the 'loc_all' that are excluded to false values
%             loc_all(1) = []; % remove the column headers
            idx = find(loc_all);
            idx(~selLoc) = [];
            loc = false(size(app.excelfile,1),1);
            loc(idx) = true;
            
            % Update the table:
            t = app.excelfile;
%             t = readtable(app.xlFile); 
            t = t(loc,app.varsIdx);
            app.UITable.Data = t;

        end


%         function updateplot(app)
%             % Get Table UI component data
%             t = app.UITable.DisplayData;            
%             
%             % Plot modified data
%             x2 = t.Age;
%             y2 = t.BloodPressure(:,2);
%             plot(app.UIAxes2,x2,y2,'-o');
%         end
%         
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning off
            % Read table array from file
            [excelfile, Excel, xlFile] = load_HighResExperiments;
            app.excelfile = excelfile;
            app.Excel = Excel;
            app.xlFile = xlFile;
            
            loc_all = (strcmpi(excelfile(:,Excel.groupready),'Y'));
            % Data columns to include in the table:
            vars = {'date', 'expID', 'protocol', 'genotype','storagedrive'...
                        'well_1', 'well_2', 'well_3', 'well_4','groupready','trialID'};
            % pull the table column idx for the selected variables
            varsIdx = zeros(1, length(vars)); % Preallocate varsIdx
            for ii = 1:length(vars)
                varsIdx(ii) = Excel.(vars{ii});          
            end
            % set up table headings:
            t = excelfile(loc_all,varsIdx);
            
            app.varsIdx = varsIdx;
            % update the number of files that are selected:
            app.trialsEditField.Value = sum(loc_all);

            % Add data to the Table UI Component
            app.UITable.Data = t;
            app.UITable.ColumnName = strrep(excelfile(1,varsIdx),' ',''); %vars
            app.BloodPressureAnalyisisUIFigure.Name = 'HR Data Grouping';
     
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % app.BloodPressureAnalyisisUIFigure.Name = 'HR Data Grouping';
            % load current excel file
            % [excelfile, Excel, ~] = load_HighResExperiments;
            
            % genotypes
            loc = cellfun(@ischar, app.excelfile(2:end, app.Excel.genotype));
            geno = unique(app.excelfile([false;loc], app.Excel.genotype));
            app.GenotypeListBox.Items = ['all'; geno];
            app.GenotypeListBox.Multiselect = true;
            
            % food options:
            foodOpt = [];
            for ii = 1:4 %for each well
                loc = cellfun(@ischar, app.excelfile(2:end, app.Excel.(['well_' num2str(ii)])));
                well = unique(app.excelfile([false;loc], app.Excel.(['well_' num2str(ii)])));
                foodOpt = unique([well;foodOpt]);
            end
            app.FoodOptionsListBox.Items = ['all'; 'empty only'; foodOpt];
            app.FoodOptionsListBox.Multiselect = true;
            
            % exp ID
            loc = cellfun(@ischar, app.excelfile(2:end, app.Excel.expID));
            expID = unique(app.excelfile([false;loc], app.Excel.expID));
            expID = ['all'; expID];
            app.ExperimentIDListBox.Items = expID;
            app.ExperimentIDListBox.Multiselect = true;

            % temp protocol
            loc = cellfun(@ischar, app.excelfile(2:end, app.Excel.protocol));
            proto = unique(app.excelfile([false;loc], app.Excel.protocol));
            proto(strcmpi(proto,'Protocol Name')) = []; 
            proto = ['all'; proto];
            app.TemperatureProtocolListBox.Items = proto;
            app.TemperatureProtocolListBox.Multiselect = true;
                                    
            % Experimenter choice
            app.ExperimenterListBox.Items = {'all', 'Evyn', 'Becca'};
            app.ExperimenterListBox.Multiselect = true;
     
            % Experiment Date %deselect option
            loc = cellfun(@ischar, app.excelfile(:, app.Excel.date));
            date_names = unique(app.excelfile(loc, app.Excel.date),'stable');
            date_names(strcmpi(date_names,'Date')) = []; %remove blank spaces for section headers
            date_names = flip(date_names); %reverse order for chronologically newer at the top
            app.ExperimentDateListBox.Items = ['all';date_names];
            app.ExperimentDateListBox.Multiselect = true;

            % exp ID
            loc = cellfun(@ischar, app.excelfile(2:end, app.Excel.expID));
            expID = unique(app.excelfile([false;loc], app.Excel.expID));
            expID = ['all'; expID];
            app.ExperimentIDListBox.Items = expID;
            app.ExperimentIDListBox.Multiselect = true;

            % Storage Drive
            loc = cellfun(@ischar, app.excelfile(2:end, app.Excel.storagedrive));
            storageDrive = unique(app.excelfile([false;loc], app.Excel.storagedrive));
            storageDrive = ['all'; storageDrive];
            app.StorageDriveListBox.Items = storageDrive;
            app.StorageDriveListBox.Multiselect = true;
            
        end

        % Callback function
        function NumFliesListBoxValueChanged(app, event)
%             value = app.NumFliesListBox.Value;
            updateTable(app)
        end

        % Callback function
        function ArenaListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Callback function
        function FlySexListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Value changed function: FoodOptionsListBox
        function FoodOptionsListBoxValueChanged(app, event)
            updateTable(app)            
        end

        % Value changed function: GenotypeListBox
        function GenotypeListBoxValueChanged(app, event)
            updateTable(app)            
        end

        % Value changed function: ExperimentIDListBox
        function ExperimentIDListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Value changed function: TemperatureProtocolListBox
        function TemperatureProtocolListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Value changed function: StorageDriveListBox
        function StorageDriveListBoxValueChanged(app, event)
            updateTable(app)
        end

        % Value changed function: FoodExclusiveButton
        function FoodExclusiveButtonValueChanged(app, event)
            updateTable(app)
        end

        % Button pushed function: SaveGroupingButton
        function SaveGroupingButtonPushed(app, event)
            T = app.UITable.Data; 
            T = cell2table(T, 'VariableNames', app.UITable.ColumnName);
            % [~, folder] = getCloudPath(3);
            folder = getDataPath(6,0);
            structureName = app.GroupingNameEditField.Value;
            folderDir = createFolder([folder, 'grouped/' structureName]);      
            save([folderDir '/filelist'], 'T');
        end

        % Value changed function: ExperimentDateListBox
        function ExperimentDateListBoxValueChanged(app, event)
            % value = app.ExperimentDateListBox.Value;
            updateTable(app)
        end

        % Value changed function: ExperimenterListBox
        function ExperimenterListBoxValueChanged(app, event)
            % value = app.ExperimenterListBox.Value;
             updateTable(app)
        end

        % Callback function
        function ZeitgeberListBoxValueChanged(app, event)
           updateTable(app)
        end

        % Callback function
        function PlateListBoxValueChanged(app, event)
            updateTable(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BloodPressureAnalyisisUIFigure = uifigure('Visible', 'off');
            app.BloodPressureAnalyisisUIFigure.Name = 'Blood Pressure Analyisis';
            app.UITable = uitable(app.BloodPressureAnalyisisUIFigure);
            app.UITable.ColumnName = {'date'; 'expID'; 'protocol'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true true];
            app.UITable.ColumnEditable = [true false true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ExperimentIDListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ExperimentIDListBoxLabel.Text = 'Experiment ID';
            app.ExperimentIDListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ExperimentIDListBox.Items = {'All'};
            app.ExperimentIDListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentIDListBoxValueChanged, true);
            app.ExperimentIDListBox.Value = 'All';
            app.TemperatureProtocolListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.TemperatureProtocolListBoxLabel.Text = 'Temperature Protocol';
            app.TemperatureProtocolListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.TemperatureProtocolListBox.Items = {'All'};
            app.TemperatureProtocolListBox.ValueChangedFcn = createCallbackFcn(app, @TemperatureProtocolListBoxValueChanged, true);
            app.TemperatureProtocolListBox.Value = 'All';
            app.StorageDriveListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.StorageDriveListBoxLabel.Text = 'Storage Drive';
            app.StorageDriveListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.StorageDriveListBox.Items = {'All'};
            app.StorageDriveListBox.ValueChangedFcn = createCallbackFcn(app, @StorageDriveListBoxValueChanged, true);
            app.StorageDriveListBox.Value = 'All';
            app.GenotypeListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.GenotypeListBoxLabel.Text = 'Genotype';
            app.GenotypeListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.GenotypeListBox.Items = {'All'};
            app.GenotypeListBox.ValueChangedFcn = createCallbackFcn(app, @GenotypeListBoxValueChanged, true);
            app.GenotypeListBox.Value = 'All';
            app.FoodOptionsListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.FoodOptionsListBoxLabel.Text = 'Food Options';
            app.FoodOptionsListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.FoodOptionsListBox.Items = {'All'};
            app.FoodOptionsListBox.ValueChangedFcn = createCallbackFcn(app, @FoodOptionsListBoxValueChanged, true);
            app.FoodOptionsListBox.Value = 'All';
            app.LoadButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveGroupingButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.SaveGroupingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGroupingButtonPushed, true);
            app.SaveGroupingButton.Text = 'Save Grouping';
            app.trialsEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.trialsEditFieldLabel.Text = '# trials';
            app.trialsEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.FoodExclusiveButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'state');
            app.FoodExclusiveButton.ValueChangedFcn = createCallbackFcn(app, @FoodExclusiveButtonValueChanged, true);
            app.FoodExclusiveButton.Text = 'Food Exclusive';
            app.GroupingNameLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.GroupingNameLabel.Text = 'Grouping Name :';
            app.GroupingNameEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'text');
            app.ExperimentDateListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ExperimentDateListBoxLabel.Text = 'Experiment Date';
            app.ExperimentDateListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ExperimentDateListBox.Items = {'All'};
            app.ExperimentDateListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentDateListBoxValueChanged, true);
            app.ExperimentDateListBox.Value = 'All';
            app.ExperimenterListBoxLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.ExperimenterListBoxLabel.Text = 'Experimenter';
            app.ExperimenterListBox = uilistbox(app.BloodPressureAnalyisisUIFigure);
            app.ExperimenterListBox.Items = {'All'};
            app.ExperimenterListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimenterListBoxValueChanged, true);
            app.ExperimenterListBox.Value = 'All';
            % Show the figure after all components are created
            app.BloodPressureAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group_HR_DataGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BloodPressureAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BloodPressureAnalyisisUIFigure)
        end
    end
end